classdef SolverEDP2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabPrincipal                    matlab.ui.container.TabGroup
        InicioTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        MenuPrincipal                   matlab.ui.container.ButtonGroup
        HiperbolicasButton              matlab.ui.control.RadioButton
        ParabolicasButton               matlab.ui.control.RadioButton
        ElipticasButton                 matlab.ui.control.RadioButton
        ReseteoButton                   matlab.ui.control.RadioButton
        ElipticasTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        ReseteoEliptica                 matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        RevisardatosEliptica            matlab.ui.control.Button
        ResolverEliptica                matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        Resultados                      matlab.ui.control.DropDown
        FuenteysolucinexactaPanel       matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        uxy                             matlab.ui.control.EditField
        uxyEditFieldLabel               matlab.ui.control.Label
        SiNo                            matlab.ui.control.DropDown
        SetienelasolucinexactauxyLabel  matlab.ui.control.Label
        fxy                             matlab.ui.control.EditField
        fxyEditFieldLabel               matlab.ui.control.Label
        TamaosdepasoPanel               matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ky                              matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        hx                              matlab.ui.control.EditField
        hEditFieldLabel                 matlab.ui.control.Label
        CondicionesdecontornoDirichletPanel  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        uabajo                          matlab.ui.control.EditField
        Abajouxy_0EditFieldLabel        matlab.ui.control.Label
        uarriba                         matlab.ui.control.EditField
        Arribauxy_mEditFieldLabel       matlab.ui.control.Label
        uderecha                        matlab.ui.control.EditField
        Derechaux_nyEditFieldLabel      matlab.ui.control.Label
        uizquierda                      matlab.ui.control.EditField
        Izquierdaux_0yLabel             matlab.ui.control.Label
        DimensionesdelaplacaPanel       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        d                               matlab.ui.control.EditField
        y_mEditFieldLabel               matlab.ui.control.Label
        c                               matlab.ui.control.EditField
        y_0EditFieldLabel               matlab.ui.control.Label
        b                               matlab.ui.control.EditField
        x_nEditFieldLabel               matlab.ui.control.Label
        a                               matlab.ui.control.EditField
        x_0EditFieldLabel               matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        Exportar3                       matlab.ui.control.Button
        Texto3                          matlab.ui.control.TextArea
        UITable3                        matlab.ui.control.Table
        Exportar4                       matlab.ui.control.Button
        Exportar2                       matlab.ui.control.Button
        Texto4                          matlab.ui.control.TextArea
        UITable4                        matlab.ui.control.Table
        Exportar                        matlab.ui.control.Button
        Texto2                          matlab.ui.control.TextArea
        Texto                           matlab.ui.control.TextArea
        UITable2                        matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ParabolicasTab                  matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        PanelErrorConvergencia          matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        Exportar12                      matlab.ui.control.Button
        Texto12                         matlab.ui.control.Label
        UITable12                       matlab.ui.control.Table
        Exportar11                      matlab.ui.control.Button
        Exportar10                      matlab.ui.control.Button
        Texto11                         matlab.ui.control.Label
        Texto10                         matlab.ui.control.Label
        UITable11                       matlab.ui.control.Table
        UITable10                       matlab.ui.control.Table
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        Exportar7                       matlab.ui.control.Button
        Exportar9                       matlab.ui.control.Button
        Texto9                          matlab.ui.control.Label
        Exportar8                       matlab.ui.control.Button
        Texto8                          matlab.ui.control.Label
        Texto7                          matlab.ui.control.Label
        UITable9                        matlab.ui.control.Table
        UITable8                        matlab.ui.control.Table
        UITable7                        matlab.ui.control.Table
        Texto6                          matlab.ui.control.Label
        Texto5                          matlab.ui.control.Label
        Exportar6                       matlab.ui.control.Button
        Exportar5                       matlab.ui.control.Button
        UITable6                        matlab.ui.control.Table
        UITable5                        matlab.ui.control.Table
        DeslizadorP                     matlab.ui.control.Slider
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        GridLayout11                    matlab.ui.container.GridLayout
        CoeficientededifusintrmicaPanel  matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        cP                              matlab.ui.control.EditField
        cEditFieldLabel                 matlab.ui.control.Label
        DominioespacialytemporalPanel   matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        tmax                            matlab.ui.control.EditField
        t_mxEditFieldLabel              matlab.ui.control.Label
        L                               matlab.ui.control.EditField
        LEditFieldLabel                 matlab.ui.control.Label
        SetienelasolucinexactauxtPanel  matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        uxt                             matlab.ui.control.EditField
        uxtLabel                        matlab.ui.control.Label
        SiNoP                           matlab.ui.control.DropDown
        PanelExplicito                  matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        PanelResultadosParabolico       matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        ResultadosParabolico            matlab.ui.control.DropDown
        RevisarDatosParabolico          matlab.ui.control.Button
        ResolverParabolicaExplicito     matlab.ui.control.Button
        PanelMetodosSolucionParabolicas  matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        MetodosSolucionParabolicas      matlab.ui.control.DropDown
        TamaosdepasoPanel_2             matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        kP                              matlab.ui.control.EditField
        kLabel                          matlab.ui.control.Label
        hP                              matlab.ui.control.EditField
        hLabel                          matlab.ui.control.Label
        CondicionesdecontornoDirichletPanel_2  matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        uLt                             matlab.ui.control.EditField
        uLtEditFieldLabel               matlab.ui.control.Label
        u0t                             matlab.ui.control.EditField
        u0tEditFieldLabel               matlab.ui.control.Label
        CondicionesinicialesPanel       matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ux0                             matlab.ui.control.EditField
        ux0EditFieldLabel               matlab.ui.control.Label
        ReseteoParabolica               matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        HiperbolicasTab                 matlab.ui.container.Tab
        GridLayout10_2                  matlab.ui.container.GridLayout
        GridLayout24_2                  matlab.ui.container.GridLayout
        PanelErrorConvergencia_2        matlab.ui.container.Panel
        GridLayout28_2                  matlab.ui.container.GridLayout
        Exportar12_2                    matlab.ui.control.Button
        Texto12_2                       matlab.ui.control.Label
        UITable12_2                     matlab.ui.control.Table
        Exportar11_2                    matlab.ui.control.Button
        Exportar10_2                    matlab.ui.control.Button
        Texto11_2                       matlab.ui.control.Label
        Texto10_2                       matlab.ui.control.Label
        UITable11_2                     matlab.ui.control.Table
        UITable10_2                     matlab.ui.control.Table
        UIAxes11_2                      matlab.ui.control.UIAxes
        UIAxes12_2                      matlab.ui.control.UIAxes
        UIAxes13_2                      matlab.ui.control.UIAxes
        Exportar7_2                     matlab.ui.control.Button
        Exportar9_2                     matlab.ui.control.Button
        Texto9_2                        matlab.ui.control.Label
        Exportar8_2                     matlab.ui.control.Button
        Texto8_2                        matlab.ui.control.Label
        Texto7_2                        matlab.ui.control.Label
        UITable9_2                      matlab.ui.control.Table
        UITable8_2                      matlab.ui.control.Table
        UITable7_2                      matlab.ui.control.Table
        Texto6_2                        matlab.ui.control.Label
        Texto5_2                        matlab.ui.control.Label
        Exportar6_2                     matlab.ui.control.Button
        Exportar5_2                     matlab.ui.control.Button
        UITable6_2                      matlab.ui.control.Table
        UITable5_2                      matlab.ui.control.Table
        DeslizadorP_2                   matlab.ui.control.Slider
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes9_2                       matlab.ui.control.UIAxes
        GridLayout11_2                  matlab.ui.container.GridLayout
        ParmetrodevelocidadPanel        matlab.ui.container.Panel
        GridLayout27_2                  matlab.ui.container.GridLayout
        cP_2                            matlab.ui.control.EditField
        c2Label                         matlab.ui.control.Label
        DominioespacialytemporalPanel_2  matlab.ui.container.Panel
        GridLayout12_2                  matlab.ui.container.GridLayout
        tmax_2                          matlab.ui.control.EditField
        t_mxEditFieldLabel_2            matlab.ui.control.Label
        L_2                             matlab.ui.control.EditField
        LEditFieldLabel_2               matlab.ui.control.Label
        SetienelasolucinexactauxtPanel_2  matlab.ui.container.Panel
        GridLayout26_2                  matlab.ui.container.GridLayout
        uxt_2                           matlab.ui.control.EditField
        uxtLabel_2                      matlab.ui.control.Label
        SiNoP_2                         matlab.ui.control.DropDown
        PanelExplicito_2                matlab.ui.container.Panel
        GridLayout22_2                  matlab.ui.container.GridLayout
        PanelResultadosParabolico_2     matlab.ui.container.Panel
        GridLayout25_2                  matlab.ui.container.GridLayout
        ResultadosParabolico_2          matlab.ui.control.DropDown
        RevisarDatosParabolico_2        matlab.ui.control.Button
        ResolverParabolicaExplicito_2   matlab.ui.control.Button
        PanelMetodosSolucionParabolicas_2  matlab.ui.container.Panel
        GridLayout23_2                  matlab.ui.container.GridLayout
        MetodosSolucionParabolicas_2    matlab.ui.control.DropDown
        TamaosdepasoPanel_3             matlab.ui.container.Panel
        GridLayout15_2                  matlab.ui.container.GridLayout
        kP_2                            matlab.ui.control.EditField
        kLabel_2                        matlab.ui.control.Label
        hP_2                            matlab.ui.control.EditField
        hLabel_2                        matlab.ui.control.Label
        CondicionesdecontornoDirichletPanel_3  matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        uLt_2                           matlab.ui.control.EditField
        uLtEditFieldLabel_2             matlab.ui.control.Label
        u0t_2                           matlab.ui.control.EditField
        u0tEditFieldLabel_2             matlab.ui.control.Label
        CondicionesinicialesPanel_2     matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        utx0_2                          matlab.ui.control.EditField
        u_tx0EditFieldLabel             matlab.ui.control.Label
        ux0_2                           matlab.ui.control.EditField
        ux0EditFieldLabel_2             matlab.ui.control.Label
        ReseteoParabolica_2             matlab.ui.control.Button
        Image3_2                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        moduloActivo = 'Inicio';  % Pestañas 'Inicio', 'Elipticas', 'Parabolicas', 'Hiperbolicas'

         resetGeneral = false; % Para resetear todo
        
        % PARA EDP ELÍPTICAS
        AbortarCalculo logical = false;  % Para abortar la función app.Eliptica
        usaSolExacta logical = false % Para solución exacta de Elíptica, Parabólica e Hiperbólica
        u_exacta = [] % Solución exacta para EDP Elípticas
        % u_exactaP = [] % Solución exacta para EDP Parabólicas
        % u_exacta = [] % Solución exacta para EDP Hiperbólicas

        % Variables globales para condiciones de frontera
        g_izq
        g_der
        g_inf
        g_sup

        % Dimensiones de la placa
        x0
        xn
        y0
        ym
        h
        k
        % Fuente
        fE 

        % Solución EDP Elíptica
        x1
        y1
        u
        u_exacta_evaluadaE
        tiempoE


        % PARA EDP PARABÓLICAS
        sliderEDP
        labelTiempoEDP

        % Variables globales
       
        LPPP
        tmaxPPP
        hPPP
        kPPP
        cPPP
        % Condición inicial
        ux0PPP
        % Condiciones de frontera
        u0tPPP
        uLtPPP

        % Solución, variables globales para Parabólicas Explícito
        x1PPE
        t1PPE
        uPPE
        tiempoPPE
        % Solución, variables globales para Parabólicas Crank-Nicholson
        x1PPCN
        t1PPCN
        uPPCN
        tiempoPPCN
        rPP

        u_exacta_evaluadaPP  % Solución exacta evaluada para toda EDP Parabólica

        hLinea  % Para el slider
        hPlot2D    % Para el slider


        % PARA EDP HIPERBÓLICAS
        %Variables globales
        %
        LPPP_2
        tmaxPPP_2
        hPPP_2
        kPPP_2
        cPPP_2
        % Condición inicial
        ux0PPP_2
        utx0PPP_2 % aumenta respecto de Parabólicas
        % Condiciones de frontera
        u0tPPP_2
        uLtPPP_2

        % Solución, variables globales para Hiperbólicas Explícito
        x1PPE_2
        t1PPE_2
        uPPE_2
        tiempoPPE_2
        % Solución, variables globales para Hiperbólicas Implícito
        x1PPCN_2
        t1PPCN_2
        uPPCN_2
        tiempoPPCN_2
        rPP_2

        u_exacta_evaluadaPP_2 % Solución exacta evaluada para toda EDP Hiperbólica


    end
    
    methods (Access = private)
        
        % Aquí se escriben todas las funciones de la App
        
        function resetearApp(app)
            % VOLVER A INICIO
            app.TabPrincipal.SelectedTab = app.InicioTab;
            app.MenuPrincipal.SelectedObject = app.ReseteoButton;
            app.moduloActivo = 'Inicio';    

            % LLAMAR A LOS RESETEOS

            app.resetGeneral = true;

            app.ReseteoElipticaButtonPushed();
            app.ReseteoParabolicaButtonPushed();
            app.ReseteoParabolica_2ButtonPushed();

            app.resetGeneral = false;

            % MENSAJE ÚNICO GLOBAL

            drawnow;
            uialert(app.UIFigure, ...
                '✔ Aplicación reiniciada correctamente', ...
                'Reseteo', ...
                'Icon', 'success');
        end

        % Función para validar la solución exacta
        function ok = validarSolExacta(app, texto, opcion, varsEsperadas,...
                nombreCampo)

            % varsEsperadas = {'x','y'} o {'x','t'}
            % Salida lógica (control externo)
            ok = false;

            % VALIDAR SELECCIÓN
            if isempty(opcion)
                uialert(app.UIFigure, ...
                    'Debe seleccionar si existe solución exacta (Sí o No)', ...
                    'Error');
                return;
            end

            % CASO: NO hay solución exacta
            if strcmp(opcion, 'No')
                app.usaSolExacta = false;
                app.u_exacta = [];
                ok = true;
                return;
            end
            
            % VALIDAR CAMPO VACÍO
            if isempty(texto)
                uialert(app.UIFigure, ...
                    ['Debe ingresar ', nombreCampo], ...
                    'Error');
                return;
            end

            % VALIDACIÓN SIMBÓLICA
            syms x y t
            try
                u_sym = str2sym(texto);
            catch
                uialert(app.UIFigure, ...
                    ['Error de sintaxis en ', nombreCampo], ...
                    'Error');
                return;
            end
            vars = symvar(u_sym);

            % VALIDAR VARIABLES PERMITIDAS
            if isequal(varsEsperadas, {'x','y'})
                varsPermitidas = [sym('x'), sym('y')];
            elseif isequal(varsEsperadas, {'x','t'})
                varsPermitidas = [sym('x'), sym('t')];
            end

            for i = 1:length(vars)
                if ~ismember(vars(i), varsPermitidas)
                    uialert(app.UIFigure, ...
                        ['La solución exacta solo permite variables ', strjoin(varsEsperadas, ' y ')], ...
                        'Error');
                    return;
                end
            end

            % VALIDAR DEPENDENCIA COMPLETA
            for i = 1:length(varsEsperadas)
                if ~ismember(sym(varsEsperadas{i}), vars)
                    uialert(app.UIFigure, ...
                        [nombreCampo ' debe depender de ' strjoin(varsEsperadas, ' y ')], ...
                        'Error');
                    return;
                end
            end

            % CONVERTIR A FUNCIÓN
            try
                if isequal(varsEsperadas, {'x','y'})
                    u_e = matlabFunction(u_sym, 'Vars', [x y]);
                    u_e(1,1);
                elseif isequal(varsEsperadas, {'x','t'})
                    u_e = matlabFunction(u_sym, 'Vars', [x t]);
                    u_e(1,1);
                end
            catch
                uialert(app.UIFigure, ...
                    ['No se pudo evaluar ', nombreCampo], ...
                    'Error');
                return;
            end

            % GUARDAR EN VARIABLE GLOBAL
            app.usaSolExacta = true;
            app.u_exacta = u_e;

            ok = true;

        end


        % Función para validar condiciones de contorno en Elípticas
        function g = validarFrontera(app, texto, variablePermitida, nombreFrontera)
            % VALIDARFRONTERA
            % Valida y convierte una condición de contorno en función handle
            % texto: app.uizquierda.Value
            % variablePermitida: 'x' o 'y'
            % nombreFrontera: texto para mostrar en errores (ej: 'u(x0,y)')

            syms x y
            g = [];

            % VALIDAR SINTAXIS
            try
                g_sym = str2sym(texto);
            catch
                uialert(app.UIFigure, ...
                    ['Expresión inválida en ', nombreFrontera], ...
                    'Error');
                return;
            end

            vars = symvar(g_sym);

            % VALIDAR VARIABLES PERMITIDAS
            for i = 1:length(vars)
                if ~ismember(vars(i), [sym('x'), sym('y')])
                    uialert(app.UIFigure, ...
                        ['Solo se permiten variables x o y en ', nombreFrontera], ...
                        'Error');
                    return;
                end
            end

            % VALIDAR DEPENDENCIA CORRECTA
            if strcmp(variablePermitida, 'x')
                if ismember(sym('y'), vars)
                    uialert(app.UIFigure, ...
                        ['La condición en ', nombreFrontera, ...
                        ' solo debe depender a lo sumo de x'], ...
                        'Error');
                    return;
                end
            end

            if strcmp(variablePermitida, 'y')
                if ismember(sym('x'), vars)
                    uialert(app.UIFigure, ...
                        ['La condición en ', nombreFrontera, ...
                        ' solo debe depender a lo sumo de y'], ...
                        'Error');
                    return;
                end
            end

            % CONVERTIR A FUNCIÓN
            try
                if ismember(sym('x'), vars)
                    g = matlabFunction(g_sym, 'Vars', x);
                    g(1);

                elseif ismember(sym('y'), vars)
                    g = matlabFunction(g_sym, 'Vars', y);
                    g(1);

                else
                    % constante
                    aux = double(g_sym);
                    g = @(z) aux;
                end

            catch
                uialert(app.UIFigure, ...
                    ['Error al evaluar ', nombreFrontera], ...
                    'Error');
                g = [];
                return;
            end
        end


        % Función para resolver EDP Elípticas
        function [x1, y1, u, tiempo, h_efectivoE, k_efectivoE] = Eliptica(app, f, ...
                h, k, a, b, c, d, superior, inferior, izquierda, derecha)

            % TAMAÑOS DE PASO Y NÚMERO DE ECUACIONES
            n = round((b-a)/h);
            m = round((d-c)/k);
            X = linspace(a, b, n+1);
            Y = linspace(c, d, m+1);
            Y = Y(end:-1:1);
            h_efectivoE = abs(X(2)-X(1));
            k_efectivoE = abs(Y(2)-Y(1));
            Nx = n - 1;
            Ny = m - 1;
            N = Nx * Ny; % número de ecuaciones

            % AVISO PREVIO (SI LA MALLA ES GRANDE)
            if N > 40000
                seleccion = uiconfirm(app.UIFigure, ...
                    sprintf(['Advertencia: Un tamaño de malla fino, como el seleccionado, '...
                    'genera un sistema de %dx%d = %d ecuaciones, lo que puede ocasionar tiempos de ejecución elevados. '...
                    '¿Desea continuar?'], Nx, Ny, N), ...
                    'Sistema grande', ...
                    'Options', {'Sí','No'}, ...
                    'DefaultOption', 2);
                if strcmp(seleccion, 'No')
                    uialert(app.UIFigure, ...
                        ['Proceso cancelado.' newline ...
                        'Sugerencia: Considere aumentar h y k para reducir el tamaño del sistema y el costo computacional.'], ...
                        'Proceso cancelado');
                    x1 = [];
                    y1 = [];
                    u  = [];
                    tiempo = 0;
                    % Reset botones
                    app.RevisardatosEliptica.Text = 'Revisar datos';
                    app.RevisardatosEliptica.BackgroundColor = [0.83, 0.86, 0.91];
                    app.ResolverEliptica.Text = '▶ Resolver EDP';
                    app.ResolverEliptica.BackgroundColor = [0.83, 0.86, 0.91];
                    app.ResolverEliptica.Enable = 'off';
                    return
                end
            end

            % MALLA
            [x1, y1] = meshgrid(X, Y);

            % INICIALIZACIÓN
            u = zeros(m+1, n+1);
            u(1, :)   = superior(X);
            u(end, :) = inferior(X);
            u(:, 1)   = izquierda(Y);
            u(:, end) = derecha(Y);

            % PARÁMETROS SOR
            rho = (cos(pi/n) + cos(pi/m)) / 2;
            omega = 2 / (1 + sqrt(1 - rho^2));
            tol   = 1e-10;
            maxIter = 10000;
            lam = (h_efectivoE/k_efectivoE)^2; % Relación de malla (Malla/Grid ratio)
            coef = 2*(1 + lam);

            % CONTROL TIEMPO + PROGRESO
            tStart = tic;
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Resolviendo EDP...', ...
                'Message', 'Inicializando...', ...
                'Cancelable', 'on');

            % ITERACIÓN SOR
            for iter = 1:maxIter
                u_old = u;
                for j = 2:m
                    for i = 2:n
                        rhs = u(j, i-1) + u(j, i+1) + ...
                            lam*(u(j-1,i) + u(j+1,i)) ...
                            - h^2 * f(x1(j,i), y1(j,i));
                        u_new = rhs / coef;
                        u(j,i) = (1-omega)*u(j,i) + omega*u_new;
                    end
                end

                % CRITERIO DE PARADA
                err = max(abs(u(:) - u_old(:)));

                % ACTUALIZACIÓN UI
                if mod(iter, 10) == 0
                    % CONTROL DE CANCELACIÓN DE USUARIO (Verificación proactiva)
                    if d.CancelRequested
                        close(d);
                        uialert(app.UIFigure, ...
                            ['El cálculo fue cancelado por el usuario.' newline ...
                            'Sugerencia: Considere aumentar los tamaños de paso h y k para reducir el tamaño del sistema.'], ...
                            'Cancelado');

                        % Forzar salidas vacías para que el código principal sepa que se canceló
                        x1 = []; y1 = []; u = []; tiempo = 0;

                        % Reset botones
                        app.RevisardatosEliptica.Text = 'Revisar datos';
                        app.RevisardatosEliptica.BackgroundColor = [0.83, 0.86, 0.91];
                        app.ResolverEliptica.Text = '▶ Resolver EDP';
                        app.ResolverEliptica.BackgroundColor = [0.83, 0.86, 0.91];
                        app.ResolverEliptica.Enable = 'off';
                        return % Salida inmediata de la función sin continuar a tablas/gráficas
                    end

                    elapsed = toc(tStart);
                    d.Value = iter / maxIter;
                    d.Message = sprintf( ...
                        'Iteración: %d\nTiempo: %.2f s\nError: %.2e', ...
                        iter, elapsed, err);
                    drawnow;
                end

                if err < tol
                    break
                end
            end

            close(d);
            tiempo = toc(tStart);
        end


        % Función para crear tablas
        function Tablas33(app, tablaUI, u, etiqueta_y)

            % PARÁMETROS DE FORMATO
            decimales = 6;           % control de decimales
            umbral = 1e-4;           % debajo de esto → notación científica

            % Tamaño
            [nFilas, nCols] = size(u);

            % FORMATEO INTELIGENTE
            T = cell(nFilas, nCols);

            for i = 1:nFilas
                for j = 1:nCols

                    val = u(i,j);

                    if abs(val) < umbral && val ~= 0
                        % Notación científica
                        T{i,j} = sprintf(['%.' num2str(decimales) 'e'], val);
                    else
                        % Decimal normal
                        T{i,j} = sprintf(['%.' num2str(decimales) 'f'], val);
                    end

                end
            end

            % ETIQUETAS EJE Y
            y_labels = compose([etiqueta_y '%d'], nFilas-1:-1:0);
            y_labels_cell = reshape(cellstr(y_labels), nFilas, 1);

            T = [y_labels_cell, T];

            % ETIQUETAS EJE X
            x_labels = compose('x_%d', 0:nCols-1);
            x_labels_cell = cellstr(x_labels);

            % ENCABEZADOS
            columnNames = [{[etiqueta_y(1) '/x']}, x_labels_cell];

            if length(columnNames) ~= size(T,2)
                error('Error interno: columnas no coinciden');
            end

            % ASIGNAR A TABLA
            tablaUI.Data = T;
            tablaUI.ColumnName = columnNames;
            tablaUI.FontSize = 18;
            tablaUI.ColumnWidth = {'auto'};
        end

        % Función para exportar Tablas a Excel
        function exportarTablaExcel(app, tablaUI, boton)
            % tablaUI, por ejemplo app.UITable es la tabla que quieres exportar
            % boton, por ejemplo app.Exportar1 es el botón (para cambiar color/texto)

            % OBTENER DATOS
            data = tablaUI.Data;
            columnNames = tablaUI.ColumnName;

            % Validación básica
            if isempty(data)
                uialert(app.UIFigure, 'No hay datos para exportar.', 'Error');
                return;
            end

            % VALIDAR COLUMNAS
            if length(columnNames) ~= size(data,2)
                uialert(app.UIFigure, ...
                    'Error: columnas inconsistentes.', ...
                    'Error');
                return;
            end

            % LIMPIAR NOMBRES (Excel-safe)
            columnNames = matlab.lang.makeValidName(columnNames);

            % CONVERTIR A TABLA
            try
                T = cell2table(data, 'VariableNames', columnNames);
            catch
                uialert(app.UIFigure, ...
                    'Error al convertir datos a tabla.', ...
                    'Error');
                return;
            end

            % DIÁLOGO DE GUARDADO
            [file, path] = uiputfile('*.xlsx', 'Guardar como');

            if isequal(file,0) || isequal(path,0)
                % Usuario canceló
                return;
            end

            fullFile = fullfile(path, file);

            % EXPORTAR
            try
                writetable(T, fullFile);

                % ✔ Mensaje de éxito
                uialert(app.UIFigure, ...
                    'Tabla exportada correctamente.', ...
                    'Éxito', 'Icon', 'success');

                % Cambiar botón (opcional)
                if nargin > 2 && ~isempty(boton)
                    boton.Text = '✔ Exportado';
                    boton.BackgroundColor = [0.6 0.85 0.6];
                end

            catch
                uialert(app.UIFigure, ...
                    'Error al guardar el archivo.', ...
                    'Error');
            end

        end


        % Función para calcular las métricas de cualquiera de los métodos
        function [norm_inf, norm_L2, EOC_L2, tiempos] = ...
                CalculoConvergencia(app, fE1, h_vals, k_vals, ...
                x0E, xnE, y0E, ymE, ...
                superior, inferior, izquierda, derecha, u_e)

            % NÚMERO DE EXPERIMENTOS
            num_exp = length(h_vals);

            % INICIALIZACIÓN
            norm_inf = NaN(num_exp,1);
            norm_L2  = NaN(num_exp,1);
            EOC_L2   = NaN(num_exp,1);
            tiempos  = NaN(num_exp,1);

            % Bandera para saber si se completaron todos los experimentos con éxito
            proceso_completado = true;

            % BUCLE PRINCIPAL
            for experimento = 1:num_exp
                % Parámetros
                hE = h_vals(experimento);
                kE = k_vals(experimento);

                % CORRECCIÓN AQUÍ: Se eliminó el 'app' de adentro del paréntesis
                [x1EE, y1EE, uEE, tiempo, ~, ~] = app.Eliptica(fE1, ...
                    hE, kE, x0E, xnE, y0E, ymE, superior, inferior, ...
                    izquierda, derecha);

                % CONTROL DE CANCELACIÓN: Si 'uEE' viene vacío, el usuario canceló el proceso
                if isempty(uEE)
                    proceso_completado = false;
                    break; % Rompe el bucle 'for' inmediatamente para evitar cálculos erróneos
                end

                tiempos(experimento) = tiempo;

                % SOLUCIÓN EXACTA (si existe)
                u_exactaE = u_e(x1EE, y1EE);

                % ERROR (solo nodos internos)
                e = abs(uEE(2:end-1, 2:end-1) - u_exactaE(2:end-1, 2:end-1));

                % Norma infinito
                norm_inf(experimento) = max(e(:));

                % Norma discreta L2
                norm_L2(experimento) = sqrt(hE * kE * sum(e(:).^2));
            end

            % SI EL PROCESO FUE CANCELADO
            if ~proceso_completado
                % Devolvemos vectores vacíos para que la tabla y gráficas externas no se dibujen
                norm_inf = [];
                norm_L2  = [];
                EOC_L2   = [];
                tiempos  = [];
                return; % Terminación limpia de la función
            end

            % CÁLCULO DE EOC (Solo se ejecuta si se completaron todas las mallas)
            h_vals = h_vals(:);
            norm_L2 = norm_L2(:);
            EOC_L2(2:end) = diff(log(norm_L2)) ./ diff(log(h_vals));
        end

 
        % Función para generar las tablas de las métricas
        function TablaMetricas(app, tablaUI, h, k, errInf, errL2, eocL2, tiempo)

            % CONTROL DE CANCELACIÓN: Si los errores vienen vacíos, el usuario canceló
            if isempty(errInf) || isempty(errL2)
                % Limpiamos el contenido de la tabla en la interfaz para que no queden datos viejos
                tablaUI.Data = [];
                return; % Salida limpia e inmediata. No ejecuta el bucle ni genera errores.
            end

            % PARÁMETROS DE FORMATO
            decimales = 6;
            umbral = 1e-4;
            n = length(h);

            % FUNCIÓN FORMATEADORA
            function str = formatVal(val)
                if isnan(val)
                    str = 'NaN';
                elseif abs(val) < umbral && val ~= 0
                    str = sprintf(['%.' num2str(decimales) 'e'], val);
                else
                    str = sprintf(['%.' num2str(decimales) 'f'], val);
                end
            end

            % CONSTRUIR TABLA
            T = cell(n, 6);
            for i = 1:n
                T{i,1} = formatVal(h(i));
                T{i,2} = formatVal(k(i));
                T{i,3} = formatVal(errInf(i));
                T{i,4} = formatVal(errL2(i));
                T{i,5} = formatVal(eocL2(i));
                T{i,6} = formatVal(tiempo(i));
            end

            % ENCABEZADOS (FORMATO ACADÉMICO)
            columnNames = { ...
                'h', ...
                'k', ...
                'Error_{\infty}', ...
                'Error_{L^2}', ...
                'EOC_{L^2}', ...
                'Tiempo (s)'};

            
            tablaUI.Visible = 'on';
            app.Texto4.Visible = 'on';
            app.Exportar4.Visible = 'on';
            app.Texto4.Value = 'Métricas del método';

            % ASIGNAR A UITABLE
            tablaUI.Data = T;
            tablaUI.ColumnName = columnNames;
            tablaUI.FontSize = 16;
            tablaUI.ColumnWidth = {'auto'};
        end

        % Función para graficar la convergencia de cualquiera de los métodos
        function GraficaConvergencia(app, tablaUI, h_vals, err_L2, tituloTexto)

            % CONTROL DE CANCELACIÓN: Si el error viene vacío, el usuario canceló
            if isempty(err_L2)
                cla(tablaUI); % Limpia el gráfico actual para que no queden curvas del intento anterior
                app.Resultados.Enable = 'off';
                app.Resultados.Value = 'Seleccionar resultado';
                return;       % Salida limpia e inmediata antes de ejecutar loglog
            end

            % ASEGURAR VECTORES COLUMNA
            h_vals = h_vals(:);
            err_L2 = err_L2(:);

            % LIMPIAR EJE
            cla(tablaUI);
            hold(tablaUI, 'on'); grid(tablaUI, 'on');
            tablaUI.Visible = 'on';
            tablaUI.XMinorGrid = 'on';
            tablaUI.YMinorGrid = 'on';
            tablaUI.GridAlpha = 0.15;        % intensidad líneas principales
            tablaUI.MinorGridAlpha = 0.23;  % intensidad líneas secundarias

            % GRÁFICA PRINCIPAL
            loglog(tablaUI, h_vals, err_L2, '-dr', ...
                'LineWidth', 2, 'MarkerFaceColor', 'r');

            % LÍNEA DE REFERENCIA (orden 2)
            linea_ref = (h_vals.^2) * (err_L2(1) / h_vals(1)^2);
            loglog(tablaUI, h_vals, linea_ref, '--b', 'LineWidth', 1.5);

            % FORMATO
            set(tablaUI, 'FontSize', 18)
            xlabel(tablaUI, '$$h$$', 'Interpreter', 'latex');
            ylabel(tablaUI, '$$\| e \|_{L^2}$$', ...
                'Interpreter', 'latex', 'Rotation', 0);

            % TÍTULO DINÁMICO
            titulo{1} = '$$\mbox{Orden Experimental de Convergencia}$$';
            titulo{2} = ['$$\mbox{' tituloTexto '}$$'];
            title(tablaUI, char(titulo), 'Interpreter', 'latex');

            % LEYENDA
            leyenda{1} = '$$\mbox{Error en norma }L^2$$';
            leyenda{2} = '$$\mbox{Pendiente te\''orica (orden 2)}$$';
            legend(tablaUI, leyenda, ...
                'Location', 'northwest', ...
                'Interpreter', 'latex');

            % CÁLCULO EOC (ajuste lineal)
            p = polyfit(log(h_vals), log(err_L2), 1);
            orden_exp = p(1);

            % SLOPE MARKER (triángulo)
            if length(h_vals) >= 3
                x13 = h_vals(end-1);
                x23 = h_vals(end-2);
                y13 = err_L2(end-1);
                y23 = err_L2(end-2);
                % Base
                loglog(tablaUI, [x13 x23], [y13 y13], 'k--', ...
                    'LineWidth', 1, 'HandleVisibility', 'off');
                % Altura
                loglog(tablaUI, [x23 x23], [y13 y23], 'k--', ...
                    'LineWidth', 1, 'HandleVisibility', 'off');
                % Texto EOC
                ym3 = sqrt(y13*y23);
                texto = ['$$\mathrm{EOC} = ' ...
                    num2str(orden_exp,'%0.4f') '$$'];
                text(tablaUI, x23*1.05, ym3*1.25, texto, ...
                    'Interpreter', 'latex', ...
                    'FontSize', 18);
            end
        end


        % FUNCIONES PARA EDP PARABÓLICO
        % Función para resolver EDP Parabólica por el método Explícito
        function [x1,t1,u,tiempo,r,h_efectivoPE,k_efectivoPE] = Parabolica_E(app,h,k,c,L,tmax,inferior,izquierda,derecha)
            n = round(L/h);
            m = round(tmax/k);
            h_efectivoPE = L/n; 
            k_efectivoPE = tmax/m;
            r = c * k_efectivoPE / h_efectivoPE^2;

            if r <= 1/2
                fprintf('r = %.4f <= 0.5 -> ESTABLE\n', r);
            else
                fprintf('r = %.4f > 0.5 -> INESTABLE\n', r);
            end

            tic
            X = 0:h_efectivoPE:L;
            T = tmax:-k_efectivoPE:0;
            [x1,t1] = meshgrid(X,T);

            u = zeros(m+1, n+1);
            u(end,:) = inferior(X);
            u(:,1)   = izquierda(T);
            u(:,end) = derecha(T);

            % VECTORIZACIÓN: Eliminamos el bucle 'j'
            for i = m:-1:1
                % Calculamos todos los nodos internos (2 a n) de la fila i basados en la fila i+1
                u(i, 2:n) = (1 - 2*r) * u(i+1, 2:n) + ...
                    r * (u(i+1, 3:n+1) + u(i+1, 1:n-1));
            end

            tiempo = toc;
        end

        % Función para resolver EDP Parabólica por el método
        % Crank-Nicholson
        function [x1,t1,u,tiempo,h_efectivoPCN,k_efectivoPCN] = Parabolica_CN(app,h,k,c,L,tmax,inferior,izquierda,derecha)
            n = round(L/h);
            m = round(tmax/k);
            h_efectivoPCN = L/n; 
            k_efectivoPCN = tmax/m;

            r = c*k_efectivoPCN/h_efectivoPCN^2;

            tic
            % 1. Malla (Numérica, no simbólica)
            X = 0:h_efectivoPCN:L;
            T = tmax:-k_efectivoPCN:0; % Tu lógica de tiempo descendente para la matriz
            [x1,t1] = meshgrid(X,T);

            % 2. Inicialización de la matriz de solución (double)
            u = zeros(m+1, n+1);
            u(end,:) = inferior(X);       % Condición inicial (t=0, última fila)
            u(:,1)   = izquierda(T);      % Frontera izquierda
            u(:,end) = derecha(T);        % Frontera derecha

            % 3. Construcción ÚNICA de matrices del sistema (Sparse)
            Nx = n - 1; % Nodos internos

            % Matriz A (Implícita): (1+r) en diag, -r/2 en sub/super
            mainA = (1 + r) * ones(Nx, 1);
            offA  = (-r/2) * ones(Nx-1, 1);
            A = spdiags([ [offA;0], mainA, [0;offA] ], [-1, 0, 1], Nx, Nx);

            % Matriz B (Explícita): (1-r) en diag, r/2 en sub/super
            mainB = (1 - r) * ones(Nx, 1);
            offB  = (r/2) * ones(Nx-1, 1);
            B = spdiags([ [offB;0], mainB, [0;offB] ], [-1, 0, 1], Nx, Nx);

            % Pre-factorización de A para mayor velocidad en el bucle
            % MATLAB detecta que es tridiagonal y resuelve casi instantáneamente
            [L_mat, U_mat] = lu(A);

            % 4. Bucle temporal (Crank-Nicolson)
            ppp = m + 1;
            for i = 2:m+1
                % Lado derecho: B * u_actual
                u_actual = u(ppp, 2:end-1).';
                bb = B * u_actual;

                % Inyección de condiciones de frontera (promediadas)
                % Esto es vital para que Crank-Nicolson sea Orden 2 en el tiempo
                bb(1)   = bb(1)   + (r/2) * (u(ppp, 1) + u(ppp-1, 1));
                bb(end) = bb(end) + (r/2) * (u(ppp, end) + u(ppp-1, end));

                % Resolver sistema: A * u_siguiente = b
                U_next = U_mat \ (L_mat \ bb);

                u(ppp-1, 2:end-1) = U_next.';
                ppp = ppp - 1;
            end

            tiempo = toc;
        end

        % Funciones para el Slider (Se utiliza en Parabólica y en Hiperbólica)
        function inicializarGraficasEDP(app, x1, t1, u, ax3D, ax2D,...
                tipoEDP, metodo, slider)

            deci = 4;
            dd = num2str(deci);
            fuente = 18;

            % EXTRAER EJES
            x = x1(1,:);
            t = t1(:,1);

            % CORRECCIÓN GLOBAL (CLAVE)
            if t(1) > t(end)
                t  = flipud(t);
                u  = flipud(u);
                t1 = flipud(t1);
            end

            % GUARDAR YA CORREGIDO
            app.x1PPE = x1;
            app.t1PPE = t1;
            app.uPPE  = u;

            % SUPERFICIE
            cla(ax3D,'reset')

            surf(ax3D, x1, t1, u)
            shading(ax3D,'interp')
            colormap(ax3D,jet)
            view(ax3D,3)

            ax3D.BackgroundColor = [0.83, 0.86, 0.91];
            ax3D.Color = [0.83, 0.86, 0.91];
            ax3D.FontSize = fuente;

            colorbar(ax3D)

            xlabel(ax3D, '$$ x $$', 'Interpreter', 'latex');
            ylabel(ax3D, '$$ t $$', 'Interpreter', 'latex');
            zlabel(ax3D, '$$ u $$', 'Interpreter', 'latex','Rotation',0);

            % LÍNEA INICIAL (t = INICIAL)
            hold(ax3D,'on')

            app.hLinea = plot3(ax3D, ...
                x, ...
                t(1)*ones(size(x)), ...
                u(1,:), ...
                'k','LineWidth',2);

            hold(ax3D,'off')

            titulo = ['$$\mbox{EDP ' tipoEDP ' M\''etodo ' metodo '}$$'];
            title(ax3D, titulo,'Interpreter','latex');

            % GRÁFICA 2D (t inicial)
            cla(ax2D,'reset')

            plot(ax2D, x, u(1,:), 'LineWidth',2)
            grid(ax2D,'on')

            ax2D.BackgroundColor = [0.83, 0.86, 0.91];
            ax2D.Color = [0.83, 0.86, 0.91];
            ax2D.FontSize = fuente;

            xlabel(ax2D, '$$ x $$', 'Interpreter', 'latex');
            ylabel(ax2D, '$$ u $$', 'Interpreter', 'latex','Rotation',0);

            ylim(ax2D,[min(u(:)) max(u(:))])
            ax2D.YLimMode = 'manual';

            titulo2 = ['$$t=' num2str(t(1),['%0.' dd 'f']) '$$'];
            title(ax2D, titulo2,'Interpreter','latex');

            % SLIDER
            slider.Limits = [t(1) t(end)];
            slider.Value  = t(1);
            slider.MajorTicks = linspace(t(1), t(end), 6);
        end

        % Función de ayuda para presentar el Slider
        function actualizarGraficasEDP(app, t_val, ax3D, ax2D)

            deci = 4;
            dd = num2str(deci);
            fuente = 18;

            x11 = app.x1PPE;
            t1 = app.t1PPE;
            u1  = app.uPPE;

            x = x11(1,:);
            t = t1(:,1);

            % AQUÍ YA NO SE CORRIGE NADA (YA VIENE BIEN)

            % ==============================
            % ÍNDICE
            % ==============================
            [~,kk] = min(abs(t - t_val));

            % ==============================
            % SUPERFICIE (línea móvil)
            % ==============================
            set(app.hLinea, ...
                'YData', t(kk)*ones(size(x)), ...
                'ZData', u1(kk,:));

            % ==============================
            % GRÁFICA 2D
            % ==============================
            cla(ax2D)

            plot(ax2D, x, u1(kk,:), 'LineWidth',2)
            grid(ax2D,'on')

            ax2D.BackgroundColor = [0.83, 0.86, 0.91];
            ax2D.Color = [0.83, 0.86, 0.91];
            ax2D.FontSize = fuente;

            xlabel(ax2D, '$$ x $$', 'Interpreter', 'latex');
            ylabel(ax2D, '$$ u $$', 'Interpreter', 'latex','Rotation',0);

            ylim(ax2D,[min(u1(:)) max(u1(:))])

            titulo = ['$$t=' num2str(t(kk),['%0.' dd 'f']) '$$'];
            title(ax2D, titulo,'Interpreter','latex');

            drawnow limitrate

        end


        % Función para calcular las métricas de Parabólicas
        function [norm_inf, norm_L2, EOC_L2, tiempos] = ...
                CalculoConvergenciaParabolica(app, h_vals,...
                k_vals,c1,L1,tmax1,...
                inferior, izquierda, derecha, u_e, metodo1)

            % NÚMERO DE EXPERIMENTOS
            num_exp = length(h_vals);

            % INICIALIZACIÓN
            norm_inf = NaN(num_exp,1);
            norm_L2  = NaN(num_exp,1);
            EOC_L2   = NaN(num_exp,1);
            tiempos  = NaN(num_exp,1);

            % BUCLE PRINCIPAL
            for experimento = 1:num_exp

                % Parámetros
                hE = h_vals(experimento);
                kE = k_vals(experimento);

                % Se llama a la función app.Parabólica_E y/o Parabolica_CN para resolver la EDP
                if strcmp(metodo1, 'Explícito')
                    [x1P,t1P,uP,tiempoP,~,~] = Parabolica_E(app,hE,kE,c1,L1,tmax1,...
                        inferior,izquierda,derecha);

                elseif strcmp(metodo1, 'Crank-Nicholson')
                    [x1P,t1P,uP,tiempoP,~,~] = Parabolica_CN(app,hE,kE,c1,L1,tmax1,...
                        inferior,izquierda,derecha);
                end
                
                tiempos(experimento) = tiempoP;

                % SOLUCIÓN EXACTA (si existe)
                u_exactaE = u_e(x1P, t1P);

                % ERROR (solo en el último tiempo)
                % e = abs(uP(1:end-1, 2:end-1) - u_exactaE(1:end-1, 2:end-1));
                e = abs(uP(1,2:end-1) - u_exactaE(1,2:end-1)); % Error en el tiempo final para que 
                % no se contamine con los tiempos previos
                
                % Norma infinito
                % norm_inf(experimento) = max(e(:));
                norm_inf(experimento) = max(e);

                % Norma discreta L2
                % norm_L2(experimento) = sqrt(hE * sum(e(:).^2));
                norm_L2(experimento) = sqrt(hE * sum(e.^2));
            end

            % CÁLCULO DE EOC
            h_vals = h_vals(:);
            norm_L2 = norm_L2(:);
            EOC_L2(2:end) = diff(log(norm_L2)) ./ diff(log(h_vals));
        end


        % Función para emitir una alerta sobre la estabilidad de la
        % solución explícita tanto para EDP Parabólica como Hiperbólica
        function AlertaEstabilidad(app, tipoEDP, r, criterio)

            if r <= criterio

                mensaje = sprintf(['El método explícito para la EDP ' tipoEDP '\n' ...
                    'es ESTABLE.\n\n' ...
                    'Se cumple la condición de estabilidad:\n' ...
                    'r = %.4f <= %0.1f'], r,criterio);

                resp = uiconfirm(app.UIFigure, ...
                    mensaje, ...
                    'Método estable', ...
                    'Options', {'OK'}, ...
                    'Icon','success');

            elseif r > criterio

                mensaje = sprintf(['El método explícito para la EDP ' tipoEDP '\n' ...
                    'es INESTABLE.\n\n' ...
                    'No se cumple la condición de estabilidad:\n' ...
                    'r = %.4f > %0.1f\n\n' ...
                    'Esto puede producir:\n' ...
                    '- Oscilaciones numéricas\n' ...
                    '- Crecimiento no físico de la solución\n' ...
                    '- Divergencia del método'], r,criterio);

                resp = uiconfirm(app.UIFigure, ...
                    mensaje, ...
                    'Método inestable', ...
                    'Options', {'OK'}, ...
                    'Icon','success');
            end
        end

        % FUNCIONES EDP HIPERBÓLICAS
        % Función para poner colores al inicio de la Pestaña Hiperbólicas
        function aplicarEstilo(app)

            % COLORES BASE
            % colorFondo = [0.94 0.94 0.94];
            colorFondo = [0.83 0.86 0.91];
            colorAxes  = [0.83 0.86 0.91];
            colorBoton = [0.83 0.86 0.91];

            % FIGURA
            app.UIFigure.Color = colorFondo;

            % GRID LAYOUTS
            app.GridLayout24_2.BackgroundColor = colorFondo;

            % EJES (NORMAL Y _2)
            for kk = 5:13
                try
                    ax = app.(['UIAxes' num2str(kk) '_2']);
                    ax.Color = colorAxes;
                end
            end

            % BOTONES EXPORTAR (6–12)
            for kk = 6:12
                try
                    btn = app.(['Exportar' num2str(kk) '_2']);
                    btn.BackgroundColor = colorBoton;
                end
            end

            % BOTÓN RESETEO
            app.ReseteoParabolica_2.BackgroundColor = [0.97,0.61,0.61];
        end


        % Función para resolver EDP Hiperbólicas por el método Explícito
        function [x1, t1, u, tiempo, r,h_efectivoHE,k_efectivoHE] = Hiperbolica_E(app,h, k, c, L, tmax, inferior, izquierda, derecha, f, g)
            n = round(L/h);
            m = round(tmax/k);

            h_efectivoHE = L/n; 
            k_efectivoHE = tmax/m;

            r = sqrt(c) * k / h;
            r2 = r^2;

            if r <= 1
                fprintf('r = %.4f <= 1 -> Método explícito ESTABLE\n', r);
           
            else
                fprintf('r = %.4f > 1 -> Método explícito INESTABLE. Revisa h o k.', r);
            end
            tic
            X = 0:h_efectivoHE:L;
            T = tmax:-k_efectivoHE:0; % Tiempos de tmax a 0
            [x1, t1] = meshgrid(X, T);

            % 1. Inicialización numérica (DOUBLE)
            u = zeros(m+1, n+1);
            u(end, :) = inferior(X);       % t = 0 (última fila)
            u(:, 1)   = izquierda(T);      % Frontera izquierda
            u(:, end) = derecha(T);        % Frontera derecha

            % 2. Segundo paso (t = k) usando Taylor de 2do orden
            % VECTORIZADO: eliminamos subs() y trabajamos con índices
            idx = 2:n; % Nodos internos
            u(end-1, idx) = (1 - r2) * f(X(idx)) + ...
                (r2/2) * (f(X(idx+1)) + f(X(idx-1))) + ...
                k * g(X(idx));

            % 3. Bucle temporal VECTORIZADO
            % u_next = 2(1-r^2)u_curr - u_prev + r^2(u_izq + u_der)
            for i = m-1:-1:1
                % Calculamos toda la fila de nodos internos de un solo golpe
                u(i, idx) = 2*(1 - r2) * u(i+1, idx) - u(i+2, idx) + ...
                    r2 * (u(i+1, idx+1) + u(i+1, idx-1));
            end

            tiempo = toc;
        end


        % Función para resolver EDP Hiperbólicas por el método Explícito
        function [x1,t1,u,tiempo,h_efectivoHI,k_efectivoHI] = Hiperbolica_I(app,h,k,c,L,tmax,inferior,izquierda,derecha,f,g)
            n = round(L/h);
            m = round(tmax/k);
           
            tic
            
            h_efectivoHI = L/n;
            k_efectivoHI = tmax/m;

            r = sqrt(c)*k_efectivoHI/h_efectivoHI;
            r2 = r^2;

            X = 0:h_efectivoHI:L;
            T = tmax:-k_efectivoHI:0; % Tiempo descendente para la matriz
            [x1,t1] = meshgrid(X,T);

            % 1. Inicialización numérica (DOUBLE, no simbólica)
            u = zeros(m+1, n+1);
            u(end,:) = inferior(X);       % t = 0 (última fila)
            u(:,1)   = izquierda(T);      % Frontera izquierda
            u(:,end) = derecha(T);        % Frontera derecha

            % 2. Segundo paso (t = k) con Taylor de 2do orden para mantener O(k^2)
            % Usamos la condición inicial f y la velocidad g
            idx = 2:n; % Índices internos
            u(end-1, idx) = (1-r2)*f(X(idx)) + ...
                (r2/2)*(f(X(idx+1)) + f(X(idx-1))) + ...
                k*g(X(idx));

            % 3. Construcción ÚNICA de matrices del sistema (Sparse)
            Nx = n - 1; % Nodos internos

            % Matriz A (Implícita en t_n+1)
            mainA = (1 + r2) * ones(Nx, 1);
            offA  = (-r2/2) * ones(Nx-1, 1);
            A = spdiags([ [offA;0], mainA, [0;offA] ], [-1, 0, 1], Nx, Nx);

            % Matriz B (Explícita en t_n-1)
            mainB = -(1 + r2) * ones(Nx, 1);
            offB  = (r2/2) * ones(Nx-1, 1);
            B = spdiags([ [offB;0], mainB, [0;offB] ], [-1, 0, 1], Nx, Nx);

            % Pre-factorización LU para resolver instantáneamente en el bucle
            [L_mat, U_mat] = lu(A);

            % 4. Bucle temporal (Esquema de 3 niveles)
            ppp = m; % Apuntador al tiempo actual (n)
            for i = 2:m
                % Lado derecho: 2*u(n) + B*u(n-1)
                u_curr = u(ppp, 2:end-1).';
                u_prev = u(ppp+1, 2:end-1).';

                b3 = 2*u_curr + B * u_prev;

                % Inyectar condiciones de contorno (promediadas para orden 2)
                % b(1) = b(1) + (r2/2)*(u_izq_next + u_izq_prev)
                b3(1)   = b3(1)   + (r2/2) * (u(ppp-1, 1) + u(ppp+1, 1));
                b3(end) = b3(end) + (r2/2) * (u(ppp-1, end) + u(ppp+1, end));

                % Resolver sistema lineal mediante sustitución
                U_next = U_mat \ (L_mat \ b3);

                u(ppp-1, 2:end-1) = U_next.';
                ppp = ppp - 1;
            end

            tiempo = toc;
        end


        % Función para calcular las métricas de EDP Hiperbólicas
        function [norm_inf, norm_L2, EOC_L2, tiempos] = ...
                CalculoConvergenciaHiperbolica(app, h_vals,...
                k_vals,c1,L1,tmax1,...
                inferior, izquierda, derecha,g,u_e, metodo1)

            % NÚMERO DE EXPERIMENTOS
            num_exp = length(h_vals);

            % INICIALIZACIÓN
            norm_inf = NaN(num_exp,1);
            norm_L2  = NaN(num_exp,1);
            EOC_L2   = NaN(num_exp,1);
            tiempos  = NaN(num_exp,1);

            % BUCLE PRINCIPAL
            for experimento = 1:num_exp

                % Parámetros
                hE = h_vals(experimento);
                kE = k_vals(experimento);

                % Se llama a la función app.Parabólica_E y/o Parabolica_CN para resolver la EDP
                if strcmp(metodo1, 'Explícito')
                    % [x1P,t1P,uP,tiempoP] = Parabolica_E(app,hE,kE,c1,L1,tmax1,...
                    %     inferior,izquierda,derecha);

                    [x1P, t1P, uP, tiempoP, rP,~,~] = Hiperbolica_E(app,hE, kE, c1, L1, tmax1,...
                        inferior, izquierda, derecha, inferior, g);

                elseif strcmp(metodo1, 'Implícito')
                    % [x1P,t1P,uP,tiempoP] = Parabolica_CN(app,hE,kE,c1,L1,tmax1,...
                    %     inferior,izquierda,derecha);
                    [x1P,t1P,uP,tiempoP,~,~] = Hiperbolica_I(app,hE, kE, c1, L1, tmax1,...
                        inferior, izquierda, derecha, inferior, g);
                end
                
                tiempos(experimento) = tiempoP;

                % SOLUCIÓN EXACTA (si existe)
                u_exactaE = u_e(x1P, t1P);

                % ERROR (solo para el último tiempo)
                % e = abs(uP - u_exactaE); % Todos los nodos
                % e = abs(uP(1:end-1, 2:end-1) - u_exactaE(1:end-1, 2:end-1));
                e = abs(uP(1,2:end-1) - u_exactaE(1,2:end-1)); % Error en el tiempo final para que 
                % no se contamine con los tiempos previos

                % Norma infinito
                % norm_inf(experimento) = max(e(:));  %Todos los nodos incógnitas
                norm_inf(experimento) = max(e); % Solo nodos incógnitas del tmax

                % Norma discreta L2
                % norm_L2(experimento) = sqrt(hE * sum(e(:).^2)); %Todos los nodos
                % incógnitas
                norm_L2(experimento) = sqrt(hE * sum(e.^2)); % Solo nodos incógnitas del tmax
            end
            % 3. CÁLCULO DE EOC
            % EOC_L2 = [NaN, diff(log(norm_L2)) ./ diff(log(h_vals))];
            h_vals = h_vals(:);
            norm_L2 = norm_L2(:);
            EOC_L2(2:end) = diff(log(norm_L2)) ./ diff(log(h_vals));
        end
           
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Código para Controlar el Botón del Menú principal y 
            % las pestañas del Tab Principal
            app.TabPrincipal.SelectedTab = app.InicioTab;
            app.MenuPrincipal.SelectedObject = app.ReseteoButton;
            app.moduloActivo = 'Inicio';


            % INICIO PARA ELÍPTICAS
            % Desactivar gráficas, tablas y botones al inicio de
            % EDP Elípticas
            app.UIAxes2.Visible = 'off'; % Inicia desactivado la
            % gráfica 2D de EDP Elíptica
            app.UIAxes3.Visible = 'off'; % Inicia desactivado la
            % gráfica 3D de EDP Elíptica
            app.UIAxes4.Visible = 'off'; % Inicia desactivado la
            % gráfica de convergencia de EDP Elíptica
            app.UIAxes5.Visible = 'off'; % Inicia desactivado la
            % gráfica de errores de EDP Elíptica
            app.UITable.Visible = 'off'; % Inicia desactivado la
            % tabla de la solución numérica de EDP Elíptica
            app.UITable2.Visible = 'off'; % Inicia desactivado la
            % tabla de la solución exacta de EDP Elíptica
            app.UITable3.Visible = 'off'; % Inicia desactivado la
            % tabla de errores de EDP Elíptica
            app.UITable4.Visible = 'off'; % Inicia desactivado la
            % tabla de errores de EDP Elíptica

            app.Texto.Visible = 'off'; % Inicia desactivado el
            % título de la tabla de la solución numérica de EDP Elíptica
            app.Texto2.Visible = 'off'; % Inicia desactivado el
            % título de la tabla de la solución exacta de EDP Elíptica
            app.Texto3.Visible = 'off'; % Inicia desactivado el
            % título de la tabla de errores de EDP Elíptica
            app.Texto4.Visible = 'off'; % Inicia desactivado el
            % título de la tabla de errores de EDP Elíptica
            app.Exportar.Visible = 'off'; % Inicia desactivado el
            % botón para exportar la tabla de solución numérica EDP Elíptica
            app.Exportar2.Visible = 'off'; % Inicia desactivado el
            % botón para exportar la tabla de solución exacta EDP Elíptica
            app.Exportar3.Visible = 'off'; % Inicia desactivado el
            % botón para exportar la tabla de errores de EDP Elíptica
            app.Exportar4.Visible = 'off'; % Inicia desactivado el
            % botón para exportar la tabla métricas de EDP Elíptica

            app.uxy.Enable = 'off'; % Desactivar el campo de solución exacta
            
            app.ResolverEliptica.Enable = 'off'; % Desactivar el botón de Solver
            app.Resultados.Enable = 'off'; % Desactivar el botón de Resultados
            % app.ResolverParabolicasExplicito.Text = '▶ Resolver EDP';
            app.ResultadosPanel.BackgroundColor = [0.9 0.9 0.9]; % Poner en gris al inicio

            % INICIO PARA PARABÓLICAS
            app.PanelExplicito.Visible = 'on';
            app.PanelExplicito.Title = ' ';
            app.uxt.BackgroundColor = [0.94 0.94 0.94]; % Opcional: fondo gris (inactivo)
            app.uxt.Enable = 'off';

            app.ResolverParabolicaExplicito.Enable = 'off';
            app.ResultadosParabolico.Enable = 'off';
            app.RevisarDatosParabolico.Enable = 'off';

            app.PanelResultadosParabolico.BackgroundColor = [0.9 0.9 0.9]; % Poner en gris al inicio

            for kk = 6:13
                app.(['UIAxes' num2str(kk)]).Visible = 'off';
            end

            app.DeslizadorP.Visible = 'off';

            for kk = 5:12
                app.(['UITable' num2str(kk)]).Visible   = 'off';
                app.(['Texto' num2str(kk)]).Visible     = 'off';
                app.(['Exportar' num2str(kk)]).Visible  = 'off';
            end

            app.PanelErrorConvergencia.Visible = 'off';

            % INICIO PARA HIPERBÓLICAS
            aplicarEstilo(app)

            % LIMPIEZA TOTAL (ÚNICA)
            app.PanelExplicito_2.Visible = 'on';
            app.PanelExplicito_2.Title = 'Métodos';
            app.uxt_2.BackgroundColor = [0.94 0.94 0.94]; % Opcional: fondo gris (inactivo)
            app.uxt_2.Enable = 'off';

            app.ResolverParabolicaExplicito_2.Enable = 'off';
            app.ResultadosParabolico_2.Enable = 'off';
            app.RevisarDatosParabolico_2.Enable = 'off';

            app.PanelResultadosParabolico_2.BackgroundColor = [0.9 0.9 0.9]; % Poner en gris al inicio


            axesList = [app.UIAxes6_2, app.UIAxes7_2, app.UIAxes8_2, app.UIAxes9_2, ...
                app.UIAxes10_2, app.UIAxes11_2, app.UIAxes12_2, app.UIAxes13_2];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP_2.Visible = 'off';

            tables = [app.UITable5_2, app.UITable6_2, app.UITable7_2, app.UITable8_2, ...
                app.UITable9_2, app.UITable10_2, app.UITable11_2, app.UITable12_2];

            labels = [app.Texto5_2, app.Texto6_2, app.Texto7_2, app.Texto8_2, ...
                app.Texto9_2, app.Texto10_2, app.Texto11_2, app.Texto12_2];

            buttons = [app.Exportar5_2, app.Exportar6_2, app.Exportar7_2, app.Exportar8_2, ...
                app.Exportar9_2, app.Exportar10_2, app.Exportar11_2, app.Exportar12_2];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            app.PanelErrorConvergencia_2.Visible = 'off';           
        end

        % Selection changed function: MenuPrincipal
        function MenuPrincipalSelectionChanged(app, event)
            boton = app.MenuPrincipal.SelectedObject;

            if boton == app.ReseteoButton
                % CONFIRMACIÓN GLOBAL
                seleccion = uiconfirm(app.UIFigure, ...
                    ['¿Está seguro de reiniciar TODA la aplicación?' newline ...
                    'Se eliminarán todos los datos, gráficas y resultados.'], ...
                    'Confirmar reseteo total', ...
                    'Icon', 'warning', ...
                    'Options', {'Continuar', 'Cancelar'}, ...
                    'DefaultOption', 2);

                if ~strcmp(seleccion, 'Continuar')
                    return;
                end

                % EJECUTAR RESET GLOBAL
                app.resetearApp();
                return;

            elseif boton == app.ElipticasButton

                app.TabPrincipal.SelectedTab = app.ElipticasTab;
                app.moduloActivo = 'Elipticas';

            elseif boton == app.ParabolicasButton

                app.TabPrincipal.SelectedTab = app.ParabolicasTab;
                app.moduloActivo = 'Parabolicas';

            elseif boton == app.HiperbolicasButton

                app.TabPrincipal.SelectedTab = app.HiperbolicasTab;
                app.moduloActivo = 'Hiperbolicas';

            end
           
        end

        % Selection change function: TabPrincipal
        function TabPrincipalSelectionChanged(app, event)
            nuevaTab = event.NewValue;

            % SIEMPRE permitir Inicio
            if nuevaTab == app.InicioTab
                return;
            end

            % BLOQUEAR SEGÚN módulo activo
            switch app.moduloActivo

                case 'Elipticas'
                    if nuevaTab ~= app.ElipticasTab
                        app.TabPrincipal.SelectedTab = app.ElipticasTab;
                    end

                case 'Parabolicas'
                    if nuevaTab ~= app.ParabolicasTab
                        app.TabPrincipal.SelectedTab = app.ParabolicasTab;
                    end

                case 'Hiperbolicas'
                    if nuevaTab ~= app.HiperbolicasTab
                        app.TabPrincipal.SelectedTab = app.HiperbolicasTab;
                    end

                otherwise
                    % Si está en Inicio, no permitir ir a otras sin menú
                    app.TabPrincipal.SelectedTab = app.InicioTab;

            end

        end

        % Value changed function: SiNo
        function SiNoValueChanged(app, event)
            % Obtenemos el valor seleccionado (asegúrate que coincida con tus opciones)
            seleccion = app.SiNo.Value; 
            
            % Comparamos la selección
            if strcmp(seleccion, 'Sí')
                app.uxy.Enable = 'on';   % Se activa para escribir la solución exacta
                app.uxy.BackgroundColor = [1 1 1]; % Opcional: fondo blanco (activo)
            else
                app.uxy.Enable = 'off';  % Se desactiva
                app.uxy.Value = '';      % Limpiamos el campo por si había algo escrito
                app.uxy.BackgroundColor = [0.94 0.94 0.94]; % Opcional: fondo gris (inactivo)
            end
        end

        % Button pushed function: ResolverEliptica
        function ResolverElipticaButtonPushed(app, event)
 
            % Ventana de progreso
            procesandoE = uiprogressdlg(app.UIFigure, ...
            'Title','Procesando', ...
            'Message','Iniciando...', ...
            'Value',0);
            drawnow; % IMPORTANTE para que se vea
            
            % RECUPERAR DATOS
            x0E = app.x0;
            xnE = app.xn;
            y0E = app.y0;
            ymE = app.ym;
            hE = app.h;
            kE = app.k;

            fE1 = app.fE ; % Fuente
            u_exactaE = app.u_exacta;
            % app.u_exacta = u_e; % Ya lo guarda automáticamente
            % la función app.validarSolExacta

            izquierda = app.g_izq;
            derecha = app.g_der;
            inferior = app.g_inf;
            superior = app.g_sup;

            % ETAPA 1: Preparación de datos
            procesandoE.Message = 'Discretizando la malla...';
            procesandoE.Value = 0.2;
            drawnow;

            % ETAPA 2: Resolución de la EDP
            procesandoE.Message = 'Resolviendo la EDP...';
            procesandoE.Value = 0.5;
            drawnow;

            % Se llama a la función app.Eliptica para resolver la EDP
            [x1E, y1E, uE, tiempo,hefectivoE, kefectivoE] = app.Eliptica(fE1,...
                hE, kE, x0E, xnE, y0E, ymE, superior, inferior,...
                izquierda, derecha);

            % CONTROL DE CANCELACIÓN
            if isempty(uE)
                % No hacer nada más
                % (el mensaje ya se mostró dentro de la función)

                app.Resultados.Enable = 'off';   % asegurarte que siga deshabilitado
                return
            end

            % FINALIZACIÓN
            procesandoE.Value = 1;
            procesandoE.Message = 'Resultados listos';
            drawnow;
        
            pause(0.5); % opcional (para que el usuario lo vea)
        
            close(procesandoE);

            % Solución Exacta
            if ~isempty(u_exactaE)
                % Si_No_Exacta = 1; %Sí se dispone de la solución exacta;
                u_exacta_evaluadaE1 = u_exactaE(x1E,y1E);
                
            else
                u_exacta_evaluadaE1 = [];
                % Si_No_Exacta = 0; %No se dispone de la solución exacta;
            end
            
            [filas, columnas] = size(uE);
            filas1 = filas-2;
            columnas1 = columnas-2;
            tamano = filas1*columnas1;
        
                % Mensaje final

                tiempofinal = sprintf(['Tiempo de ejecución del método: %.4f segundos.\n' ...
                    'Se resolvió un sistema de %dx%d = %d ecuaciones.\n\n' ...
                    'h_{efectivo} = %0.10f\n' ...
                    'k_{efectivo} = %0.10f'], ...
                    tiempo, filas1, columnas1, tamano,hefectivoE,kefectivoE);

                uialert(app.UIFigure, ...
                    tiempofinal, ...
                    'Proceso completado','Icon', 'success');
        
            % Guardar las variables calculadas para usos futuros
            app.x1 = x1E;
            app.y1 = y1E;
            app.u = uE;
            app.tiempoE = tiempo;
            app.u_exacta_evaluadaE = u_exacta_evaluadaE1;

            % ÉXITO
            app.ResolverEliptica.Text = '✔ EDP Resuelta';
            app.ResolverEliptica.BackgroundColor = [0.6 0.85 0.6]; % verde suave

            % ACTIVAR EL MENÚ DESPLEGABLE RESULTADOS
            app.Resultados.Enable = 'on';
            app.Resultados.Value = 'Seleccionar resultado';
            app.ResultadosPanel.BackgroundColor = [0.83,0.86,0.91]; % Color general de la app

        end

        % Button pushed function: RevisardatosEliptica
        function RevisardatosElipticaButtonPushed(app, event)
            % VALIDAR CAMPOS VACÍOS
            campos = {
                'u(x0,y)', app.uizquierda.Value;
                'u(xn,y)', app.uderecha.Value;
                'u(x,y0)', app.uabajo.Value;
                'u(x,ym)', app.uarriba.Value;
                'x0', app.a.Value;
                'xn', app.b.Value;
                'y0', app.c.Value;
                'ym', app.d.Value;
                'f(x,y)', app.fxy.Value;
                'h', app.hx.Value;
                'k', app.ky.Value;
            };
            
            for i = 1:size(campos,1)
                if isempty(campos{i,2})
                    uialert(app.UIFigure, ['El campo ', campos{i,1}, ' está vacío'], 'Error');
                    return;
                end
            end
            
            % CONVERTIR NUMÉRICOS
            numericos = {
                'x0', app.a.Value;
                'xn', app.b.Value;
                'y0', app.c.Value;
                'ym', app.d.Value;
                'h',  app.hx.Value;
                'k',  app.ky.Value;
            };
            
            val = zeros(6,1);
            
            for i = 1:6
                nombre = numericos{i,1};
                txt = numericos{i,2};
                
                try
                    v = eval(txt);
                catch
                    uialert(app.UIFigure, ['El campo ', nombre, ' no es válido'], 'Error');
                    return;
                end
                
                if ~isnumeric(v) || ~isscalar(v) || isnan(v)
                    uialert(app.UIFigure, ['El campo ', nombre, ' debe ser numérico'], 'Error');
                    return;
                end
                
                val(i) = v;
            end
            
            x0_val = val(1); xn_val = val(2);
            y0_val = val(3); ym_val = val(4);
            h_val  = val(5); k_val  = val(6);

            % VALIDACIONES
            if x0_val < 0
                uialert(app.UIFigure, 'x0 debe ser ≥ 0', 'Error'); return;
            end
            
            if x0_val >= xn_val
                uialert(app.UIFigure, 'x0 debe ser menor que xn', 'Error'); return;
            end
            
            if y0_val < 0
                uialert(app.UIFigure, 'y0 debe ser ≥ 0', 'Error'); return;
            end
            
            if ym_val <= y0_val
                uialert(app.UIFigure, 'ym debe ser mayor que y0', 'Error'); return;
            end
            
            if h_val <= 0 || k_val <= 0
                uialert(app.UIFigure, 'h y k deben ser positivos', 'Error'); return;
            end
            
            % VALIDAR SI h DIVIDE EL INTERVALO
            if abs((xn_val - x0_val)/h_val - ...
                    round((xn_val - x0_val)/h_val)) > 1e-10

                % Número entero de subintervalos
                n = round((xn_val - x0_val)/h_val);

                % h ajustado internamente
                h_sugerido = (xn_val - x0_val)/n;

                % MENSAJE INFORMATIVO
                resp = uiconfirm(app.UIFigure, ...
                    sprintf(['El valor de h ingresado no divide exactamente\n' ...
                    'el intervalo espacial x.\n\n' ...
                    'Se recomienda modificar h manualmente o,\n' ...
                    'en su defecto, el solver recalculará\n' ...
                    'internamente un valor adecuado que no \n'...
                    'genere problemas numéricos.\n\n' ...
                    'Valor sugerido:\n' ...
                    'h = %.10f\n\n' ...
                    'Número de subintervalos:\n' ...
                    'n = %d'], ...
                    h_sugerido, n),...
                    'Advertencia', ...
                    'Options', {'OK'}, ...
                    'Icon', 'warning');
            end
            

            if abs((ym_val-y0_val)/k_val - round((ym_val-y0_val)/k_val)) > 1e-10
                
                % Número entero de subintervalos sugerido
                m = round((ym_val-y0_val)/k_val);

                % Nuevo k sugerido
                k_sugerido = (ym_val-y0_val)/m;

                % MENSAJE INFORMATIVO
                resp = uiconfirm(app.UIFigure, ...
                    sprintf(['El valor de k ingresado no divide exactamente\n' ...
                    'el intervalo espacial y.\n\n' ...
                    'Se recomienda modificar k manualmente o,\n' ...
                    'en su defecto, el solver recalculará\n' ...
                    'internamente un valor adecuado que no \n'...
                    'genere problemas numéricos.\n\n' ...
                    'Valor sugerido:\n' ...
                    'k = %.10f\n\n' ...
                    'Número de subintervalos:\n' ...
                    'm = %d'], ...
                    k_sugerido, m), ...
                    'Advertencia', ...
                    'Options', {'OK'}, ...
                    'Icon', 'warning');
            end

            % f(x,y) - Validación flexible
            % A partir de aquí, fE1(x,y) funcionará correctamente en tus bucles 
            % sin importar si f_sym era "0", "5", "x^2" o "x+y".
            syms x y
            val_f = app.fxy.Value;

            % 1. Verificar si el campo está vacío
            if isempty(strtrim(val_f))
                uialert(app.UIFigure, 'El campo f(x,y) no puede estar vacío. Use 0 para Laplace.', 'Error');
                return;
            end

            try
                % Intentar convertir el string a expresión simbólica
                f_sym = str2sym(val_f);
            catch
                % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                uialert(app.UIFigure, 'Error de sintaxis en la fuente f(x,y). Verifique paréntesis y operadores.', 'Error');
                return;
            end

            % 2. Validar variables permitidas
            vars = symvar(f_sym); % Obtiene las variables presentes (puede ser ninguna si es constante)
            for i = 1:length(vars)
                % Si la variable no es ni x ni y, lanzar error
                if ~any(has(vars(i), [x, y]))
                    uialert(app.UIFigure, ...
                        ['En f(x,y) hay variables no permitidas: "' char(vars(i)) '". Solo se permiten x y/o y.'], ...
                        'Error');
                    return;
                end
            end

            % 3. Generar la función de MATLAB
            % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
            % la función resultante siempre acepte dos entradas: f(x,y).
            try
                fE1 = matlabFunction(f_sym, 'Vars', [x y]);
            catch
                uialert(app.UIFigure, 'Error al procesar la función f(x,y).', 'Error');
                return;
            end

            % SOLUCIÓN EXACTA
            ok = app.validarSolExacta( ...
                app.uxy.Value, ...
                app.SiNo.Value, ...
                {'x','y'}, ...
                'u(x,y)' );

            if ~ok
                return;
            end

            % CONDICIONES DE FRONTERA
            % Fronteras verticales → dependen de y
            % IZQUIERDA
            g_izq_fun = app.validarFrontera(app.uizquierda.Value, 'y', 'u(x0,y)');
            if isempty(g_izq_fun)
                return;
            end

            % DERECHA
            g_der_fun = app.validarFrontera(app.uderecha.Value, 'y', 'u(xn,y)');
            if isempty(g_der_fun)
                return;
            end

            % Fronteras horizontales → dependen de x

            % ABAJO
            g_inf_fun = app.validarFrontera(app.uabajo.Value, 'x', 'u(x,y0)');
            if isempty(g_inf_fun)
                return;
            end

            % ARRIBA
            g_sup_fun = app.validarFrontera(app.uarriba.Value, 'x', 'u(x,ym)');
            if isempty(g_sup_fun)
                return;
            end
        
            % GUARDAR VARIABLES PARA USARLAS EN CUALQUIER MOMENTO
            app.x0 = x0_val;
            app.xn = xn_val;
            app.y0 = y0_val;
            app.ym = ym_val;
            app.h  = h_val;
            app.k  = k_val;

            app.fE = fE1; % Fuente
            % app.u_exacta = u_e; % Ya lo guarda automáticamente
            % la función app.validarSolExacta

            app.g_izq = g_izq_fun;
            app.g_der = g_der_fun;
            app.g_inf = g_inf_fun;
            app.g_sup = g_sup_fun;

  
            % MENSAJE FINAL
            uialert(app.UIFigure, ...
                '✔ Parámetros correctos', ...
                'Éxito', ...
                'Icon','success');
            
            % ÉXITO
            app.RevisardatosEliptica.Text = '✔ Datos revisados';
            app.RevisardatosEliptica.BackgroundColor = [0.6 0.85 0.6]; % Verde suave

            % 11. ACTIVAR EL BOTÓN RESOLVER EDP
            app.ResolverEliptica.Enable = 'on';
            % app.ResultadosPanel.BackgroundColor = [0.83,0.86,0.91];
            
        end

        % Size changed function: ResultadosPanel
        function ResultadosPanelSizeChanged(app, event)
            
            
        end

        % Value changed function: Resultados
        function ResultadosValueChanged(app, event)
            value = app.Resultados.Value;
            
            % Recuperar resultados calculados globales
            x1E = app.x1;
            y1E = app.y1;
            uE = app.u;
            hE = app.h;

            a2 = app.x0;
            b2 = app.xn;
            c2 = app.y0;
            d2 = app.ym;

            izquierda = app.g_izq;
            derecha = app.g_der;
            inferior = app.g_inf;
            superior = app.g_sup;
            
            uExacta = app.u_exacta;
            fE1 = app.fE;

            u_exacta_evaluadaE1 = app.u_exacta_evaluadaE;

            % Cálculo de error numérico
            if ~isempty(u_exacta_evaluadaE1)
                e_E = abs(u_exacta_evaluadaE1 - uE);
            else
                e_E = [];
            end

            % Cálculo de h y k efectivos
            h_efectivoE = abs(x1E(1,2)-x1E(1,1));
            k_efectivoE = abs(y1E(1,1)-y1E(2,1));


            fuente_para_todas = 18; % Fuente para las gráficas

            % Ocultar TODO
            cla(app.UIAxes2, 'reset')
            cla(app.UIAxes3, 'reset')
            cla(app.UIAxes4, 'reset')
            cla(app.UIAxes5, 'reset')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.UIAxes5.Visible = 'off';

            app.UITable.Visible = 'off';
            app.UITable2.Visible = 'off';
            app.UITable3.Visible = 'off';
            app.UITable4.Visible = 'off';

            app.Texto.Visible = 'off';
            app.Texto2.Visible = 'off';
            app.Texto3.Visible = 'off';
            app.Texto4.Visible = 'off';

            app.Exportar.Visible = 'off';
            app.Exportar2.Visible = 'off';
            app.Exportar3.Visible = 'off';
            app.Exportar4.Visible = 'off';

            % LIMPIAR GRÁFICAS

            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            colorbar(app.UIAxes2, 'off');
            colorbar(app.UIAxes3, 'off');

            % LIMPIAR TABLAS
            app.UITable.Data = [];
            app.UITable2.Data = [];
            app.UITable3.Data = [];
            app.UITable4.Data = [];

            switch value
                case 'Gráficas 2D'
                    app.UIAxes2.Visible = 'on';

                    % Mapa de calor solución numérica EDP Elíptica
                    cla(app.UIAxes2, 'reset');
                    surf(app.UIAxes2, x1E, y1E, uE);

                    % Estética de la superficie
                    shading(app.UIAxes2, 'interp'); % Shading requiere el eje como primer argumento
                    view(app.UIAxes2, 0, 90);       % Vista superior (2D)

                    % Formato de los Ejes (Reemplazando 'set(gca...)')
                    app.UIAxes2.FontSize = fuente_para_todas;

                    % Configuración del Color (Mapa y Barra)
                    cMap = jet(256);
                    colormap(app.UIAxes2, cMap);     % Asocia el mapa de colores al eje específico
                    colorbar(app.UIAxes2);      % Crea la barra en el eje de la app
                    
                    % Etiquetas con Intérprete LaTeX
                    xlabel(app.UIAxes2, '$$ x $$', 'Interpreter', 'latex');
                    ylabel(app.UIAxes2, '$$ y $$', 'Interpreter', 'latex',...
                        'Rotation',0);
                    title(app.UIAxes2, '$$\mbox{Mapa de Calor (Soluci\''on Num\''erica)}$$', 'Interpreter', 'latex');

                    
                    % Mapa de calor solución exacta EDP Elíptica
                    if ~isempty(u_exacta_evaluadaE1)
                        app.UIAxes3.Visible = 'on';
                        cla(app.UIAxes3, 'reset');
                        surf(app.UIAxes3, x1E, y1E, u_exacta_evaluadaE1);

                        % Estética de la superficie
                        shading(app.UIAxes3, 'interp'); % Shading requiere el eje como primer argumento
                        view(app.UIAxes3, 0, 90);       % Vista superior (2D)

                        % Formato de los Ejes (Reemplazando 'set(gca...)')
                        app.UIAxes3.FontSize = fuente_para_todas;

                        % Configuración del Color (Mapa y Barra)
                        colormap(app.UIAxes3, cMap);     % Asocia el mapa de colores al eje específico
                        colorbar(app.UIAxes3);      % Crea la barra en el eje de la app

                        % Etiquetas con Intérprete LaTeX
                        xlabel(app.UIAxes3, '$$ x $$', 'Interpreter', 'latex');
                        ylabel(app.UIAxes3, '$$ y $$', 'Interpreter', 'latex',...
                            'Rotation',0);
                        title(app.UIAxes3, '$$\mbox{Mapa de Calor (Soluci\''on Exacta)}$$', 'Interpreter', 'latex');
                    else
                        uialert(app.UIFigure, ...
                            'No se dispone de la Solución Exacta.', ...
                            'Aviso');
                    end

                case 'Gráficas 3D'
                    app.UIAxes2.Visible = 'on';
                    
                    cla(app.UIAxes2, 'reset');
                    surf(app.UIAxes2, x1E, y1E, uE);

                    % COLOR DE FONDO (0.83, 0.86, 0.91)
                    app.UIAxes2.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                    app.UIAxes2.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE
                    % app.UIFigure.Color = [0.83, 0.86, 0.91];          %  evita fondo blanco externo

                    % CONFIGURACIÓN VISUAL
                    shading(app.UIAxes2, 'interp');
                    cMap3 = jet(256);
                    colormap(app.UIAxes2, cMap3);

                    cb3 = colorbar(app.UIAxes2);                       %  capturar el colorbar
                    cb3.Color = 'k';                                   % opcional (texto visible)
                    cb3.Box = 'on';                                    % opcional

                    % FORMATO Y ETIQUETAS
                    app.UIAxes2.FontSize = fuente_para_todas;
                    xlabel(app.UIAxes2, '$$ x $$', 'Interpreter', 'latex');
                    ylabel(app.UIAxes2, '$$ y $$', 'Interpreter', 'latex');
                    zlabel(app.UIAxes2, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                    title(app.UIAxes2, '$$\mbox{Soluci\''on Num\''erica}$$', 'Interpreter', 'latex');
                    
                    if ~isempty(u_exacta_evaluadaE1)
                        app.UIAxes3.Visible = 'on';
                        cla(app.UIAxes3, 'reset');
                        surf(app.UIAxes3, x1E, y1E, u_exacta_evaluadaE1);

                        % COLOR DE FONDO (0.83, 0.86, 0.91)
                        app.UIAxes3.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                        app.UIAxes3.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE
                        % app.UIFigure.Color = [0.83, 0.86, 0.91];          %  evita fondo blanco externo

                        % CONFIGURACIÓN VISUAL
                        shading(app.UIAxes3, 'interp');
                        cMap3 = jet(256);
                        colormap(app.UIAxes3, cMap3);

                        cb3 = colorbar(app.UIAxes3);                       %  capturar el colorbar
                        cb3.Color = 'k';                                   % opcional (texto visible)
                        cb3.Box = 'on';                                    % opcional

                        % 4. FORMATO Y ETIQUETAS
                        app.UIAxes3.FontSize = fuente_para_todas;
                        xlabel(app.UIAxes3, '$$ x $$', 'Interpreter', 'latex');
                        ylabel(app.UIAxes3, '$$ y $$', 'Interpreter', 'latex');
                        zlabel(app.UIAxes3, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                        title(app.UIAxes3, '$$\mbox{Soluci\''on Exacta}$$', 'Interpreter', 'latex');
                    else
                        uialert(app.UIFigure, ...
                            'No se dispone de la Solución Exacta.', ...
                            'Aviso');
                    end

                case 'Tablas'

                    % Ventana de progreso
                    procesandoTablas = uiprogressdlg(app.UIFigure, ...
                        'Title','Procesando', ...
                        'Message','Ensamblando tablas...', ...
                        'Value',0);
                    drawnow; % IMPORTANTE para que se vea

                    app.UITable.Visible = 'on';
                    app.Texto.Visible = 'on';
                    app.Exportar.Visible = 'on';
                    app.Tablas33(app.UITable,uE,'y_')
                    app.Texto.Value = 'Solución Numérica';

                    if ~isempty(u_exacta_evaluadaE1)
                        app.UITable2.Visible = 'on';
                        app.Texto2.Visible = 'on';
                        app.Exportar2.Visible = 'on';
                        app.Texto2.Value = 'Solución Exacta';
                        app.Tablas33(app.UITable2,u_exacta_evaluadaE1,'y_')
                    else
                        uialert(app.UIFigure, ...
                            'No se dispone de la solución exacta.', ...
                            'Aviso');
                    end

                    % FINALIZACIÓN
                    procesandoTablas.Value = 1;
                    procesandoTablas.Message = 'Tablas listas';
                    drawnow;
                    pause(0.5); % opcional (para que el usuario lo vea)
                    close(procesandoTablas);

                case 'Errores'
                    if ~isempty(u_exacta_evaluadaE1)
                        % Ventana de progreso
                        procesandoErrores = uiprogressdlg(app.UIFigure, ...
                            'Title','Procesando', ...
                            'Message','Presentando errores...', ...
                            'Value',0);
                        drawnow; % IMPORTANTE para que se vea

                        app.UITable3.Visible = 'on';
                        app.Texto3.Visible = 'on';
                        app.Exportar3.Visible = 'on';
                        app.Texto3.Value = 'Error Numérico';
                        app.Tablas33(app.UITable3,e_E,'y_')

                        % Limpiar eje
                        cla(app.UIAxes5, 'reset');
                        app.UIAxes5.Visible = 'on';

                        % Gráfica de superficie del error
                        surf(app.UIAxes5, x1E, y1E, e_E);

                        % Vista desde arriba (mapa de calor)
                        view(app.UIAxes5, 2);

                        % Quitar malla de la superficie
                        shading(app.UIAxes5, 'interp');

                        % Barra de color
                        colorbar(app.UIAxes5);

                        % Formato
                        app.UIAxes5.FontSize = fuente_para_todas;

                        % % Etiquetas
                        xlabel(app.UIAxes5, '$$ x $$', 'Interpreter', 'latex');
                        ylabel(app.UIAxes5, '$$ y $$', 'Interpreter', 'latex', 'Rotation', 0);
                        title(app.UIAxes5, '$$\mbox{Mapa de Calor del Error Num\''erico}$$', 'Interpreter', 'latex');

                        % Opcional: colormap
                        colormap(app.UIAxes5, 'jet');

                        % FINALIZACIÓN
                        procesandoErrores.Value = 1;
                        procesandoErrores.Message = 'Errores listos';
                        drawnow;
                        pause(0.5); % opcional (para que el usuario lo vea)
                        close(procesandoErrores);
                    else
                        uialert(app.UIFigure, ...
                            'Necesita la solución exacta para calcular el error numérico.', ...
                            'Aviso');
                    end
  
                case 'Convergencia del método'
                    if ~isempty(u_exacta_evaluadaE1)
             
                        % Ventana de progreso
                        procesandoE = uiprogressdlg(app.UIFigure, ...
                            'Title','Procesando', ...
                            'Message','Iniciando...', ...
                            'Value',0);
                        drawnow; % IMPORTANTE para que se vea
                        h_in = h_efectivoE; % h inicial para la convergencia
                        h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                        
                        k_in = k_efectivoE;
                        k_vals = [k_in, k_in/2, k_in/4, k_in/8];  % k para convergencia
 
                        % Preparación de datos
                        procesandoE.Message = 'Discretizando la malla...';
                        procesandoE.Value = 0.2;
                        drawnow;

                        % Resolución de la EDP
                        procesandoE.Message = 'Resolviendo la EDP...';
                        procesandoE.Value = 0.5;
                        drawnow;


                        % Se llama a la función CalculoConvergencia para
                        % calcular las métricas
                        [err_max, err_L2, eoc_L2, tiempo] = app.CalculoConvergencia( ...
                            fE1, h_vals, k_vals, a2, b2, c2, d2, ...
                            superior, inferior, izquierda, derecha, ...
                            uExacta);

                        % FINALIZACIÓN
                        procesandoE.Value = 1;
                        procesandoE.Message = 'Resultados listos';
                        drawnow;
                        pause(0.5); % opcional (para que el usuario lo vea)
                        close(procesandoE);

                        % Se llama a la función TablaMetricas para generar
                        % la tabla de las métricas
                        app.TablaMetricas(app.UITable4, h_vals, k_vals, err_max, err_L2, eoc_L2, tiempo)

                        % Gráfica de convergencia
                        titulo = 'EDP El\''iptica (Diferencias Finitas)';
                        app.GraficaConvergencia(app.UIAxes4, h_vals, err_L2, titulo);

                        % app.UITable4.Visible = 'on';
                        % app.Texto4.Visible = 'on';
                        % app.Exportar4.Visible = 'on';
                        % app.UIAxes4.Visible = 'on';
                        % app.Texto4.Value = 'Métricas del método';
                    else
                        uialert(app.UIFigure, ...
                            'Necesita la solución exacta para calcular la convergencia.', ...
                            'Aviso');
                    end              
            end
        end

        % Button pushed function: Exportar
        function ExportarButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable, app.Exportar);

        end

        % Button pushed function: Exportar2
        function Exportar2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable2, app.Exportar2);

        end

        % Button pushed function: Exportar3
        function Exportar3ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable3, app.Exportar3);

        end

        % Button pushed function: Exportar4
        function Exportar4ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable4, app.Exportar4);
        end

        % Button pushed function: ReseteoEliptica
        function ReseteoElipticaButtonPushed(app, event)
            % CONFIRMACIÓN SOLO SI APLICA
            if ~app.resetGeneral
                seleccion = uiconfirm(app.UIFigure, ...
                    '¿Está seguro de que desea borrar todos los datos y gráficos?', ...
                    'Confirmar Reseteo', ...
                    'Icon', 'warning', ...
                    'Options', {'Continuar', 'Cancelar'}, ...
                    'DefaultOption', 2);

                if ~strcmp(seleccion, 'Continuar')
                    return;
                end
            end

                % LIMPIAR EJES (GRÁFICAS)
                cla(app.UIAxes2, 'reset')
                cla(app.UIAxes3, 'reset')
                cla(app.UIAxes4, 'reset')
                cla(app.UIAxes5, 'reset')

                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                app.UIAxes4.Visible = 'off';
                app.UIAxes5.Visible = 'off';

                % LIMPIAR TABLAS
                app.UITable.Data = [];
                app.UITable2.Data = [];
                app.UITable3.Data = [];
                app.UITable4.Data = [];

                app.UITable.Visible = 'off';
                app.UITable2.Visible = 'off';
                app.UITable3.Visible = 'off';
                app.UITable4.Visible = 'off';

                % LIMPIAR CAMPOS DE TEXTO
                app.uizquierda.Value = '';
                app.uderecha.Value   = '';
                app.uabajo.Value     = '';
                app.uarriba.Value    = '';

                app.a.Value = '';
                app.b.Value = '';
                app.c.Value = '';
                app.d.Value = '';

                app.fxy.Value = '';
                app.uxy.Value = '';
                app.uxy.Enable = 'off';

                app.hx.Value = '';
                app.ky.Value = '';

                % RESET BOTONES
                app.RevisardatosEliptica.Text = 'Revisar datos';
                app.RevisardatosEliptica.BackgroundColor = [0.83,0.86,0.91];

                app.ResolverEliptica.Text = '▶ Resolver EDP';
                app.ResolverEliptica.BackgroundColor = [0.83,0.86,0.91];
                app.ResolverEliptica.Enable = 'off';

                exportButtons = [app.Exportar, app.Exportar2, app.Exportar3, app.Exportar4];

                for b3 = exportButtons
                    b3.Text = 'Exportar';
                    b3.BackgroundColor = [0.83,0.86,0.91];
                    b3.Visible = 'off';
                end

                textos = [app.Texto, app.Texto2, app.Texto3, app.Texto4];

                for t = textos
                    t.Visible = 'off';
                end

                % RESET Drop Down
                app.Resultados.BackgroundColor = [0.83,0.86,0.91];
                app.Resultados.Enable = 'off';
                app.Resultados.Value = 'Seleccionar resultado';

                app.MetodosSolucionParabolicas.Value = 'Seleccionar método';
                app.SiNo.Value = '';

                % MENSAJE FINAL SOLO SI ES MANUAL
                if ~app.resetGeneral
                    uialert(app.UIFigure, ...
                        '✔ Pestaña Elíptica reiniciada correctamente', ...
                        'Reseteo', ...
                        'Icon', 'success');
                end
        end

        % Callback function
        function MetodoParabolicaComparacionButtonPushed(app, event)
            
        end

        % Callback function
        function MetodoParabolicaCrankButtonPushed(app, event)

        end

        % Value changed function: MetodosSolucionParabolicas
        function MetodosSolucionParabolicasValueChanged(app, event)
            valor = app.MetodosSolucionParabolicas.Value;
            app.RevisarDatosParabolico.Text = 'Revisar datos';
            app.RevisarDatosParabolico.BackgroundColor = [0.83,0.86,0.91]; % Color original

            app.ResolverParabolicaExplicito.Text = '▶ Resolver EDP';
            app.ResolverParabolicaExplicito.BackgroundColor = [0.83,0.86,0.91]; % Color original
            app.ResolverParabolicaExplicito.Enable = "off";   

            app.ResultadosParabolico.Enable = "off";

            % LIMPIEZA TOTAL (ÚNICA)
            axesList = [app.UIAxes6, app.UIAxes7, app.UIAxes8, app.UIAxes9, ...
                app.UIAxes10, app.UIAxes11, app.UIAxes12, app.UIAxes13];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP.Visible = 'off';

            tables = [app.UITable5, app.UITable6, app.UITable7, app.UITable8, ...
                app.UITable9, app.UITable10, app.UITable11, app.UITable12];

            labels = [app.Texto5, app.Texto6, app.Texto7, app.Texto8, ...
                app.Texto9, app.Texto10, app.Texto11, app.Texto12];

            buttons = [app.Exportar5, app.Exportar6, app.Exportar7, app.Exportar8, ...
                app.Exportar9, app.Exportar10, app.Exportar11, app.Exportar12];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            app.PanelErrorConvergencia.Visible = 'off';

            switch valor
                    case 'Explícito'
                        app.PanelExplicito.Title = 'Explícito';
                        app.PanelExplicito.Visible = 'on';
                        app.RevisarDatosParabolico.Enable = "on";
                    case 'Crank-Nicholson'
                        app.PanelExplicito.Title = 'Crank-Nicholson';
                        app.PanelExplicito.Visible = 'on';
                        app.RevisarDatosParabolico.Enable = "on";
                    case 'Explícito y Crank-Nicholson'
                        app.PanelExplicito.Title = 'Explícito y Crank-Nicholson';
                        app.PanelExplicito.Visible = 'on';
                        app.RevisarDatosParabolico.Enable = "on";

                    otherwise
                        app.PanelExplicito.Title = ' ';
                        app.PanelExplicito.Visible = 'on';
                        app.RevisarDatosParabolico.Enable = "off";
            end
        end

        % Value changed function: SiNoP
        function SiNoPValueChanged(app, event)
            % Obtenemos el valor seleccionado (asegúrate que coincida con tus opciones)
            seleccion = app.SiNoP.Value; 
            
            % Comparamos la selección
            if strcmp(seleccion, 'Sí')
                app.uxt.Enable = 'on';   % Se activa para escribir la solución exacta
                app.uxt.BackgroundColor = [1 1 1]; % Opcional: fondo blanco (activo)
            else
                app.uxt.Enable = 'off';  % Se desactiva
                app.uxt.Value = '';      % Limpiamos el campo por si había algo escrito
                app.uxt.BackgroundColor = [0.94 0.94 0.94]; % Opcional: fondo gris (inactivo)
            end
        end

        % Value changed function: ResultadosParabolico
        function ResultadosParabolicoValueChanged(app, event)
            resultado = app.ResultadosParabolico.Value;
            metodo    = app.MetodosSolucionParabolicas.Value;

            % Recuperar datos ingresados:
            uExacta = app.u_exacta;
            L1 = app.LPPP;
            tmax1 = app.tmaxPPP;
            c1 = app.cPPP;
            % Condición inicial
            inferior = app.ux0PPP;
            % Condiciones de frontera
            izquierda = app.u0tPPP;
            derecha = app.uLtPPP;

            % LIMPIEZA TOTAL (ÚNICA)
            axesList = [app.UIAxes6, app.UIAxes7, app.UIAxes8, app.UIAxes9, ...
                app.UIAxes10, app.UIAxes11, app.UIAxes12, app.UIAxes13];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP.Visible = 'off';

            tables = [app.UITable5, app.UITable6, app.UITable7, app.UITable8, ...
                app.UITable9, app.UITable10, app.UITable11, app.UITable12];

            labels = [app.Texto5, app.Texto6, app.Texto7, app.Texto8, ...
                app.Texto9, app.Texto10, app.Texto11, app.Texto12];

            buttons = [app.Exportar5, app.Exportar6, app.Exportar7, app.Exportar8, ...
                app.Exportar9, app.Exportar10, app.Exportar11, app.Exportar12];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            for i = 5:12
                nombre = sprintf('Exportar%d', i);
                boton = app.(nombre);

                boton.Text = 'Exportar';
                boton.BackgroundColor = [0.83,0.86,0.91];
            end

            app.PanelErrorConvergencia.Visible = 'off';

            % Solución exacta evaluada
            u_exacta_evaluadaP = app.u_exacta_evaluadaPP;
            
            % MÉTODO
            switch metodo

                case 'Explícito'
                    % Recuperar datos de la resolución
                    x1P1 = app.x1PPE;
                    t1P1 = app.t1PPE;
                    uP1 = app.uPPE;
                    tiempoP1 = app.tiempoPPE;
                    rP1 = app.rPP;
                    METODO = 'Expl\''icito';

                    % Cálculo de error numérico
                    if ~isempty(u_exacta_evaluadaP)
                        e_P = abs(u_exacta_evaluadaP - uP1);
                    else
                        e_P = [];
                    end

                case 'Crank-Nicholson'
                    % Recuperar datos de la resolución
                    x1P1 = app.x1PPCN;
                    t1P1 = app.t1PPCN;
                    uP1 = app.uPPCN;
                    tiempoP1 = app.tiempoPPCN;
                    METODO = 'Crank-Nicholson';

                    % Cálculo de error numérico
                    if ~isempty(u_exacta_evaluadaP)
                        e_P = abs(u_exacta_evaluadaP - uP1);
                    else
                        e_P = [];
                    end

                case 'Explícito y Crank-Nicholson'
                    % Recuperar datos de la resolución
                    x1PE = app.x1PPE;
                    t1PE = app.t1PPE;
                    uPE = app.uPPE;
                    tiempoPE = app.tiempoPPE;
                    rP1 = app.rPP;

                    x1PCN = app.x1PPCN;
                    t1PCN = app.t1PPCN;
                    uPCN = app.uPPCN;
                    tiempoPCN = app.tiempoPPCN;

                    % Cálculo de error numérico
                    if ~isempty(u_exacta_evaluadaP)
                        e_PE = abs(u_exacta_evaluadaP - uPE);
                        e_PCN = abs(u_exacta_evaluadaP - uPCN);
                    else
                        e_PE = [];
                        e_PCN = [];
                    end

            end
            
            fuente_para_todas = 18;

            switch resultado

                % GRÁFICAS
                case 'Gráficas'

                    switch metodo

                        case {'Explícito','Crank-Nicholson'}
                            cla(app.UIAxes6);
                            cla(app.UIAxes7);
                                                        
                            % app.TextoDeslizadorP.Visible = 'on';

                            if strcmp(metodo, 'Explícito')
                               metodo1 = 'Expl\''icito';
                               AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)
                            end

                            if strcmp(metodo, 'Crank-Nicholson')
                               metodo1 = 'Crank-Nicholson';
                            end


                            app.DeslizadorP.Visible = 'on';
                            app.UIAxes6.Visible = 'on';
                            app.UIAxes7.Visible = 'on';
                            app.inicializarGraficasEDP( ...
                                x1P1, t1P1, uP1, ...
                                app.UIAxes6, app.UIAxes7, ...
                                'Parab\''olica', metodo1,...
                                app.DeslizadorP);

                            if ~isempty(u_exacta_evaluadaP)
                                app.UIAxes8.Visible = 'on';
                                cla(app.UIAxes8);
                                surf(app.UIAxes8, x1P1, t1P1, u_exacta_evaluadaP);

                                % 1. COLOR DE FONDO (0.83, 0.86, 0.91)
                                app.UIAxes8.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                                app.UIAxes8.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE
                                
                                % 3. CONFIGURACIÓN VISUAL
                                shading(app.UIAxes8, 'interp');
                                cMap3 = jet(256);
                                colormap(app.UIAxes8, cMap3);

                                cb3 = colorbar(app.UIAxes8);                       %  capturar el colorbar
                                cb3.Color = 'k';                                   % opcional (texto visible)
                                cb3.Box = 'on';                                    % opcional

                                % 4. FORMATO Y ETIQUETAS
                                app.UIAxes8.FontSize = fuente_para_todas;
                                xlabel(app.UIAxes8, '$$ x $$', 'Interpreter', 'latex');
                                ylabel(app.UIAxes8, '$$ y $$', 'Interpreter', 'latex');
                                zlabel(app.UIAxes8, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                                title(app.UIAxes8, '$$\mbox{Soluci\''on Exacta}$$', 'Interpreter', 'latex');
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la Solución Exacta.', ...
                                    'Aviso');
                            end

                        case 'Explícito y Crank-Nicholson'
                            % Gráfica Explícito
                            app.UIAxes9.Visible = 'on';
                            cla(app.UIAxes9);
                            surf(app.UIAxes9, x1PE, t1PE, uPE);

                            % COLOR DE FONDO (0.83, 0.86, 0.91)
                            app.UIAxes9.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                            app.UIAxes9.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE

                            % CONFIGURACIÓN VISUAL
                            shading(app.UIAxes9, 'interp');
                            cMap3 = jet(256);
                            colormap(app.UIAxes9, cMap3);

                            colorbar(app.UIAxes9);    
                            
                            % FORMATO Y ETIQUETAS
                            app.UIAxes9.FontSize = fuente_para_todas;
                            xlabel(app.UIAxes9, '$$ x $$', 'Interpreter', 'latex');
                            ylabel(app.UIAxes9, '$$ y $$', 'Interpreter', 'latex');
                            zlabel(app.UIAxes9, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                            tituloE = '$$\mbox{Soluci\''on M\''etodo Expl\''icito}$$';
                            title(app.UIAxes9, tituloE, 'Interpreter', 'latex')

                            % Gráfica Crank-Nicholson
                            app.UIAxes6.Visible = 'on';
                            cla(app.UIAxes6);
                            surf(app.UIAxes6, x1PCN, t1PCN, uPCN);

                            % COLOR DE FONDO (0.83, 0.86, 0.91)
                            app.UIAxes6.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                            app.UIAxes6.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE

                            % CONFIGURACIÓN VISUAL
                            shading(app.UIAxes6, 'interp');
                            cMap3 = jet(256);
                            colormap(app.UIAxes6, cMap3);

                            colorbar(app.UIAxes6);    
                            
                            % FORMATO Y ETIQUETAS
                            app.UIAxes6.FontSize = fuente_para_todas;
                            xlabel(app.UIAxes6, '$$ x $$', 'Interpreter', 'latex');
                            ylabel(app.UIAxes6, '$$ y $$', 'Interpreter', 'latex');
                            zlabel(app.UIAxes6, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                            tituloE = '$$\mbox{Soluci\''on M\''etodo Crank-Nicholson}$$';
                            title(app.UIAxes6, tituloE, 'Interpreter', 'latex')
                            
                            AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)

                            if ~isempty(u_exacta_evaluadaP)
                                app.UIAxes8.Visible = 'on';
                                cla(app.UIAxes8);
                                surf(app.UIAxes8, x1PE, t1PE, u_exacta_evaluadaP);

                                % 1. COLOR DE FONDO (0.83, 0.86, 0.91)
                                app.UIAxes8.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                                app.UIAxes8.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE

                                % 3. CONFIGURACIÓN VISUAL
                                shading(app.UIAxes8, 'interp');
                                cMap3 = jet(256);
                                colormap(app.UIAxes8, cMap3);

                                cb3 = colorbar(app.UIAxes8);                       %  capturar el colorbar
                                cb3.Color = 'k';                                   % opcional (texto visible)
                                cb3.Box = 'on';                                    % opcional

                                % FORMATO Y ETIQUETAS
                                app.UIAxes8.FontSize = fuente_para_todas;
                                xlabel(app.UIAxes8, '$$ x $$', 'Interpreter', 'latex');
                                ylabel(app.UIAxes8, '$$ y $$', 'Interpreter', 'latex');
                                zlabel(app.UIAxes8, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                                title(app.UIAxes8, '$$\mbox{Soluci\''on Exacta}$$', 'Interpreter', 'latex');
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la Solución Exacta.', ...
                                    'Aviso');
                            end
                    end


                % TABLAS
                case 'Tablas'

                    switch metodo
                        case {'Explícito','Crank-Nicholson'}
                            
                            % Ventana de progreso
                            procesandoTablas = uiprogressdlg(app.UIFigure, ...
                                'Title','Procesando', ...
                                'Message','Ensamblando tablas...', ...
                                'Value',0);
                            drawnow; % IMPORTANTE para que se vea

                            app.UITable5.Visible = 'on';
                            app.Texto5.Visible = 'on';
                            app.Exportar5.Visible = 'on';
                            app.Tablas33(app.UITable5,uP1,'t_')

                            if strcmp(metodo, 'Explícito')
                                TEXTO5 = 'Solución Numérica Explícita';
                                
                            end

                            if strcmp(metodo, 'Crank-Nicholson')
                                TEXTO5 = 'Solución Numérica Crank-Nicholson';
                            end
                            app.Texto5.Text = TEXTO5;

                            if ~isempty(u_exacta_evaluadaP)
                                app.UITable6.Visible = 'on';
                                app.Texto6.Visible = 'on';
                                app.Exportar6.Visible = 'on';
                                app.Texto6.Text = 'Solución Exacta';
                                app.Tablas33(app.UITable6,u_exacta_evaluadaP,'t_')
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la solución exacta.', ...
                                    'Aviso');
                            end
                            
                            % FINALIZACIÓN
                            procesandoTablas.Value = 1;
                            procesandoTablas.Message = 'Tablas listas';
                            drawnow;
                            pause(0.5); % opcional (para que el usuario lo vea)
                            close(procesandoTablas);

                            if strcmp(metodo, 'Explícito')
                                AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)
                            end
                        
                        case 'Explícito y Crank-Nicholson'
                            
                            % Ventana de progreso
                            procesandoTablas = uiprogressdlg(app.UIFigure, ...
                                'Title','Procesando', ...
                                'Message','Ensamblando tablas...', ...
                                'Value',0);
                            drawnow; % IMPORTANTE para que se vea

                            app.UITable7.Visible = 'on';
                            app.Texto7.Visible = 'on';
                            app.Exportar7.Visible = 'on';
                            app.Tablas33(app.UITable7,uPE  ,'t_')
                            app.Texto7.Text = 'Explícita';

                            app.UITable8.Visible = 'on';
                            app.Texto8.Visible = 'on';
                            app.Exportar8.Visible = 'on';
                            app.Tablas33(app.UITable8,uPCN ,'t_')
                            app.Texto8.Text = 'Crank-Nicholson';
 
                            if ~isempty(u_exacta_evaluadaP)
                                app.UITable9.Visible = 'on';
                                app.Texto9.Visible = 'on';
                                app.Exportar9.Visible = 'on';
                                app.Texto9.Text = 'Solución Exacta';
                                app.Tablas33(app.UITable9,u_exacta_evaluadaP,'t_')
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la solución exacta.', ...
                                    'Aviso');
                            end
                            
                            % FINALIZACIÓN
                            procesandoTablas.Value = 1;
                            procesandoTablas.Message = 'Tablas listas';
                            drawnow;
                            pause(0.5); % opcional (para que el usuario lo vea)
                            close(procesandoTablas);

                            AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)


                    end

                % ERRORES
                case 'Errores'

                    switch metodo
                        case {'Explícito','Crank-Nicholson'}
                            if ~isempty(u_exacta_evaluadaP)
                                % Ventana de progreso
                                procesandoErrores = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Presentando errores...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea

                                app.UITable5.Visible = 'on';
                                app.Texto5.Visible = 'on';
                                app.Exportar5.Visible = 'on';
                                if strcmp(metodo, 'Explícito')
                                    TEXTO5 = 'Error Numérico Explícita';
                                end

                                if strcmp(metodo, 'Crank-Nicholson')
                                    TEXTO5 = 'Error Numérico Crank-Nicholson';
                                end
                                app.Texto5.Text = TEXTO5;
                                app.Tablas33(app.UITable5,e_P,'t_')

                                % Limpiar eje
                                cla(app.UIAxes10);
                                app.UIAxes10.Visible = 'on';

                                % Gráfica de superficie del error
                                surf(app.UIAxes10, x1P1 , t1P1 , e_P);

                                % Vista desde arriba (mapa de calor)
                                view(app.UIAxes10, 2);

                                % Quitar malla de la superficie
                                shading(app.UIAxes10, 'interp');

                                % Barra de color
                                colorbar(app.UIAxes10);

                                % Formato
                                app.UIAxes10.FontSize = fuente_para_todas;

                                % % Etiquetas
                                xlabel(app.UIAxes10, '$$ x $$', 'Interpreter', 'latex');
                                ylabel(app.UIAxes10, '$$ t $$', 'Interpreter', 'latex', 'Rotation', 0);
                                title(app.UIAxes10, '$$\mbox{Mapa de Calor del Error Num\''erico}$$', 'Interpreter', 'latex');

                                % Opcional: colormap
                                colormap(app.UIAxes10, 'jet');

                               
                                % FINALIZACIÓN
                                procesandoErrores.Value = 1;
                                procesandoErrores.Message = 'Errores listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoErrores);

                                if strcmp(metodo, 'Explícito')
                                    AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)
                                end
                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular el error numérico.', ...
                                    'Aviso');
                            end


                        case 'Explícito y Crank-Nicholson'
                            AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)
                            if ~isempty(u_exacta_evaluadaP)
                                
                                e_E = abs(u_exacta_evaluadaP - uPE);
                                e_CN = abs(u_exacta_evaluadaP - uPCN);

                                % Norma infinito
                                Einf_E  = max(abs(e_E),[],2);
                                Einf_CN = max(abs(e_CN),[],2);

                                % Norma L2
                                hx2 = x1PCN(1,2) - x1PCN(1,1); 
                                tiempo = t1PCN(end:-1:1,1);
                                EL2_E  = sqrt(sum(e_E.^2,2) * hx2);
                                EL2_CN = sqrt(sum(e_CN.^2,2) * hx2);


                                % Tablas:
                                % Ventana de progreso
                                procesandoErrores = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Presentando errores...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea

                                app.PanelErrorConvergencia.Visible = 'on';

                                app.UITable10.Visible = 'on';
                                app.Texto10.Visible = 'on';
                                app.Exportar10.Visible = 'on';
                                app.Texto10.Text = 'Error Explícito';
                                app.Tablas33(app.UITable10,e_E,'t_')

                                app.UITable11.Visible = 'on';
                                app.Texto11.Visible = 'on';
                                app.Exportar11.Visible = 'on';
                                app.Texto11.Text = 'Error Crank-Nicholson';
                                app.Tablas33(app.UITable11,e_CN,'t_')

                                % Gráficas
                                cla(app.UIAxes11);
                                app.UIAxes11.Visible = 'on';

                                % Gráfica de superficie del error
                                hold(app.UIAxes11,"on")
                                plot(app.UIAxes11,tiempo, Einf_E, 'b','LineWidth',2)
                                plot(app.UIAxes11,tiempo, Einf_CN, 'r','LineWidth',2)
                                hold(app.UIAxes11,"off")
                                grid(app.UIAxes11,'on')
                                app.UIAxes11.Color = [0.83, 0.86, 0.91];
                                
                                % Formato
                                app.UIAxes11.FontSize = fuente_para_todas-2;

                                xlabel(app.UIAxes11,'$$t$$','Interpreter','latex')
                                ylabel(app.UIAxes11,'$$\| e \|_{\infty}$$','Interpreter','latex')
                                title(app.UIAxes11,'$$\mbox{Error en norma infinito vs tiempo}$$','Interpreter','latex')
                                leyenda{1} = '$$\mbox{Expl\''icito}$$';
                                leyenda{2} = '$$\mbox{Crank-Nicholson}$$';
                                legend(app.UIAxes11,leyenda,'Interpreter','latex','Location','best')

                                
                                cla(app.UIAxes12);
                                app.UIAxes12.Visible = 'on';

                                % Gráfica de superficie del error
                                hold(app.UIAxes12,"on")
                                plot(app.UIAxes12,tiempo, EL2_E, 'b','LineWidth',2)
                                plot(app.UIAxes12,tiempo, EL2_CN, 'r','LineWidth',2)
                                hold(app.UIAxes12,"off")
                                grid(app.UIAxes12,'on')
                                app.UIAxes12.Color = [0.83, 0.86, 0.91];

                                % Formato
                                app.UIAxes12.FontSize = fuente_para_todas-2;

                                xlabel(app.UIAxes12,'$$t$$','Interpreter','latex')
                                ylabel(app.UIAxes12,'$$\left\| u^{\mbox{num}} - u^{\mbox{exact}} \right\|_{L^2}$$','Interpreter','latex')
                                title(app.UIAxes12,'$$\mbox{Error en norma }L^2 \mbox{ vs tiempo}$$','Interpreter','latex')
                                legend(app.UIAxes12,leyenda,'Interpreter','latex','Location','best')

                                % FINALIZACIÓN
                                procesandoErrores.Value = 1;
                                procesandoErrores.Message = 'Errores listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoErrores);

                                AlertaEstabilidad(app, 'Parabólica', rP1, 0.5)

                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular el error numérico.', ...
                                    'Aviso');
                                
                            end

                    end

                % CONVERGENCIA
                case 'Convergencia del método'
                    switch metodo

                        case {'Explícito','Crank-Nicholson'}
                            if ~isempty(u_exacta_evaluadaP)

                                % Ventana de progreso
                                procesandoE = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Iniciando...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea
                                
                                % Valores de h y k para el análisis de
                                % convergencia Explícito
                                if strcmp(metodo, 'Explícito')
                                    h_efectivoPE = abs(x1P1(1,2)-x1P1(1,1));
                                    % k_efectivoPE = abs(t1P1(1,1)-t1P1(2,1));
                                    h_in = h_efectivoPE; % h inicial para la convergencia
                                    h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                                    % k_in = k_efectivoPE;
                                    k_vals = 0.4*h_vals.^2;  % k para convergencia
                                    % k_vals = [k_in, k_in/2, k_in/4, k_in/8];

                                    metodo1 = 'Explícito';
                                    titulo1 = 'EDP Parab\''olica (Expl\''icita)';
                                    TEXTO1 = 'Métricas Método Explícito';
                                    
                                end

                                % Valores de h y k para el análisis de
                                % convergencia Crank-Nicholson
                                if strcmp(metodo, 'Crank-Nicholson')
                                    h_efectivoPCN = abs(x1P1(1,2)-x1P1(1,1));
                                    % k_efectivoPCN = abs(t1P1(1,1)-t1P1(2,1));
                                    h_in = h_efectivoPCN;
                                    %h_in = 0.025; % h inicial para la convergencia
                                    h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                                    % k_in = k_efectivoPCN;
                                    % k_vals = 0.5*h_vals; 
                                    % k_vals = [k_in, k_in/2, k_in/4, k_in/8];
                                    k_vals = 0.4*h_vals.^2;
                                    metodo1 = 'Crank-Nicholson';
                                    titulo1 = 'EDP Parab\''olica (Crank-Nicholson)';
                                    TEXTO1 = 'Métricas Método Crank-Nicholson';
                                end


                                % Preparación de datos
                                procesandoE.Message = 'Discretizando la malla...';
                                procesandoE.Value = 0.2;
                                drawnow;

                                % Resolución de la EDP
                                procesandoE.Message = 'Resolviendo la EDP...';
                                procesandoE.Value = 0.5;
                                drawnow;

                                % Se llama a la función CalculoConvergencia para
                                % calcular las métricas
                                [err_max, err_L2, eoc_L2, tiempos] = ...
                                    app.CalculoConvergenciaParabolica(h_vals,...
                                    k_vals,c1,L1,tmax1,...
                                    inferior, izquierda, derecha, uExacta, metodo1);

                                % FINALIZACIÓN
                                procesandoE.Value = 1;
                                procesandoE.Message = 'Resultados listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoE);

                                % Se llama a la función TablaMetricas para generar
                                % la tabla de las métricas
                                app.TablaMetricas(app.UITable12, h_vals, k_vals, err_max, err_L2, eoc_L2, tiempos)

                                % Gráfica de convergencia
                                cla(app.UIAxes13)
                                app.UIAxes13.Visible = 'on';
                                app.GraficaConvergencia(app.UIAxes13, h_vals, err_L2, titulo1);
                                app.PanelErrorConvergencia.Visible = 'on';
                                app.UITable12.Visible = 'on';
                                app.Texto12.Visible = 'on';
                                app.Texto12.Text = TEXTO1;
                                app.Exportar12.Visible = 'on';

                                if strcmp(metodo, 'Explícito')
                                    if rP1 > 0.5
                                        mensaje = sprintf(['Aunque el método explícito para la EDP Parabólica\n' ...
                                            'es INESTABLE, se han considerado valores de h y k que generan valores de\n' ...
                                            'r <= 0.5 para analizar la convergencia.']);

                                        aviso = uiconfirm(app.UIFigure, ...
                                            mensaje, ...
                                            'Análisis de convergencia listo', ...
                                            'Icon','info');
                                    end
                                end

                                
                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular la convergencia.', ...
                                    'Aviso');
                            end


                        case 'Explícito y Crank-Nicholson'

                            if ~isempty(u_exacta_evaluadaP)
                                
                                % Ventana de progreso
                                procesandoE = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Iniciando...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea

                                % Valores de h y k para el análisis Explícito

                                h_efectivoPE = abs(x1PE(1,2)-x1PE(1,1));
                                % k_efectivoPE = abs(t1PE(1,1)-t1PE(2,1));
                                h_in = h_efectivoPE; % h inicial para la convergencia
                                h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                                % k_in = k_efectivoPE;
                                k_vals = 0.4*h_vals.^2;  % k para convergencia
                                % k_vals = [k_in, k_in/2, k_in/4, k_in/8];
                                % h_in = 0.025; % h inicial para la convergencia
                                % h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                                % k_vals = 0.4*h_vals.^2;  % k para convergencia

                                metodo1 = 'Explícito';
                                titulo1 = 'Parab\''olica (Expl\''icita)';
                                TEXTO1 = 'Métricas Explícito';

                                % ETAPA 1: Preparación de datos
                                procesandoE.Message = 'Discretizando la malla...';
                                procesandoE.Value = 0.2;
                                drawnow;

                                % ETAPA 2: Resolución de la EDP
                                procesandoE.Message = 'Resolviendo la EDP...';
                                procesandoE.Value = 0.5;
                                drawnow;

                                % Se llama a la función CalculoConvergencia para
                                % calcular las métricas
                                [err_max, err_L2, eoc_L2, tiempos] = ...
                                    app.CalculoConvergenciaParabolica(h_vals,...
                                    k_vals,c1,L1,tmax1,...
                                    inferior, izquierda, derecha, uExacta, metodo1);

                                % Se llama a la función TablaMetricas para generar
                                % la tabla de las métricas
                                app.TablaMetricas(app.UITable10, h_vals, k_vals, err_max, err_L2, eoc_L2, tiempos)

                                % Gráfica de convergencia
                                cla(app.UIAxes11)
                                app.GraficaConvergencia(app.UIAxes11, h_vals, err_L2, titulo1);
                                app.UIAxes11.Visible = 'on';
                                app.PanelErrorConvergencia.Visible = 'on';
                                app.UITable10.Visible = 'on';
                                app.Texto10.Visible = 'on';
                                app.Texto10.Text = TEXTO1;
                                app.Exportar10.Visible = 'on';
                                
                                % Valores de h y k para el análisis Crank-Nicholson
                                h_efectivoPCN = abs(x1PCN(1,2)-x1PCN(1,1));
                                % k_efectivoPCN = abs(t1PCN(1,1)-t1PCN(2,1));
                                h_in = h_efectivoPCN; % h inicial para la convergencia
                                h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                                % k_in = k_efectivoPCN;
                                k_vals = 0.4*h_vals.^2;  % k para convergencia
                                % k_vals = [k_in, k_in/2, k_in/4, k_in/8];
                                
                                % h_in = 0.025; % h inicial para la convergencia
                                % h_vals = [h_in, h_in/2, h_in/4, h_in/8]; % h para convergencia
                                % k_vals = 0.4*h_vals.^2;
                                metodo1 = 'Crank-Nicholson';
                                titulo1 = 'Parab\''olica (Crank-Nicholson)';
                                TEXTO1 = 'Métricas Crank-Nicholson';
                                
                                % Se llama a la función CalculoConvergencia para
                                % calcular las métricas

                                [err_max, err_L2, eoc_L2, tiempos] = ...
                                    app.CalculoConvergenciaParabolica(h_vals,...
                                    k_vals,c1,L1,tmax1,...
                                    inferior, izquierda, derecha, uExacta, metodo1);

                                % Se llama a la función TablaMetricas para generar
                                % la tabla de las métricas
                                app.TablaMetricas(app.UITable11, h_vals, k_vals, err_max, err_L2, eoc_L2, tiempos)

                                % Gráfica de convergencia
                                cla(app.UIAxes12)
                                app.UIAxes12.Visible = 'on';
                                app.GraficaConvergencia(app.UIAxes12, h_vals, err_L2, titulo1);
                                app.PanelErrorConvergencia.Visible = 'on';
                                app.UITable11.Visible = 'on';
                                app.Texto11.Visible = 'on';
                                app.Texto11.Text = TEXTO1;
                                app.Exportar11.Visible = 'on';
                                
                                % FINALIZACIÓN
                                procesandoE.Value = 1;
                                procesandoE.Message = 'Resultados listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoE);

                                % Forzar actualización de la interfaz
                                drawnow;

                                % Esperar un instante pequeño
                                pause(0.1);

                                if rP1 > 0.5
                                    mensaje = sprintf(['Aunque el método explícito para la EDP Parabólica ' ...
                                        'es INESTABLE, se han considerado valores de h y k que generan valores de' ...
                                        'r <= 0.5 para analizar la convergencia.']);

                                    aviso = uiconfirm(app.UIFigure, ...
                                        mensaje, ...
                                        'Análisis de convergencia listo', ...
                                        'Icon','info');
                                end

                               
                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular la convergencia.', ...
                                    'Aviso');
                            end
                    end
            end
            
        end

        % Button pushed function: ResolverParabolicaExplicito
        function ResolverParabolicaExplicitoButtonPushed(app, event)
            % Ventana de progreso
            procesandoE = uiprogressdlg(app.UIFigure, ...
            'Title','Procesando', ...
            'Message','Iniciando...', ...
            'Value',0);
            drawnow; % IMPORTANTE para que se vea
            
            % RECUPERAR DATOS
            LPP1 = app.LPPP;
            tmaxPP1 = app.tmaxPPP;
            hPP1 = app.hPPP;
            kPP1 = app.kPPP;
            cPP1 = app.cPPP;
            
            % Condición inicial
            ux0PP1 = app.ux0PPP;

            % Condiciones de contorno:
            u0tPP1 = app.u0tPPP;
            uLtPP1 = app.uLtPPP;

            u_exactaP = app.u_exacta;
            % app.u_exacta = u_e; % Ya lo guarda automáticamente
            % la función app.validarSolExacta

            % Preparación de datos
            procesandoE.Message = 'Discretizando la malla...';
            procesandoE.Value = 0.2;
            drawnow;

            % Resolución de la EDP
            procesandoE.Message = 'Resolviendo la EDP...';
            procesandoE.Value = 0.5;
            drawnow;

            metodo = app.MetodosSolucionParabolicas.Value; % Para determinar 
            % qué caso hay que resolver.

            % CASO: EXPLÍCITO
            if strcmp(metodo,'Explícito')
                % Se llama a la función app.Parabolica_E para resolver la EDP
                [x1PE,t1PE,uPE,tiempoPE,rP,h_efectivoPE,k_efectivoPE] = app.Parabolica_E(hPP1,kPP1,cPP1,LPP1,tmaxPP1,...
                    ux0PP1,u0tPP1,uLtPP1);

                % Guardar las variables calculadas para usos futuros
                app.x1PPE = x1PE;
                app.t1PPE = t1PE;
                app.uPPE = uPE;
                app.tiempoPPE = tiempoPE;
                app.rPP = rP;

                % FINALIZACIÓN
                procesandoE.Value = 1;
                procesandoE.Message = 'Resultados listos';
                drawnow;

                pause(0.5); % opcional (para que el usuario lo vea)

                close(procesandoE);

                % % Mensaje final
                if rP<=0.5
                    % Mensaje final
                    tiempofinal = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h^2 =%.4f <= 0.5\n' ...
                        '⇒El método es ESTABLE.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE, rP,h_efectivoPE,k_efectivoPE);

                    uialert(app.UIFigure, ...
                        tiempofinal, ...
                        'Proceso completado','Icon', 'success');

                else
                    % Mensaje final
                    tiempofinal = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h^2 =%.4f > 0.5\n' ...
                        '⇒El método es INESTABLE.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE, rP,h_efectivoPE,k_efectivoPE);
                    uialert(app.UIFigure, ...
                        tiempofinal, ...
                        'Proceso completado','Icon', 'success');
                end

                % Solución Exacta
                if ~isempty(u_exactaP)
                    u_exacta_evaluadaP = u_exactaP(x1PE,t1PE);

                else
                    u_exacta_evaluadaP = [];
                end
            end


             % CASO: CRANK-NICHOLSON
            if strcmp(metodo,'Crank-Nicholson')
                % % Se llama a la función app.Parabolica_CN para resolver la EDP
                [x1PCN,t1PCN,uPCN,tiempoPCN,h_efectivoPCN,k_efectivoPCN] = app.Parabolica_CN(hPP1,kPP1,cPP1,LPP1,tmaxPP1,...
                    ux0PP1,u0tPP1,uLtPP1);

                % Guardar las variables calculadas para usos futuros
                app.x1PPCN = x1PCN;
                app.t1PPCN = t1PCN;
                app.uPPCN = uPCN;
                app.tiempoPPCN = tiempoPCN;

                % FINALIZACIÓN
                procesandoE.Value = 1;
                procesandoE.Message = 'Resultados listos';
                drawnow;

                pause(0.5); % opcional (para que el usuario lo vea)

                close(procesandoE);

                 % Mensaje final
                    tiempofinal = sprintf(['Tiempo de ejecución del Método Crank-Nicholson:\n '...
                        '%0.4f segundos. El método es incondicionalmente ESTABLE.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f.'],...
                        tiempoPCN,h_efectivoPCN,k_efectivoPCN);
                    uialert(app.UIFigure, ...
                        tiempofinal, ...
                        'Proceso completado','Icon', 'success');

                % Solución Exacta
                if ~isempty(u_exactaP)
                    u_exacta_evaluadaP = u_exactaP(x1PCN,t1PCN);

                else
                    u_exacta_evaluadaP = [];
                end

            end

            % CASO: EXPLÍCITO Y CRANK-NICHOLSON
            if strcmp(metodo,'Explícito y Crank-Nicholson')
                % Se llama a la función app.Parabolica_E para resolver la EDP
                [x1PE,t1PE,uPE,tiempoPE,rP,h_efectivoPE,k_efectivoPE] = app.Parabolica_E(hPP1,kPP1,cPP1,LPP1,tmaxPP1,...
                    ux0PP1,u0tPP1,uLtPP1);

                % Guardar las variables calculadas para usos futuros
                app.x1PPE = x1PE;
                app.t1PPE = t1PE;
                app.uPPE = uPE;
                app.tiempoPPE = tiempoPE;
                app.rPP = rP;

                % % Se llama a la función app.Parabolica_CN para resolver la EDP
                [x1PCN,t1PCN,uPCN,tiempoPCN,~,~] = app.Parabolica_CN(hPP1,kPP1,cPP1,LPP1,tmaxPP1,...
                    ux0PP1,u0tPP1,uLtPP1);

                % Guardar las variables calculadas para usos futuros
                app.x1PPCN = x1PCN;
                app.t1PPCN = t1PCN;
                app.uPPCN = uPCN;
                app.tiempoPPCN = tiempoPCN;

                % FINALIZACIÓN
                procesandoE.Value = 1;
                procesandoE.Message = 'Resultados listos';
                drawnow;

                pause(0.5); % opcional (para que el usuario lo vea)
        
                close(procesandoE);

                % Mensaje final
                if rP<=0.5
                    mensaje = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h^2 =%.4f <= 0.5\n' ...
                        'El método es ESTABLE.\n\n' ...
                        'Tiempo de ejecución del Método Crank-Nicholson: %.4f segundos.\n' ...
                        'Es incondicionalmente estable.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE, rP, tiempoPCN,h_efectivoPE,k_efectivoPE);

                    uialert(app.UIFigure, ...
                        mensaje, ...
                        'Proceso completado', ...
                        'Icon','success');

                elseif rP>0.5
                    mensaje = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h^2 =%.4f > 0.5\n' ...
                        'El método es INESTABLE.\n\n' ...
                        'Tiempo de ejecución del Método Crank-Nicholson: %.4f segundos.\n' ...
                        'Es incondicionalmente estable.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE, rP, tiempoPCN,h_efectivoPE,k_efectivoPE);

                    uialert(app.UIFigure, ...
                        mensaje, ...
                        'Proceso completado', ...
                        'Icon','success');
                end
                
                % Solución Exacta
                if ~isempty(u_exactaP)
                    u_exacta_evaluadaP = u_exactaP(x1PCN,t1PCN);

                else
                    u_exacta_evaluadaP = [];
                end
            end
            % Guardar la solución exacta evaluada para usarla en otros espacios:
            app.u_exacta_evaluadaPP = u_exacta_evaluadaP;

            % ÉXITO
            app.ResolverParabolicaExplicito.Text = '✔ EDP Resuelta';
            app.ResolverParabolicaExplicito.BackgroundColor = [0.6 0.85 0.6]; % verde suave

            % ACTIVAR EL DROP DOWN RESULTADOS
            app.ResultadosParabolico.Enable = 'on';
            app.PanelResultadosParabolico.BackgroundColor = [0.83,0.86,0.91];
            
            app.ResultadosParabolico.Items = {'Seleccione una opción', 'Gráficas', 'Tablas', 'Errores', 'Convergencia del método'};
            app.ResultadosParabolico.Value = 'Seleccione una opción';         
                        
        end

        % Button pushed function: RevisarDatosParabolico
        function RevisarDatosParabolicoButtonPushed(app, event)
                % Desactivar botón resolver inicialmente
                app.ResolverParabolicaExplicito.Enable = 'off';

                metodo = app.MetodosSolucionParabolicas.Value;

                if isempty(metodo)
                    uialert(app.UIFigure,'Seleccione un método de solución','Error');
                    return;
                end

                % VALIDAR CAMPOS VACÍOS
                campos = {
                    'u(x,0)', app.ux0.Value;
                    'u(0,t)', app.u0t.Value;
                    'u(L,t)', app.uLt.Value;
                    'c', app.cP.Value;
                    'L', app.L.Value;
                    'tmax', app.tmax.Value;
                    'h', app.hP.Value;
                    'k', app.kP.Value;
                    };

                for i = 1:size(campos,1)
                    if isempty(campos{i,2})
                        uialert(app.UIFigure, ['El campo ', campos{i,1}, ' está vacío'], 'Error');
                        return;
                    end
                end

                % Convertir datos
                LPP   = str2num(app.L.Value);
                tmaxPP = str2num(app.tmax.Value);
                hPP   = str2num(app.hP.Value);
                kPP   = str2num(app.kP.Value);
                cPP   = str2num(app.cP.Value);

                % Validar números
                if any(isnan([LPP tmaxPP hPP kPP cPP]))
                    uialert(app.UIFigure,'Todos los campos numéricos deben estar llenos y deben ser números','Error');
                    return;
                end

                % Validaciones básicas
                if LPP<=0 
                    uialert(app.UIFigure,'L debe ser mayor que cero','Error');
                    return;
                end

                if tmaxPP<=0
                    uialert(app.UIFigure,'tmax ser mayor que cero','Error');
                    return;
                end

                if hPP<=0 
                    uialert(app.UIFigure,'h debe ser mayor que cero','Error');
                    return;
                end

                if kPP<=0
                    uialert(app.UIFigure,'k deben ser mayor que cero','Error');
                    return;
                end

                if cPP<=0
                    uialert(app.UIFigure,'El coeficiente c debe ser mayor que cero','Error');
                    return;
                end

                % VALIDACIÓN DE ESTABILIDAD
                % CASO 1: SOLO EXPLÍCITO y/o EXPLÍCITO Y CRANMK-NICHOLSON ---

                if strcmp(metodo,'Explícito') || strcmp(metodo,'Explícito y Crank-Nicholson')

                    % Número de subintervalos espaciales sugerido
                    Nx = round(LPP/hPP);

                    % h sugerido
                    h_sugerido = LPP/Nx;

                    % VALIDACIÓN DE h
                    if abs(LPP/hPP - Nx) > 1e-10
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de h ingresado no divide exactamente\n' ...
                            'el intervalo espacial.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'h = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'n = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            h_sugerido, Nx), ...
                            'Advertencia espacial', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');
                    end

                    % VALIDACIÓN DE k
                    Nt = round(tmaxPP/kPP);

                    % k sugerido SOLO por divisibilidad temporal
                    k_division = tmaxPP/Nt;

                    if abs(tmaxPP/kPP - Nt) > 1e-10

                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de k ingresado no divide exactamente\n' ...
                            'el intervalo temporal.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'k = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'm = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            k_division, Nt), ...
                            'Advertencia temporal', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');
                    end

                    % CONDICIÓN DE ESTABILIDAD
                    r = cPP*kPP/(hPP^2);
                    if r > 0.5

                        % Paso máximo estable
                        k_estable = (hPP^2)/(2*cPP);

                        % Ajustar Nt entero
                        Nt_estable = ceil(tmaxPP/k_estable);

                        % k sugerido estable
                        k_sugerido = tmaxPP/Nt_estable;

                        % Nuevo r
                        r_sugerido = cPP*k_sugerido/(hPP^2);

                        % ADVERTENCIA DE ESTABILIDAD
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de k ingresado NO satisface\n' ...
                            'la condición de estabilidad del método explícito.\n\n' ...
                            'Condición requerida:\n' ...
                            'r = ck/h^2 <= 0.5\n\n' ...
                            'Valor actual:\n' ...
                            'r = %.6f\n\n' ...
                            'Esto puede producir:\n' ...
                            '- Oscilaciones numéricas\n' ...
                            '- Crecimiento no físico de la solución\n' ...
                            '- Divergencia del método\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'k = %.10f\n' ...
                            'm = %d\n' ...
                            'r = %.6f\n\n' ...
                            'El valor sugerido cumple:\n' ...
                            '- Estabilidad numérica\n' ...
                            '- tmax/k entero'], ...
                            r, k_sugerido, Nt_estable, r_sugerido), ...
                            'Advertencia de estabilidad', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');
                    end
                end


                % CASO: CRANK-NICHOLSON
                if strcmp(metodo,'Crank-Nicholson')

                    % VALIDACIÓN DE h
                    Nx = round(LPP/hPP);

                    if abs(LPP/hPP - Nx) > 1e-10
                        % h sugerido
                        h_sugerido = LPP/Nx;
                        % Advertencia
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de h ingresado no divide exactamente\n' ...
                            'el intervalo espacial.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'h = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'n = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            h_sugerido, Nx), ...
                            'Advertencia espacial', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');
                    end

                    % VALIDACIÓN DE k
                    Nt = tmaxPP/kPP;

                    if abs(Nt - round(Nt)) > 1e-10
                        % Número entero sugerido
                        N = round(Nt);

                        % Evitar N = 0
                        if N <= 0
                            N = 1;
                        end

                        % k sugerido
                        k_sugerido = tmaxPP/N;

                        % Advertencia
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de k ingresado no divide exactamente\n' ...
                            'el intervalo temporal.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'k = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'm = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            k_sugerido, N), ...
                            'Advertencia temporal', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');
                    end
                end

                % Validar funciones
                % u(x,0) - Validación flexible
                syms x t
                val_ux0 = app.ux0.Value;

                % 1. Verificar si el campo está vacío
                if isempty(strtrim(val_ux0))
                    uialert(app.UIFigure, 'El campo u(x,0) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    ux0_sym = str2sym(val_ux0);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en el campo u(x,0). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % 2. Validar variables permitidas
                vars = symvar(ux0_sym); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(vars)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(vars(i), x))
                        uialert(app.UIFigure, ...
                            ['En u(x,0) hay variables no permitidas: "' char(vars(i)) '". Solo se permite x.'], ...
                            'Error');
                        return;
                    end
                end

                % 3. Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    ux0PP = matlabFunction(ux0_sym, 'Vars', x);
                catch
                    uialert(app.UIFigure, 'Error al procesar el campo u(x,0).', 'Error');
                    return;
                end

                % u(0,t) y u(L,t) - Validación flexible condiciones de contorno
                val_u0t = app.u0t.Value;
                % val_uLt = app.uLt.Value;
                % Verificar si el campo está vacío
                if isempty(strtrim(val_u0t))
                    uialert(app.UIFigure, 'El campo u(0,t) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    u0t_sym = str2sym(val_u0t);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en el campo u(0,t). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % Validar variables permitidas
                vars2 = symvar(u0t_sym); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(vars2)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(vars2(i), t))
                        uialert(app.UIFigure, ...
                            ['En el campo u(0,t) hay variables no permitidas: "' char(vars2(i)) '". Solo se permite t.'], ...
                            'Error');
                        return;
                    end
                end

                % Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    u0tPP = matlabFunction(u0t_sym, 'Vars', t);
                catch
                    uialert(app.UIFigure, 'Error al procesar el campo u(0,t).', 'Error');
                    return;
                end

                val_uLt = app.uLt.Value;
                % Verificar si el campo está vacío
                if isempty(strtrim(val_uLt))
                    uialert(app.UIFigure, 'El campo u(L,t) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    uLt_sym = str2sym(val_uLt);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en el campo u(L,t). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % Validar variables permitidas
                vars3 = symvar(uLt_sym); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(vars3)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(vars3(i), t))
                        uialert(app.UIFigure, ...
                            ['En u(L,t) hay variables no permitidas: "' char(vars3(i)) '". Solo se permite t.'], ...
                            'Error');
                        return;
                    end
                end

                % Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    uLtPP = matlabFunction(uLt_sym, 'Vars', t);
                catch
                    uialert(app.UIFigure, 'Error al procesar el campo u(L,t).', 'Error');
                    return;
                end

                % Validar solución exacta
                ok = app.validarSolExacta( ...
                    app.uxt.Value, ...
                    app.SiNoP.Value, ...
                    {'x','t'}, ...
                    'u(x,t)' );

                % Para Parabólicas
                if ~ok
                    return;
                end

                uialert(app.UIFigure, ...
                    'Datos verificados correctamente', ...
                    'Éxito', ...
                    'Icon','success');

                % GUARDAR VARIABLES PARA USARLAS EN CUALQUIER MOMENTO
                app.LPPP = LPP;
                app.tmaxPPP = tmaxPP;
                app.hPPP = hPP;
                app.kPPP = kPP;
                app.cPPP = cPP;

                % Condición inicial
                app.ux0PPP = ux0PP;

                % Condiciones de frontera
                app.u0tPPP = u0tPP;
                app.uLtPPP = uLtPP;

                % ÉXITO
                app.RevisarDatosParabolico.Text = '✔ Datos revisados';
                app.RevisarDatosParabolico.BackgroundColor = [0.6 0.85 0.6]; % Verde suave
  
                % ACTIVAR EL BOTÓN RESOLVER EDP
                app.ResolverParabolicaExplicito.Enable = 'on';
                
        end

        % Value changed function: DeslizadorP
        function DeslizadorPValueChanged(app, event)
            t_val = event.Value;

            app.actualizarGraficasEDP( ...
                t_val, ...
                app.UIAxes6, app.UIAxes7);
        end

        % Button pushed function: Exportar5
        function Exportar5ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable5, app.Exportar5);
        end

        % Button pushed function: Exportar6
        function Exportar6ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable6, app.Exportar6);
        end

        % Button pushed function: Exportar12
        function Exportar12ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable12, app.Exportar12);
        end

        % Button pushed function: Exportar11
        function Exportar11ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable11, app.Exportar11);
        end

        % Button pushed function: Exportar10
        function Exportar10ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable10, app.Exportar10);
        end

        % Button pushed function: Exportar7
        function Exportar7ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable7, app.Exportar7);
        end

        % Button pushed function: Exportar9
        function Exportar9ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable9, app.Exportar9);
        end

        % Button pushed function: Exportar8
        function Exportar8ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable8, app.Exportar8);
        end

        % Button pushed function: ReseteoParabolica
        function ReseteoParabolicaButtonPushed(app, event)
            % RESET TOTAL APP PARABÓLICA

            % 1. CONFIRMACIÓN SOLO SI APLICA
            if ~app.resetGeneral
                seleccion = uiconfirm(app.UIFigure, ...
                    '¿Está seguro de que desea borrar todos los datos y gráficos?', ...
                    'Confirmar Reseteo', ...
                    'Icon', 'warning', ...
                    'Options', {'Continuar', 'Cancelar'}, ...
                    'DefaultOption', 2);

                if ~strcmp(seleccion, 'Continuar')
                    return;
                end
            end

            % 2. LIMPIEZA TOTAL
            axesList = [app.UIAxes6, app.UIAxes7, app.UIAxes8, app.UIAxes9, ...
                app.UIAxes10, app.UIAxes11, app.UIAxes12, app.UIAxes13];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP.Visible = 'off';

            tables = [app.UITable5, app.UITable6, app.UITable7, app.UITable8, ...
                app.UITable9, app.UITable10, app.UITable11, app.UITable12];

            labels = [app.Texto5, app.Texto6, app.Texto7, app.Texto8, ...
                app.Texto9, app.Texto10, app.Texto11, app.Texto12];

            buttons = [app.Exportar5, app.Exportar6, app.Exportar7, app.Exportar8, ...
                app.Exportar9, app.Exportar10, app.Exportar11, app.Exportar12];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            app.PanelErrorConvergencia.Visible = 'off';

            % 3. RESET BOTONES
            app.RevisarDatosParabolico.Text = 'Revisar datos';
            app.RevisarDatosParabolico.BackgroundColor = [0.83,0.86,0.91];

            app.ResolverParabolicaExplicito.Text = '▶ Resolver EDP';
            app.ResolverParabolicaExplicito.BackgroundColor = [0.83,0.86,0.91];
            app.ResolverParabolicaExplicito.Enable = "off";

            app.ResultadosParabolico.Enable = "off";

            % 4. LIMPIAR CAMPOS
            app.tmax.Value = '';
            app.L.Value = '';
            app.uxt.Value = '';
            app.uxt.Enable = 'off';
            app.uLt.Value = '';
            app.u0t.Value = '';
            app.ux0.Value = '';
            app.cP.Value = '';
            app.hP.Value = '';
            app.kP.Value = '';

            % 5. RESET BOTONES EXPORTAR
            for i = 5:12
                btn = app.(['Exportar' num2str(i)]);
                btn.Text = ['Exportar ' num2str(i)];
                btn.BackgroundColor = [0.83,0.86,0.91];
                btn.Visible = 'off';
            end

            % 6. RESET LABELS
            for i = 5:12
                app.(['Texto' num2str(i)]).Visible = 'off';
            end

            % 7. RESET DROPDOWNS
            app.ResultadosParabolico.BackgroundColor = [0.83,0.86,0.91];
            app.ResultadosParabolico.Enable = 'off';
            app.ResultadosParabolico.Items = {'Seleccionar resultado', ...
                'Gráficas', 'Tablas', 'Errores', 'Convergencia del método'};
            app.ResultadosParabolico.Value = 'Seleccionar resultado';

            app.MetodosSolucionParabolicas.Items = {'Seleccionar método', ...
                'Explícito', 'Crank-Nicholson', 'Explícito y Crank-Nicholson'};
            app.MetodosSolucionParabolicas.Value = 'Seleccionar método';

            app.SiNoP.Value = '';

            % 8. MENSAJE FINAL SOLO SI ES MANUAL
            if ~app.resetGeneral
                uialert(app.UIFigure, ...
                    '✔ Pestaña Parabólica reiniciada correctamente', ...
                    'Reseteo', ...
                    'Icon', 'success');
            end            
        end

        % Value changed function: MetodosSolucionParabolicas_2
        function MetodosSolucionParabolicas_2ValueChanged(app, event)
            valor = app.MetodosSolucionParabolicas_2.Value;
            app.RevisarDatosParabolico_2.Text = 'Revisar datos';
            app.RevisarDatosParabolico_2.BackgroundColor = [0.83,0.86,0.91]; % Color original

            app.ResolverParabolicaExplicito_2.Text = '▶ Resolver EDP';
            app.ResolverParabolicaExplicito_2.BackgroundColor = [0.83,0.86,0.91]; % Color original
            app.ResolverParabolicaExplicito_2.Enable = "off";   

            app.ResultadosParabolico_2.Enable = "off";

            % LIMPIEZA TOTAL (ÚNICA)
            axesList = [app.UIAxes6_2, app.UIAxes7_2, app.UIAxes8_2, app.UIAxes9_2, ...
                app.UIAxes10_2, app.UIAxes11_2, app.UIAxes12_2, app.UIAxes13_2];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP_2.Visible = 'off';

            tables = [app.UITable5_2, app.UITable6_2, app.UITable7_2, app.UITable8_2, ...
                app.UITable9_2, app.UITable10_2, app.UITable11_2, app.UITable12_2];

            labels = [app.Texto5_2, app.Texto6_2, app.Texto7_2, app.Texto8_2, ...
                app.Texto9_2, app.Texto10_2, app.Texto11_2, app.Texto12_2];

            buttons = [app.Exportar5_2, app.Exportar6_2, app.Exportar7_2, app.Exportar8_2, ...
                app.Exportar9_2, app.Exportar10_2, app.Exportar11_2, app.Exportar12_2];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            app.PanelErrorConvergencia_2.Visible = 'off';
            

            switch valor
                case 'Explícito'
                    app.PanelExplicito_2.Title = 'Explícito';
                    app.PanelExplicito_2.Visible = 'on';
                    app.RevisarDatosParabolico_2.Enable = "on";
                case 'Implícito'
                    app.PanelExplicito_2.Title = 'Implícito';
                    app.PanelExplicito_2.Visible = 'on';
                    app.RevisarDatosParabolico_2.Enable = "on";
                case 'Explícito e Implícito'
                    app.PanelExplicito_2.Title = 'Explícito e Implícito';
                    app.PanelExplicito_2.Visible = 'on';
                    app.RevisarDatosParabolico_2.Enable = "on";

                case 'Seleccionar método'
                    app.PanelExplicito_2.Title = 'Método';
                    app.PanelExplicito_2.Visible = 'on';
                    app.RevisarDatosParabolico_2.Enable = "off";
            end
            
        end

        % Button pushed function: RevisarDatosParabolico_2
        function RevisarDatosParabolico_2ButtonPushed(app, event)
            % Desactivar botón resolver inicialmente
                app.ResolverParabolicaExplicito_2.Enable = 'off';

                metodo = app.MetodosSolucionParabolicas_2.Value;

                if isempty(metodo)
                    uialert(app.UIFigure,'Seleccione un método de solución','Error');
                    return;
                end


                % VALIDAR CAMPOS VACÍOS
                campos = {
                    'u(x,0)', app.ux0_2.Value;
                    'u_t(x,0)', app.utx0_2.Value;
                    'u(0,t)', app.u0t_2.Value;
                    'u(L,t)', app.uLt_2.Value;
                    'c^2', app.cP_2.Value;
                    'L', app.L_2.Value;
                    'tmax', app.tmax_2.Value;
                    'h', app.hP_2.Value;
                    'k', app.kP_2.Value;
                    };

                for i = 1:size(campos,1)
                    if isempty(campos{i,2})
                        uialert(app.UIFigure, ['El campo ', campos{i,1}, ' está vacío'], 'Error');
                        return;
                    end
                end

                % Convertir datos
                LPP_2   = str2num(app.L_2.Value);
                tmaxPP_2 = str2num(app.tmax_2.Value);
                hPP_2   = str2num(app.hP_2.Value);
                kPP_2   = str2num(app.kP_2.Value);
                cPP_2   = str2num(app.cP_2.Value);

                % Validar números
                if any(isnan([LPP_2 tmaxPP_2 hPP_2 kPP_2 cPP_2]))
                    uialert(app.UIFigure,'Todos los campos numéricos deben estar llenos y deben ser números','Error');
                    return;
                end

                % Validaciones básicas
                if LPP_2<=0 
                    uialert(app.UIFigure,'L debe ser mayor que cero','Error');
                    return;
                end

                if tmaxPP_2<=0
                    uialert(app.UIFigure,'tmax ser mayor que cero','Error');
                    return;
                end

                if hPP_2<=0 
                    uialert(app.UIFigure,'h debe ser mayor que cero','Error');
                    return;
                end

                if kPP_2<=0
                    uialert(app.UIFigure,'k deben ser mayor que cero','Error');
                    return;
                end

                if cPP_2<=0
                    uialert(app.UIFigure,'El coeficiente c debe ser mayor que cero','Error');
                    return;
                end


                % --- CASO: SOLO EXPLÍCITO y/o EXPLÍCITO E IMPLÍCITO ---

                % VALIDACIÓN DE ESTABILIDAD
                if strcmp(metodo,'Explícito') || strcmp(metodo,'Explícito e Implícito')

                    % Número de subintervalos espaciales sugerido
                    Nx = round(LPP_2/hPP_2);

                    % h sugerido
                    h_sugerido = LPP_2/Nx;

                    % VALIDACIÓN DE h
                    if abs(LPP_2/hPP_2 - Nx) > 1e-10

                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de h ingresado no divide exactamente\n' ...
                            'el intervalo espacial.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'h = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'n = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            h_sugerido, Nx), ...
                            'Advertencia espacial', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');

                    end

                    % VALIDACIÓN DE k
                    Nt = round(tmaxPP_2/kPP_2);

                    % k sugerido SOLO por divisibilidad temporal
                    k_division = tmaxPP_2/Nt;

                    if abs(tmaxPP_2/kPP_2 - Nt) > 1e-10

                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de k ingresado no divide exactamente\n' ...
                            'el intervalo temporal.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'k = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'm = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            k_division, Nt), ...
                            'Advertencia temporal', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');

                    end

                    % CONDICIÓN DE ESTABILIDAD
                    r = sqrt(cPP_2)*kPP_2/hPP_2;
                    if r > 1

                        % Paso máximo por estabilidad
                        k_estable = hPP_2/cPP_2;

                        % Buscar N entero tal que k = tmax/N <= k_estable
                        N = ceil(tmaxPP_2 / k_estable);
                        k_sugerido = tmaxPP_2 / N;

                        % Ajustar Nt entero
                        Nt_estable = ceil(tmaxPP_2/k_estable);

                        % Nuevo r con k sugerido
                        r_sugerido = sqrt(cPP_2)*k_sugerido/hPP_2;

                        % ADVERTENCIA DE ESTABILIDAD
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de k ingresado NO satisface\n' ...
                            'la condición de estabilidad del método explícito.\n\n' ...
                            'Condición requerida:\n' ...
                            'r = ck/h <= 1\n\n' ...
                            'Valor actual:\n' ...
                            'r = ck/h = %.6f\n\n' ...
                            'Esto puede producir:\n' ...
                            '- Oscilaciones numéricas\n' ...
                            '- Crecimiento no físico de la solución\n' ...
                            '- Divergencia del método\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'k = %.10f\n' ...
                            'm = %d\n' ...
                            'r = %.6f\n\n' ...
                            'El valor sugerido cumple:\n' ...
                            '- Estabilidad numérica\n' ...
                            '- tmax/k entero'], ...
                            r, k_sugerido, Nt_estable, r_sugerido), ...
                            'Advertencia de estabilidad', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');

                    end
                end

                % CASO: IMPLÍCITO
                if strcmp(metodo,'Implícito')

                    % VALIDACIÓN DE h
                    Nx = round(LPP_2/hPP_2);

                    if abs(LPP_2/hPP_2 - Nx) > 1e-10

                        % h sugerido
                        h_sugerido = LPP_2/Nx;

                        % Advertencia
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de h ingresado no divide exactamente\n' ...
                            'el intervalo espacial.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'h = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'n = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            h_sugerido, Nx), ...
                            'Advertencia espacial', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');

                    end

                    % VALIDACIÓN DE k
                    Nt = tmaxPP_2/kPP_2;

                    if abs(Nt - round(Nt)) > 1e-10

                        % Número entero sugerido
                        N = round(Nt);

                        % Evitar N = 0
                        if N <= 0
                            N = 1;
                        end

                        % k sugerido
                        k_sugerido = tmaxPP_2/N;

                        % Advertencia
                        resp = uiconfirm(app.UIFigure, ...
                            sprintf(['El valor de k ingresado no divide exactamente\n' ...
                            'el intervalo temporal.\n\n' ...
                            'Se recomienda usar:\n\n' ...
                            'k = %.10f\n\n' ...
                            'Número de subintervalos:\n' ...
                            'm = %d\n\n' ...
                            'En caso de continuar, el solver utilizará\n' ...
                            'internamente una discretización consistente.'], ...
                            k_sugerido, N), ...
                            'Advertencia temporal', ...
                            'Options', {'OK'}, ...
                            'Icon', 'warning');

                    end
                end

                % Validar condiciones iniciales
                % u(x,0) 
                % ut(x,0)
                syms x t
                val_ux0_2 = app.ux0_2.Value;
                val_utx0_2 = app.utx0_2.Value;

                % 1. Verificar si el campo está vacío
                if isempty(strtrim(val_ux0_2))
                    uialert(app.UIFigure, 'El campo u(x,0) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    ux0_sym = str2sym(val_ux0_2);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en u(x,0). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % 2. Validar variables permitidas
                vars = symvar(ux0_sym); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(vars)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(vars(i), x))
                        uialert(app.UIFigure, ...
                            ['En u(x,0) hay variables no permitidas: "' char(vars(i)) '". Solo se permite x.'], ...
                            'Error');
                        return;
                    end
                end

                % 3. Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    ux0PP_2 = matlabFunction(ux0_sym, 'Vars', x);
                catch
                    uialert(app.UIFigure, 'Error al procesar la función u(x,0).', 'Error');
                    return;
                end

                if isempty(strtrim(val_utx0_2))
                    uialert(app.UIFigure, 'El campo ut(x,0) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    utx0_sym = str2sym(val_utx0_2);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en ut(x,0). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % 2. Validar variables permitidas
                varst = symvar(utx0_sym); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(varst)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(varst(i), x))
                        uialert(app.UIFigure, ...
                            ['En ut(x,0) hay variables no permitidas: "' char(varst(i)) '". Solo se permite x.'], ...
                            'Error');
                        return;
                    end
                end

                % 3. Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    utx0PP_2 = matlabFunction(utx0_sym, 'Vars', x);
                catch
                    uialert(app.UIFigure, 'Error al procesar u(x,0).', 'Error');
                    return;
                end


                % Condiciones de frontera
                % u(0,t) y u(L,t) - Validación flexible condiciones de contorno
                val_u0t_2 = app.u0t_2.Value;
                
                % 1. Verificar si el campo está vacío
                if isempty(strtrim(val_u0t_2))
                    uialert(app.UIFigure, 'El campo u(0,t) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    u0t_sym_2 = str2sym(val_u0t_2);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en u(0,t). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % 2. Validar variables permitidas
                vars3 = symvar(u0t_sym_2); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(vars3)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(vars3(i), t))
                        uialert(app.UIFigure, ...
                            ['En u(0,t) hay variables no permitidas: "' char(vars2(i)) '". Solo se permite t.'], ...
                            'Error');
                        return;
                    end
                end

                % 3. Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    u0tPP_2 = matlabFunction(u0t_sym_2, 'Vars', t);
                catch
                    uialert(app.UIFigure, 'Error al procesar u(0,t).', 'Error');
                    return;
                end


                val_uLt_2 = app.uLt_2.Value;
                % 1. Verificar si el campo está vacío
                if isempty(strtrim(val_uLt_2))
                    uialert(app.UIFigure, 'El campo u(L,t) no puede estar vacío.', 'Error');
                    return;
                end

                try
                    % Intentar convertir el string a expresión simbólica
                    uLt_sym_2 = str2sym(val_uLt_2);
                catch
                    % Captura errores de sintaxis (ej: "x++y", "3x", "log(x")
                    uialert(app.UIFigure, 'Error de sintaxis en u(L,t). Verifique paréntesis y operadores.', 'Error');
                    return;
                end

                % 2. Validar variables permitidas
                vars4 = symvar(uLt_sym_2); % Obtiene las variables presentes (puede ser ninguna si es constante)
                for i = 1:length(vars4)
                    % Si la variable no es ni x lanzar error
                    if ~any(has(vars4(i), t))
                        uialert(app.UIFigure, ...
                            ['En u(L,t) hay variables no permitidas: "' char(vars3(i)) '". Solo se permite t.'], ...
                            'Error');
                        return;
                    end
                end

                % 3. Generar la función de MATLAB
                % Usamos el argumento 'Vars', [x y] para que, aunque f sea constante o de una variable,
                % la función resultante siempre acepte una entrada: x.
                try
                    uLtPP_2 = matlabFunction(uLt_sym_2, 'Vars', t);
                catch
                    uialert(app.UIFigure, 'Error al procesar la función u(L,t).', 'Error');
                    return;
                end

                % Validar solución exacta
                ok = app.validarSolExacta( ...
                    app.uxt_2.Value, ...
                    app.SiNoP_2.Value, ...
                    {'x','t'}, ...
                    'u(x,t)' );

                % Para Hiperbólicas
                if ~ok
                    return;
                end

                uialert(app.UIFigure, ...
                    'Datos verificados correctamente', ...
                    'Éxito', ...
                    'Icon','success');

                % GUARDAR VARIABLES PARA USARLAS EN CUALQUIER MOMENTO
                app.LPPP_2 = LPP_2;
                app.tmaxPPP_2 = tmaxPP_2;
                app.hPPP_2 = hPP_2;
                app.kPPP_2 = kPP_2;
                app.cPPP_2 = cPP_2;

                % Condiciones iniciales
                app.ux0PPP_2 = ux0PP_2;
                app.utx0PPP_2 = utx0PP_2;

                % Condiciones de frontera
                app.u0tPPP_2 = u0tPP_2;
                app.uLtPPP_2 = uLtPP_2;

                % ÉXITO
                app.RevisarDatosParabolico_2.Text = '✔ Datos revisados';
                app.RevisarDatosParabolico_2.BackgroundColor = [0.6 0.85 0.6]; % Verde suave

                % ACTIVAR EL BOTÓN RESOLVER EDP
                app.ResolverParabolicaExplicito_2.Enable = 'on';
        end

        % Value changed function: SiNoP_2
        function SiNoP_2ValueChanged(app, event)
            % Obtenemos el valor seleccionado (asegúrate que coincida con tus opciones)
            seleccion = app.SiNoP_2.Value; 
            
            % Comparamos la selección
            if strcmp(seleccion, 'Sí')
                app.uxt_2.Enable = 'on';   % Se activa para escribir la solución exacta
                app.uxt_2.BackgroundColor = [1 1 1]; % Opcional: fondo blanco (activo)
            else
                app.uxt_2.Enable = 'off';  % Se desactiva
                app.uxt_2.Value = '';      % Limpiamos el campo por si había algo escrito
                app.uxt_2.BackgroundColor = [0.94 0.94 0.94]; % Opcional: fondo gris (inactivo)
            end
            
        end

        % Button pushed function: ResolverParabolicaExplicito_2
        function ResolverParabolicaExplicito_2ButtonPushed(app, event)
            % Ventana de progreso
            procesandoE = uiprogressdlg(app.UIFigure, ...
            'Title','Procesando', ...
            'Message','Iniciando...', ...
            'Value',0);
            drawnow; % IMPORTANTE para que se vea
            
            % 1. RECUPERAR DATOS
            LPP1_2 = app.LPPP_2;
            tmaxPP1_2 = app.tmaxPPP_2;
            hPP1_2 = app.hPPP_2;
            kPP1_2 = app.kPPP_2;
            cPP1_2 = app.cPPP_2;
            
            % Condiciones iniciales
            ux0PP1_2 = app.ux0PPP_2;
            utx0PP1_2 = app.utx0PPP_2;

            % Condiciones de contorno:
            u0tPP1_2 = app.u0tPPP_2;
            uLtPP1_2 = app.uLtPPP_2;

            u_exactaP = app.u_exacta;
            % app.u_exacta = u_e; % Ya lo guarda automáticamente
            % la función app.validarSolExacta

            % Preparación de datos
            procesandoE.Message = 'Discretizando la malla...';
            procesandoE.Value = 0.2;
            drawnow;

            % Resolución de la EDP
            procesandoE.Message = 'Resolviendo la EDP...';
            procesandoE.Value = 0.5;
            drawnow;


            metodo = app.MetodosSolucionParabolicas_2.Value; % Para determinar 
            % qué caso hay que resolver.


             % CASO: EXPLÍCITO
            if strcmp(metodo,'Explícito')
                % Se llama a la función app.Hiperbolica_E para resolver la EDP
                [x1PE_2, t1PE_2, uPE_2, tiempoPE_2, rP_2,h_efectivoHE,k_efectivoHE] = app.Hiperbolica_E(hPP1_2, kPP1_2, cPP1_2, LPP1_2, tmaxPP1_2,...
                    ux0PP1_2,u0tPP1_2,uLtPP1_2, ux0PP1_2, utx0PP1_2);

                % Guardar las variables calculadas para usos futuros
                app.x1PPE_2 = x1PE_2;
                app.t1PPE_2 = t1PE_2;
                app.uPPE_2 = uPE_2;
                app.tiempoPPE_2 = tiempoPE_2;
                app.rPP_2 = rP_2;

                % FINALIZACIÓN
                procesandoE.Value = 1;
                procesandoE.Message = 'Resultados listos';
                drawnow;

                pause(0.5); % opcional (para que el usuario lo vea)

                close(procesandoE);

                % % Mensaje final
                if rP_2<=1
                    % Mensaje final
                    tiempofinal = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h =%.4f <= 1\n' ...
                        '⇒El método es ESTABLE.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE_2, rP_2,h_efectivoHE,k_efectivoHE);

                    uialert(app.UIFigure, ...
                        tiempofinal, ...
                        'Proceso completado','Icon', 'success');
                    
                else
                    % Mensaje final
                    tiempofinal = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h =%.4f > 1\n' ...
                        '⇒El método es INESTABLE.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE_2, rP_2,h_efectivoHE,k_efectivoHE);

                    uialert(app.UIFigure, ...
                        tiempofinal, ...
                        'Proceso completado','Icon', 'success');
                end

                % Solución Exacta
                if ~isempty(u_exactaP)
                    u_exacta_evaluadaP_2 = u_exactaP(x1PE_2,t1PE_2);

                else
                    u_exacta_evaluadaP_2 = [];
                end
            end

             % CASO: IMPLÍCITO
            if strcmp(metodo,'Implícito')
                % % Se llama a la función app.Hiperbolica_I para resolver la EDP
                [x1PCN_2, t1PCN_2, uPCN_2, tiempoPCN_2,h_efectivoHI,k_efectivoHI] = app.Hiperbolica_I(hPP1_2, kPP1_2, cPP1_2, LPP1_2, tmaxPP1_2,...
                    ux0PP1_2,u0tPP1_2,uLtPP1_2, ux0PP1_2, utx0PP1_2);
                % Guardar las variables calculadas para usos futuros
                app.x1PPCN_2 = x1PCN_2;
                app.t1PPCN_2 = t1PCN_2;
                app.uPPCN_2 = uPCN_2;
                app.tiempoPPCN_2 = tiempoPCN_2;

                % FINALIZACIÓN
                procesandoE.Value = 1;
                procesandoE.Message = 'Resultados listos';
                drawnow;

                pause(0.5); % opcional (para que el usuario lo vea)

                close(procesandoE);

                 % Mensaje final
                    tiempofinal = sprintf(['Tiempo de ejecución del Método Implícito: %0.4f\n' ...
                        'segundos.\n' ...
                        'El método es incondicionalmente ESTABLE\n\n'...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'],...
                        tiempoPCN_2,h_efectivoHI,k_efectivoHI);
                    uialert(app.UIFigure, ...
                        tiempofinal, ...
                        'Proceso completado','Icon', 'success');


                % Solución Exacta
                if ~isempty(u_exactaP)
                    u_exacta_evaluadaP_2 = u_exactaP(x1PCN_2,t1PCN_2);

                else
                    u_exacta_evaluadaP_2 = [];
                end

            end

            % CASO: EXPLÍCITO Y CRANK-NICHOLSON
            if strcmp(metodo,'Explícito e Implícito')
                % Se llama a la función app.Parabolica_E para resolver la EDP
                [x1PE_2, t1PE_2, uPE_2, tiempoPE_2, rP_2,h_efectivoHE,k_efectivoHE] = app.Hiperbolica_E(hPP1_2, kPP1_2, cPP1_2, LPP1_2, tmaxPP1_2,...
                    ux0PP1_2,u0tPP1_2,uLtPP1_2, ux0PP1_2, utx0PP1_2);

                % Guardar las variables calculadas para usos futuros
                app.x1PPE_2 = x1PE_2;
                app.t1PPE_2 = t1PE_2;
                app.uPPE_2 = uPE_2;
                app.tiempoPPE_2 = tiempoPE_2;
                app.rPP_2 = rP_2;

                % % Se llama a la función app.Parabolica_CN para resolver la EDP
                [x1PCN_2, t1PCN_2, uPCN_2, tiempoPCN_2,~,~] = app.Hiperbolica_I(hPP1_2, kPP1_2, cPP1_2, LPP1_2, tmaxPP1_2,...
                    ux0PP1_2,u0tPP1_2,uLtPP1_2, ux0PP1_2, utx0PP1_2);

                % Guardar las variables calculadas para usos futuros
                app.x1PPCN_2 = x1PCN_2;
                app.t1PPCN_2 = t1PCN_2;
                app.uPPCN_2 = uPCN_2;
                app.tiempoPPCN_2 = tiempoPCN_2;

                % FINALIZACIÓN
                procesandoE.Value = 1;
                procesandoE.Message = 'Resultados listos';
                drawnow;

                pause(0.5); % opcional (para que el usuario lo vea)
        
                close(procesandoE);

                % Mensaje final
                if rP_2<=1
                    mensaje = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h =%.4f <= 1\n' ...
                        'El método es ESTABLE.\n\n' ...
                        'Tiempo de ejecución del Método Implícito: %.4f segundos.\n' ...
                        'Es incondicionalmente estable.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE_2, rP_2, tiempoPCN_2,h_efectivoHE,k_efectivoHE);

                    uialert(app.UIFigure, ...
                        mensaje, ...
                        'Proceso completado', ...
                        'Icon','success');


                elseif rP_2>1
                    mensaje = sprintf(['Tiempo de ejecución del Método Explícito: %.4f segundos.\n' ...
                        'r = ck/h =%.4f > 1\n' ...
                        'El método es INESTABLE.\n\n' ...
                        'Tiempo de ejecución del Método Implícito: %.4f segundos.\n' ...
                        'Es incondicionalmente estable.\n\n' ...
                        'h_{efectivo} = %0.10f\n' ...
                        'k_{efectivo} = %0.10f'], ...
                        tiempoPE_2, rP_2, tiempoPCN_2,h_efectivoHE,k_efectivoHE);

                    uialert(app.UIFigure, ...
                        mensaje, ...
                        'Proceso completado', ...
                        'Icon','success');
                end

                % Solución Exacta
                if ~isempty(u_exactaP)
                    u_exacta_evaluadaP_2 = u_exactaP(x1PCN_2,t1PCN_2);

                else
                    u_exacta_evaluadaP_2 = [];
                end

            end


            % Guardar la solución exacta evaluada para usarla en otros espacios:
            app.u_exacta_evaluadaPP_2 = u_exacta_evaluadaP_2;

            % ÉXITO
            app.ResolverParabolicaExplicito_2.Text = '✔ EDP Resuelta';
            app.ResolverParabolicaExplicito_2.BackgroundColor = [0.6 0.85 0.6]; % verde suave

            % ACTIVAR EL DROP DOWN RESULTADOS
            app.ResultadosParabolico_2.Enable = 'on';
            app.PanelResultadosParabolico_2.BackgroundColor = [0.83,0.86,0.91];
            
            app.ResultadosParabolico_2.Items = {'Seleccione una opción', 'Gráficas', 'Tablas', 'Errores', 'Convergencia del método'};
            app.ResultadosParabolico_2.Value = 'Seleccione una opción';
        end

        % Value changed function: ResultadosParabolico_2
        function ResultadosParabolico_2ValueChanged(app, event)
            resultado = app.ResultadosParabolico_2.Value;
            metodo    = app.MetodosSolucionParabolicas_2.Value;

            % Recuperar datos ingresados:
            uExacta_2 = app.u_exacta;
            L1_2 = app.LPPP_2;
            tmax1_2 = app.tmaxPPP_2;
            c1_2 = app.cPPP_2;
            % Condiciones iniciales
            inferior_2 = app.ux0PPP_2; % es f
            g_2 = app.utx0PPP_2;

            % Condiciones de frontera
            izquierda_2 = app.u0tPPP_2;
            derecha_2 = app.uLtPPP_2;

            % LIMPIEZA TOTAL (ÚNICA)
            axesList = [app.UIAxes6_2, app.UIAxes7_2, app.UIAxes8_2, app.UIAxes9_2, ...
                app.UIAxes10_2, app.UIAxes11_2, app.UIAxes12_2, app.UIAxes13_2];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP_2.Visible = 'off';

            tables = [app.UITable5_2, app.UITable6_2, app.UITable7_2, app.UITable8_2, ...
                app.UITable9_2, app.UITable10_2, app.UITable11_2, app.UITable12_2];

            labels = [app.Texto5_2, app.Texto6_2, app.Texto7_2, app.Texto8_2, ...
                app.Texto9_2, app.Texto10_2, app.Texto11_2, app.Texto12_2];

            buttons = [app.Exportar5_2, app.Exportar6_2, app.Exportar7_2, app.Exportar8_2, ...
                app.Exportar9_2, app.Exportar10_2, app.Exportar11_2, app.Exportar12_2];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            for i = 5:12
                nombre = sprintf('Exportar%d_2', i);
                boton = app.(nombre);

                boton.Text = 'Exportar';
                boton.BackgroundColor = [0.83,0.86,0.91];
            end

            for i = 5:12
                nombre = sprintf('Texto%d_2', i);
                lbl = app.(nombre);

                lbl.BackgroundColor = [1 1 1]; % blanco
                lbl.FontWeight = 'bold';
            end

            app.PanelErrorConvergencia_2.Visible = 'off';


            % Solución exacta evaluada
            u_exacta_evaluadaP_2 = app.u_exacta_evaluadaPP_2;

            % MÉTODO
            switch metodo

                case 'Explícito'
                    % Recuperar datos de la resolución
                    x1P1_2 = app.x1PPE_2;
                    t1P1_2 = app.t1PPE_2;
                    uP1_2 = app.uPPE_2;
                    tiempoP1_2 = app.tiempoPPE_2;
                    rP1_2 = app.rPP_2;
                    METODO_2 = 'Expl\''icito';

                    % Cálculo de error numérico
                    if ~isempty(u_exacta_evaluadaP_2)
                        e_P_2 = abs(u_exacta_evaluadaP_2 - uP1_2);
                    else
                        e_P_2 = [];
                    end

                case 'Implícito'
                    % Recuperar datos de la resolución
                    x1P1_2 = app.x1PPCN_2;
                    t1P1_2 = app.t1PPCN_2;
                    uP1_2 = app.uPPCN_2;
                    tiempoP1_2 = app.tiempoPPCN_2;
                    METODO_2 = 'Impl\''icito';

                    % Cálculo de error numérico
                    if ~isempty(u_exacta_evaluadaP_2)
                        e_P_2 = abs(u_exacta_evaluadaP_2 - uP1_2);
                    else
                        e_P_2 = [];
                    end

                case 'Explícito e Implícito'
                    % Recuperar datos de la resolución
                    x1PE_2 = app.x1PPE_2;
                    t1PE_2 = app.t1PPE_2;
                    uPE_2 = app.uPPE_2;
                    tiempoPE_2 = app.tiempoPPE_2;
                    rP1_2 = app.rPP_2;

                    x1PCN_2 = app.x1PPCN_2;
                    t1PCN_2 = app.t1PPCN_2;
                    uPCN_2 = app.uPPCN_2;
                    tiempoPCN_2 = app.tiempoPPCN_2;

                    % Cálculo de error numérico
                    if ~isempty(u_exacta_evaluadaP_2)
                        e_PE_2 = abs(u_exacta_evaluadaP_2 - uPE_2);
                        e_PCN_2 = abs(u_exacta_evaluadaP_2 - uPCN_2);
                    else
                        e_PE_2 = [];
                        e_PCN_2 = [];
                    end

            end
            
            
            fuente_para_todas = 18;

            switch resultado

                % GRÁFICAS
                case 'Gráficas'

                    switch metodo

                        case {'Explícito','Implícito'}
                            cla(app.UIAxes6_2);
                            cla(app.UIAxes7_2);
                                                        
                            % app.TextoDeslizadorP.Visible = 'on';

                            if strcmp(metodo, 'Explícito')
                               metodo1 = 'Expl\''icito';
                               AlertaEstabilidad(app, 'Hiperbólica', rP1_2, 1)
                            end

                            if strcmp(metodo, 'Implícito')
                               metodo1 = 'Impl\''icito';
                            end
                           
                            app.DeslizadorP_2.Visible = 'on';
                            app.UIAxes6_2.Visible = 'on';
                            app.UIAxes7_2.Visible = 'on';
                            app.inicializarGraficasEDP( ...
                                x1P1_2, t1P1_2, uP1_2, ...
                                app.UIAxes6_2, app.UIAxes7_2, ...
                                'Hiperb\''olica', metodo1,...
                                app.DeslizadorP_2);

                            if ~isempty(u_exacta_evaluadaP_2)
                                app.UIAxes8_2.Visible = 'on';
                                cla(app.UIAxes8_2);
                                surf(app.UIAxes8_2, x1P1_2, t1P1_2, u_exacta_evaluadaP_2);

                                % 1. COLOR DE FONDO (0.83, 0.86, 0.91)
                                app.UIAxes8_2.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                                app.UIAxes8_2.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE
                                
                                % 3. CONFIGURACIÓN VISUAL
                                shading(app.UIAxes8_2, 'interp');
                                cMap3 = jet(256);
                                colormap(app.UIAxes8_2, cMap3);

                                cb3 = colorbar(app.UIAxes8_2);                       %  capturar el colorbar
                                cb3.Color = 'k';                                   % opcional (texto visible)
                                cb3.Box = 'on';                                    % opcional

                                % 4. FORMATO Y ETIQUETAS
                                app.UIAxes8_2.FontSize = fuente_para_todas;
                                xlabel(app.UIAxes8_2, '$$ x $$', 'Interpreter', 'latex');
                                ylabel(app.UIAxes8_2, '$$ y $$', 'Interpreter', 'latex');
                                zlabel(app.UIAxes8_2, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                                title(app.UIAxes8_2, '$$\mbox{Soluci\''on Exacta}$$', 'Interpreter', 'latex');
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la Solución Exacta.', ...
                                    'Aviso');
                            end

                        case 'Explícito e Implícito'
                            % Gráfica Explícito
                            app.UIAxes9_2.Visible = 'on';
                            cla(app.UIAxes9_2);
                            surf(app.UIAxes9_2, x1PE_2, t1PE_2, uPE_2);

                            % 1. COLOR DE FONDO (0.83, 0.86, 0.91)
                            app.UIAxes9_2.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                            app.UIAxes9_2.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE

                            % 3. CONFIGURACIÓN VISUAL
                            shading(app.UIAxes9_2, 'interp');
                            cMap3 = jet(256);
                            colormap(app.UIAxes9_2, cMap3);

                            colorbar(app.UIAxes9_2);    
                            
                            % 4. FORMATO Y ETIQUETAS
                            app.UIAxes9_2.FontSize = fuente_para_todas;
                            xlabel(app.UIAxes9_2, '$$ x $$', 'Interpreter', 'latex');
                            ylabel(app.UIAxes9_2, '$$ y $$', 'Interpreter', 'latex');
                            zlabel(app.UIAxes9_2, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                            tituloE = '$$\mbox{Soluci\''on M\''etodo Expl\''icito}$$';
                            title(app.UIAxes9_2, tituloE, 'Interpreter', 'latex')


                            % Gráfica Implícito
                            app.UIAxes6_2.Visible = 'on';
                            cla(app.UIAxes6_2);
                            surf(app.UIAxes6_2, x1PCN_2, t1PCN_2, uPCN_2);

                            % 1. COLOR DE FONDO (0.83, 0.86, 0.91)
                            app.UIAxes6_2.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                            app.UIAxes6_2.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE

                            % 3. CONFIGURACIÓN VISUAL
                            shading(app.UIAxes6_2, 'interp');
                            cMap3 = jet(256);
                            colormap(app.UIAxes6_2, cMap3);

                            colorbar(app.UIAxes6_2);    
                            
                            % 4. FORMATO Y ETIQUETAS
                            app.UIAxes6_2.FontSize = fuente_para_todas;
                            xlabel(app.UIAxes6_2, '$$ x $$', 'Interpreter', 'latex');
                            ylabel(app.UIAxes6_2, '$$ y $$', 'Interpreter', 'latex');
                            zlabel(app.UIAxes6_2, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                            tituloE = '$$\mbox{Soluci\''on M\''etodo Impl\''icito}$$';
                            title(app.UIAxes6_2, tituloE, 'Interpreter', 'latex')

                            AlertaEstabilidad(app, 'Hiperbólica', rP1_2, 1)

                            if ~isempty(u_exacta_evaluadaP_2)
                                app.UIAxes8_2.Visible = 'on';
                                cla(app.UIAxes8_2);
                                surf(app.UIAxes8_2, x1PE_2, t1PE_2, u_exacta_evaluadaP_2);

                                % 1. COLOR DE FONDO (0.83, 0.86, 0.91)
                                app.UIAxes8_2.BackgroundColor = [0.83, 0.86, 0.91]; % (puedes dejarla)
                                app.UIAxes8_2.Color = [0.83, 0.86, 0.91];           %  ESTA ES LA CLAVE

                                % 3. CONFIGURACIÓN VISUAL
                                shading(app.UIAxes8_2, 'interp');
                                cMap3 = jet(256);
                                colormap(app.UIAxes8_2, cMap3);

                                cb3 = colorbar(app.UIAxes8_2);                       %  capturar el colorbar
                                cb3.Color = 'k';                                   % opcional (texto visible)
                                cb3.Box = 'on';                                    % opcional

                                % 4. FORMATO Y ETIQUETAS
                                app.UIAxes8_2.FontSize = fuente_para_todas;
                                xlabel(app.UIAxes8_2, '$$ x $$', 'Interpreter', 'latex');
                                ylabel(app.UIAxes8_2, '$$ y $$', 'Interpreter', 'latex');
                                zlabel(app.UIAxes8_2, '$$ u $$', 'Interpreter', 'latex', 'Rotation', 0);
                                title(app.UIAxes8_2, '$$\mbox{Soluci\''on Exacta}$$', 'Interpreter', 'latex');
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la Solución Exacta.', ...
                                    'Aviso');
                            end
                    end

                  % TABLAS
                case 'Tablas'

                    switch metodo

                        case {'Explícito','Implícito'}
                            % Ventana de progreso
                            procesandoTablas = uiprogressdlg(app.UIFigure, ...
                                'Title','Procesando', ...
                                'Message','Ensamblando tablas...', ...
                                'Value',0);
                            drawnow; % IMPORTANTE para que se vea

                            app.UITable5_2.Visible = 'on';
                            app.Texto5_2.Visible = 'on';
                            app.Exportar5_2.Visible = 'on';
                            app.Tablas33(app.UITable5_2,uP1_2,'t_')

                            if strcmp(metodo, 'Explícito')
                                TEXTO5 = 'Solución Numérica Explícita';
                            end

                            if strcmp(metodo, 'Implícito')
                                TEXTO5 = 'Solución Numérica Implícita';
                            end
                            app.Texto5_2.Text = TEXTO5;

                            if ~isempty(u_exacta_evaluadaP_2)
                                app.UITable6_2.Visible = 'on';
                                app.Texto6_2.Visible = 'on';
                                app.Exportar6_2.Visible = 'on';
                                app.Texto6_2.Text = 'Solución Exacta';
                                app.Tablas33(app.UITable6_2,u_exacta_evaluadaP_2,'t_')
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la solución exacta.', ...
                                    'Aviso');
                            end
                            % FINALIZACIÓN
                            procesandoTablas.Value = 1;
                            procesandoTablas.Message = 'Tablas listas';
                            drawnow;
                            pause(0.5); % opcional (para que el usuario lo vea)
                            close(procesandoTablas);

                            if strcmp(metodo, 'Explícito')
                                AlertaEstabilidad(app, 'Hiperbólica', rP1_2, 1)
                            end

                        
                        case 'Explícito e Implícito'
                            % Ventana de progreso
                            procesandoTablas = uiprogressdlg(app.UIFigure, ...
                                'Title','Procesando', ...
                                'Message','Ensamblando tablas...', ...
                                'Value',0);
                            drawnow; % IMPORTANTE para que se vea

                            app.UITable7_2.Visible = 'on';
                            app.Texto7_2.Visible = 'on';
                            app.Exportar7_2.Visible = 'on';
                            app.Tablas33(app.UITable7_2,uPE_2  ,'t_')
                            app.Texto7_2.Text = 'Explícita';

                            app.UITable8_2.Visible = 'on';
                            app.Texto8_2.Visible = 'on';
                            app.Exportar8_2.Visible = 'on';
                            app.Tablas33(app.UITable8_2,uPCN_2 ,'t_')
                            app.Texto8_2.Text = 'Implícito';

                            if ~isempty(u_exacta_evaluadaP_2)
                                app.UITable9_2.Visible = 'on';
                                app.Texto9_2.Visible = 'on';
                                app.Exportar9_2.Visible = 'on';
                                app.Texto9_2.Text = 'Solución Exacta';
                                app.Tablas33(app.UITable9_2,u_exacta_evaluadaP_2,'t_')
                            else
                                uialert(app.UIFigure, ...
                                    'No se dispone de la solución exacta.', ...
                                    'Aviso');
                            end
                            % FINALIZACIÓN
                            procesandoTablas.Value = 1;
                            procesandoTablas.Message = 'Tablas listas';
                            drawnow;
                            pause(0.5); % opcional (para que el usuario lo vea)
                            close(procesandoTablas);

                            AlertaEstabilidad(app, 'Hiperbólica', rP1_2, 1)
                    end

                    % ERRORES
                case 'Errores'

                    switch metodo

                        case {'Explícito','Implícito'}
                            if ~isempty(u_exacta_evaluadaP_2)
                                % Ventana de progreso
                                procesandoErrores = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Presentando errores...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea

                                app.UITable5_2.Visible = 'on';
                                app.Texto5_2.Visible = 'on';
                                app.Exportar5_2.Visible = 'on';
                                if strcmp(metodo, 'Explícito')
                                    TEXTO5 = 'Error Numérico Explícito';
                                end

                                if strcmp(metodo, 'Implícito')
                                    TEXTO5 = 'Error Numérico Implícito';
                                end
                                app.Texto5_2.Text = TEXTO5;
                                app.Tablas33(app.UITable5_2,e_P_2,'t_')

                                % Limpiar eje
                                cla(app.UIAxes10_2);
                                app.UIAxes10_2.Visible = 'on';

                                % Gráfica de superficie del error
                                surf(app.UIAxes10_2, x1P1_2 , t1P1_2 , e_P_2);

                                % Vista desde arriba (mapa de calor)
                                view(app.UIAxes10_2, 2);

                                % Quitar malla de la superficie
                                shading(app.UIAxes10_2, 'interp');

                                % Barra de color
                                colorbar(app.UIAxes10_2);

                                % Formato
                                app.UIAxes10_2.FontSize = fuente_para_todas;

                                % % Etiquetas
                                xlabel(app.UIAxes10_2, '$$ x $$', 'Interpreter', 'latex');
                                ylabel(app.UIAxes10_2, '$$ t $$', 'Interpreter', 'latex', 'Rotation', 0);
                                title(app.UIAxes10_2, '$$\mbox{Mapa de Calor del Error Num\''erico}$$', 'Interpreter', 'latex');

                                % Opcional: colormap
                                colormap(app.UIAxes10_2, 'jet');

                                % FINALIZACIÓN
                                procesandoErrores.Value = 1;
                                procesandoErrores.Message = 'Errores listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoErrores);

                                if strcmp(metodo, 'Explícito')
                                    AlertaEstabilidad(app, 'Hiperbólica', rP1_2, 1)
                                end

                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular el error numérico.', ...
                                    'Aviso');
                            end


                        case 'Explícito e Implícito'
                            if ~isempty(u_exacta_evaluadaP_2)
                                
                                e_E_2 = abs(u_exacta_evaluadaP_2 - uPE_2);
                                e_CN_2 = abs(u_exacta_evaluadaP_2 - uPCN_2);

                                % Norma infinito
                                Einf_E_2  = max(abs(e_E_2),[],2);
                                Einf_CN_2 = max(abs(e_CN_2),[],2);

                                % Norma L2
                                hx2_2 = x1PCN_2(1,2) - x1PCN_2(1,1); 
                                tiempo_2 = t1PCN_2(end:-1:1,1);
                                EL2_E_2  = sqrt(sum(e_E_2.^2,2) * hx2_2);
                                EL2_CN_2 = sqrt(sum(e_CN_2.^2,2) * hx2_2);


                                % Tablas:
                                % Ventana de progreso
                                procesandoErrores = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Presentando errores...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea

                                app.PanelErrorConvergencia_2.Visible = 'on';

                                app.UITable10_2.Visible = 'on';
                                app.Texto10_2.Visible = 'on';
                                app.Exportar10_2.Visible = 'on';
                                app.Texto10_2.Text = 'Error Explícito';
                                app.Tablas33(app.UITable10_2,e_E_2,'t_')

                                app.UITable11_2.Visible = 'on';
                                app.Texto11_2.Visible = 'on';
                                app.Exportar11_2.Visible = 'on';
                                app.Texto11_2.Text = 'Error Implícito';
                                app.Tablas33(app.UITable11_2,e_CN_2,'t_')

                                % Gráficas
                                cla(app.UIAxes11_2);
                                app.UIAxes11_2.Visible = 'on';

                                % Gráfica de superficie del error
                                hold(app.UIAxes11_2,"on")
                                plot(app.UIAxes11_2,tiempo_2, Einf_E_2, 'b','LineWidth',2)
                                plot(app.UIAxes11_2,tiempo_2, Einf_CN_2, 'r','LineWidth',2)
                                hold(app.UIAxes11_2,"off")
                                grid(app.UIAxes11_2,'on')
                                app.UIAxes11_2.Color = [0.83, 0.86, 0.91];
                                
                                % Formato
                                app.UIAxes11_2.FontSize = fuente_para_todas-2;

                                xlabel(app.UIAxes11_2,'$$t$$','Interpreter','latex')
                                ylabel(app.UIAxes11_2,'$$\| e \|_{\infty}$$','Interpreter','latex')
                                title(app.UIAxes11_2,'$$\mbox{Error en norma infinito vs tiempo}$$','Interpreter','latex')
                                leyenda{1} = '$$\mbox{Expl\''icito}$$';
                                leyenda{2} = '$$\mbox{Impl\''icito}$$';
                                legend(app.UIAxes11_2,leyenda,'Interpreter','latex','Location','best')

                                
                                cla(app.UIAxes12_2);
                                app.UIAxes12_2.Visible = 'on';

                                % Gráfica de superficie del error
                                hold(app.UIAxes12_2,"on")
                                plot(app.UIAxes12_2,tiempo_2, EL2_E_2, 'b','LineWidth',2)
                                plot(app.UIAxes12_2,tiempo_2, EL2_CN_2, 'r','LineWidth',2)
                                hold(app.UIAxes12_2,"off")
                                grid(app.UIAxes12_2,'on')
                                app.UIAxes12_2.Color = [0.83, 0.86, 0.91];

                                % Formato
                                app.UIAxes12_2.FontSize = fuente_para_todas-2;

                                xlabel(app.UIAxes12_2,'$$t$$','Interpreter','latex')
                                ylabel(app.UIAxes12_2,'$$\left\| u^{\mbox{num}} - u^{\mbox{exact}} \right\|_{L^2}$$','Interpreter','latex')
                                title(app.UIAxes12_2,'$$\mbox{Error en norma }L^2 \mbox{ vs tiempo}$$','Interpreter','latex')
                                legend(app.UIAxes12_2,leyenda,'Interpreter','latex','Location','best')

                                % FINALIZACIÓN
                                procesandoErrores.Value = 1;
                                procesandoErrores.Message = 'Errores listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoErrores);

                                AlertaEstabilidad(app, 'Hiperbólica', rP1_2, 1)
                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular el error numérico.', ...
                                    'Aviso');
                            end
                    end

                    % CONVERGENCIA
                case 'Convergencia del método'
                    switch metodo

                        case {'Explícito','Implícito'}
                            if ~isempty(u_exacta_evaluadaP_2)

                                % Ventana de progreso
                                procesandoE = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Iniciando...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea
                                
                                % Valores de h y k para el análisis de
                                % convergencia Explícito
                                if strcmp(metodo, 'Explícito')
                                    h_efectivoHE = abs(x1P1_2(1,2)-x1P1_2(1,1));
                                    % k_efectivoHE = abs(t1P1_2(1,1)-t1P1_2(2,1));
                                    h_in_2 = h_efectivoHE; % h inicial para la convergencia
                                    h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                    % k_in_2 = k_efectivoHE;
                                    k_vals_2 = 0.5*h_vals_2;  % k para convergencia
                                    % k_vals_2 = [k_in_2, k_in_2/2, k_in_2/4, k_in_2/8];

                                    % h_in_2 = 0.25; % h inicial para la convergencia
                                    % h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                    % % k_vals_2 = 0.8*h_vals_2/sqrt(c1_2);
                                    % k_vals_2 = 0.9*h_vals_2/sqrt(c1_2);
                                    % 
                                    metodo1 = 'Explícito';
                                    titulo1 = 'EDP Parab\''olica (Expl\''icita)';
                                    TEXTO1 = 'Métricas Método Explícito';
                                end

                                % Valores de h y k para el análisis de
                                % convergencia Crank-Nicholson
                                if strcmp(metodo, 'Implícito')

                                    h_efectivoHI = abs(x1P1_2(1,2)-x1P1_2(1,1));
                                    % k_efectivoHI = abs(t1P1_2(1,1)-t1P1_2(2,1));
                                    h_in_2 = h_efectivoHI; % h inicial para la convergencia
                                    h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                    % k_in_2 = k_efectivoHI;
                                    k_vals_2 = 0.5*h_vals_2;  % k para convergencia
                                    % k_vals_2 = [k_in_2, k_in_2/2, k_in_2/4, k_in_2/8];

                                    % h_in_2 = 0.25; % h inicial para la convergencia
                                    % h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                    % % k_vals_2 = 0.8*h_vals_2/sqrt(c1_2);
                                    % k_vals_2 = 0.9*h_vals_2/sqrt(c1_2);
                                    metodo1 = 'Implícito';
                                    titulo1 = 'EDP Parab\''olica (Impl\''icito)';
                                    TEXTO1 = 'Métricas Método Implícito';
                                end


                                % ETAPA 1: Preparación de datos
                                procesandoE.Message = 'Discretizando la malla...';
                                procesandoE.Value = 0.2;
                                drawnow;

                                % ETAPA 2: Resolución de la EDP
                                procesandoE.Message = 'Resolviendo la EDP...';
                                procesandoE.Value = 0.5;
                                drawnow;
                                
                                [err_max, err_L2, eoc_L2, tiempos] = ...
                                    app.CalculoConvergenciaHiperbolica(h_vals_2,...
                                    k_vals_2,c1_2,L1_2,tmax1_2,...
                                    inferior_2, izquierda_2, derecha_2,g_2,uExacta_2, metodo1);

                                % FINALIZACIÓN
                                procesandoE.Value = 1;
                                procesandoE.Message = 'Resultados listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoE);

                                % Se llama a la función TablaMetricas para generar
                                % la tabla de las métricas
                                app.TablaMetricas(app.UITable12_2, h_vals_2, k_vals_2, err_max, err_L2, eoc_L2, tiempos)

                                % Gráfica de convergencia
                                cla(app.UIAxes13_2)
                                app.UIAxes13_2.Visible = 'on';
                                app.GraficaConvergencia(app.UIAxes13_2, h_vals_2, err_L2, titulo1);
                                app.PanelErrorConvergencia_2.Visible = 'on';
                                app.UITable12_2.Visible = 'on';
                                app.Texto12_2.Visible = 'on';
                                app.Texto12_2.Text = TEXTO1;
                                app.Exportar12_2.Visible = 'on';

                                if strcmp(metodo, 'Explícito')
                                    if rP1_2 > 1
                                        mensaje = sprintf(['Aunque el método explícito para la EDP Hiperbólica\n' ...
                                            'es INESTABLE, se han considerado valores de h y k que generan valores de\n' ...
                                            'r <= 1 para analizar la convergencia.']);

                                        aviso = uiconfirm(app.UIFigure, ...
                                            mensaje, ...
                                            'Análisis de convergencia listo', ...
                                            'Icon','info');
                                    end
                                end

                                
                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular la convergencia.', ...
                                    'Aviso');
                            end


                        case 'Explícito e Implícito'

                            if ~isempty(u_exacta_evaluadaP_2)

                                % Ventana de progreso
                                procesandoE = uiprogressdlg(app.UIFigure, ...
                                    'Title','Procesando', ...
                                    'Message','Iniciando...', ...
                                    'Value',0);
                                drawnow; % IMPORTANTE para que se vea

                                % Valores de h y k para el análisis Explícito
                                h_efectivoHE = abs(x1PE_2(1,2)-x1PE_2(1,1));
                                % k_efectivoHE = abs(t1PE_2(1,1)-t1PE_2(2,1));
                                h_in_2 = h_efectivoHE; % h inicial para la convergencia
                                h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                % k_in_2 = k_efectivoHE;
                                k_vals_2 = 0.5*h_vals_2;  % k para convergencia
                                % k_vals_2 = [k_in_2, k_in_2/2, k_in_2/4, k_in_2/8];
                                % 
                                % h_in_2 = 0.25; % h inicial para la convergencia
                                % h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                % % k_vals_2 = 0.8*h_vals_2/sqrt(c1_2);
                                % k_vals_2 = 0.9*h_vals_2/sqrt(c1_2);

                                metodo1 = 'Explícito';
                                titulo1 = 'Hiperb\''olica (Expl\''icito)';
                                TEXTO1 = 'Métricas Explícito';

                                % =========================================
                                % ETAPA 1: Preparación de datos
                                % =========================================
                                procesandoE.Message = 'Discretizando la malla...';
                                procesandoE.Value = 0.2;
                                drawnow;

                                % =========================================
                                % ETAPA 2: Resolución de la EDP
                                % =========================================
                                procesandoE.Message = 'Resolviendo la EDP...';
                                procesandoE.Value = 0.5;
                                drawnow;


                                % Se llama a la función CalculoConvergencia para
                                % calcular las métricas
                                [err_max, err_L2, eoc_L2, tiempos] = ...
                                    app.CalculoConvergenciaHiperbolica(h_vals_2,...
                                    k_vals_2,c1_2,L1_2,tmax1_2,...
                                    inferior_2, izquierda_2, derecha_2,g_2,uExacta_2, metodo1);

                                % Se llama a la función TablaMetricas para generar
                                % la tabla de las métricas
                                app.TablaMetricas(app.UITable10_2, h_vals_2, k_vals_2, err_max, err_L2, eoc_L2, tiempos)


                                % Gráfica de convergencia
                                cla(app.UIAxes11_2)
                                app.GraficaConvergencia(app.UIAxes11_2, h_vals_2, err_L2, titulo1);
                                app.UIAxes11_2.Visible = 'on';
                                app.PanelErrorConvergencia_2.Visible = 'on';
                                app.UITable10_2.Visible = 'on';
                                app.Texto10_2.Visible = 'on';
                                app.Texto10_2.Text = TEXTO1;
                                app.Exportar10_2.Visible = 'on';
                           
                                % Valores de h y k para el análisis Implícito
                                h_efectivoHCN = abs(x1PCN_2(1,2)-x1PCN_2(1,1));
                                % k_efectivoHCN = abs(t1PCN_2(1,1)-t1PCN_2(2,1));
                                h_in_2 = h_efectivoHCN; % h inicial para la convergencia
                                h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                % k_in_2 = k_efectivoHCN;
                                k_vals_2 = 0.5*h_vals_2;  % k para convergencia
                                % k_vals_2 = [k_in_2, k_in_2/2, k_in_2/4, k_in_2/8];
                                
                                % h_in_2 = 0.25; % h inicial para la convergencia
                                % h_vals_2 = [h_in_2, h_in_2/2, h_in_2/4, h_in_2/8]; % h para convergencia
                                % % k_vals_2 = 0.8*h_vals_2/sqrt(c1_2);
                                % k_vals_2 = 0.9*h_vals_2/sqrt(c1_2);
                                metodo1 = 'Implícito';
                                titulo1 = 'Hiperb\''olica (Impl\''icito)';
                                TEXTO1 = 'Métricas Implícito';
                                
                                % Se llama a la función CalculoConvergencia para
                                % calcular las métricas

                                [err_max, err_L2, eoc_L2, tiempos] = ...
                                    app.CalculoConvergenciaHiperbolica(h_vals_2,...
                                    k_vals_2,c1_2,L1_2,tmax1_2,...
                                    inferior_2, izquierda_2, derecha_2,g_2,uExacta_2, metodo1);

                                % Se llama a la función TablaMetricas para generar
                                % la tabla de las métricas
                                app.TablaMetricas(app.UITable11_2, h_vals_2, k_vals_2, err_max, err_L2, eoc_L2, tiempos)

                                % Gráfica de convergencia
                                cla(app.UIAxes12_2)
                                app.UIAxes12_2.Visible = 'on';
                                app.GraficaConvergencia(app.UIAxes12_2, h_vals_2, err_L2, titulo1);
                                app.PanelErrorConvergencia_2.Visible = 'on';
                                app.UITable11_2.Visible = 'on';
                                app.Texto11_2.Visible = 'on';
                                app.Texto11_2.Text = TEXTO1;
                                app.Exportar11_2.Visible = 'on';
                                
                                % FINALIZACIÓN
                                procesandoE.Value = 1;
                                procesandoE.Message = 'Resultados listos';
                                drawnow;
                                pause(0.5); % opcional (para que el usuario lo vea)
                                close(procesandoE);

                                % Forzar actualización de la interfaz
                                drawnow;

                                % Esperar un instante pequeño
                                pause(0.1);

                                if rP1_2 > 1
                                    mensaje = sprintf(['Aunque el método explícito para la EDP Hiperbólica ' ...
                                        'es INESTABLE, se han considerado valores de h y k que generan valores de' ...
                                        'r <= 1 para analizar la convergencia.']);

                                    aviso = uiconfirm(app.UIFigure, ...
                                        mensaje, ...
                                        'Análisis de convergencia listo', ...
                                        'Icon','info');
                                end
                          
                            else
                                uialert(app.UIFigure, ...
                                    'Necesita la solución exacta para calcular la convergencia.', ...
                                    'Aviso');
                            end
                    end
            end            
        end

        % Value changed function: DeslizadorP_2
        function DeslizadorP_2ValueChanged(app, event)
            t_val = event.Value;
            app.actualizarGraficasEDP( ...
                t_val, ...
                app.UIAxes6_2, app.UIAxes7_2);
        end

        % Button pushed function: Exportar6_2
        function Exportar6_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable6_2, app.Exportar6_2);
        end

        % Button pushed function: Exportar7_2
        function Exportar7_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable7_2, app.Exportar7_2);
        end

        % Button pushed function: Exportar8_2
        function Exportar8_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable8_2, app.Exportar8_2);
        end

        % Button pushed function: Exportar9_2
        function Exportar9_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable9_2, app.Exportar9_2);
        end

        % Button pushed function: Exportar10_2
        function Exportar10_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable10_2, app.Exportar10_2);
        end

        % Button pushed function: Exportar11_2
        function Exportar11_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable11_2, app.Exportar11_2);
        end

        % Button pushed function: Exportar12_2
        function Exportar12_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable12_2, app.Exportar12_2);
        end

        % Button pushed function: Exportar5_2
        function Exportar5_2ButtonPushed(app, event)
            app.exportarTablaExcel(app.UITable5_2, app.Exportar5_2);
        end

        % Button pushed function: ReseteoParabolica_2
        function ReseteoParabolica_2ButtonPushed(app, event)
            % RESET TOTAL APP HIPERBÓLICA

            % 1. CONFIRMACIÓN SOLO SI APLICA
            if ~app.resetGeneral
                seleccion = uiconfirm(app.UIFigure, ...
                    '¿Está seguro de que desea borrar todos los datos y gráficos?', ...
                    'Confirmar Reseteo', ...
                    'Icon', 'warning', ...
                    'Options', {'Continuar', 'Cancelar'}, ...
                    'DefaultOption', 2);

                if ~strcmp(seleccion, 'Continuar')
                    return;
                end
            end

            % 2. LIMPIEZA TOTAL
            axesList = [app.UIAxes6_2, app.UIAxes7_2, app.UIAxes8_2, app.UIAxes9_2, ...
                app.UIAxes10_2, app.UIAxes11_2, app.UIAxes12_2, app.UIAxes13_2];

            for ax = axesList
                cla(ax,'reset');
                ax.Visible = 'off';
            end

            delete(findall(app.UIFigure,'Type','ColorBar'))
            delete(findall(app.UIFigure,'Type','text'))

            app.DeslizadorP_2.Visible = 'off';

            tables = [app.UITable5_2, app.UITable6_2, app.UITable7_2, app.UITable8_2, ...
                app.UITable9_2, app.UITable10_2, app.UITable11_2, app.UITable12_2];

            labels = [app.Texto5_2, app.Texto6_2, app.Texto7_2, app.Texto8_2, ...
                app.Texto9_2, app.Texto10_2, app.Texto11_2, app.Texto12_2];

            buttons = [app.Exportar5_2, app.Exportar6_2, app.Exportar7_2, app.Exportar8_2, ...
                app.Exportar9_2, app.Exportar10_2, app.Exportar11_2, app.Exportar12_2];

            for i = 1:length(tables)
                tables(i).Data = [];
                tables(i).Visible = 'off';
                labels(i).Visible = 'off';
                buttons(i).Visible = 'off';
            end

            app.PanelErrorConvergencia_2.Visible = 'off';

            % 3. RESET BOTONES
            app.RevisarDatosParabolico_2.Text = 'Revisar datos';
            app.RevisarDatosParabolico_2.BackgroundColor = [0.83,0.86,0.91];

            app.ResolverParabolicaExplicito_2.Text = '▶ Resolver EDP';
            app.ResolverParabolicaExplicito_2.BackgroundColor = [0.83,0.86,0.91];
            app.ResolverParabolicaExplicito_2.Enable = "off";

            app.ResultadosParabolico_2.Enable = "off";

            % 4. LIMPIAR CAMPOS
            app.tmax_2.Value = '';
            app.L_2.Value = '';
            app.uxt_2.Value = '';
            app.uxt_2.Enable = 'off';
            app.uLt_2.Value = '';
            app.u0t_2.Value = '';
            app.ux0_2.Value = '';
            app.utx0_2.Value = '';
            app.cP_2.Value = '';
            app.hP_2.Value = '';
            app.kP_2.Value = '';

            % 5. RESET BOTONES EXPORTAR
            for i = 5:12
                btn = app.(['Exportar' num2str(i) '_2']);
                btn.Text = ['Exportar ' num2str(i)];
                btn.BackgroundColor = [0.83,0.86,0.91];
                btn.Visible = 'off';
            end

            % 6. RESET LABELS
            for i = 5:12
                app.(['Texto' num2str(i) '_2']).Visible = 'off';
            end

            % 7. RESET DROPDOWNS
            app.ResultadosParabolico_2.BackgroundColor = [0.83,0.86,0.91];
            app.ResultadosParabolico_2.Enable = 'off';
            app.ResultadosParabolico_2.Items = {'Seleccionar resultado', ...
                'Gráficas', 'Tablas', 'Errores', 'Convergencia del método'};
            app.ResultadosParabolico_2.Value = 'Seleccionar resultado';

            app.MetodosSolucionParabolicas_2.Items = {'Seleccionar método', ...
                'Explícito', 'Implícito', 'Explícito e Implícito'};
            app.MetodosSolucionParabolicas_2.Value = 'Seleccionar método';

            app.SiNoP_2.Value = '';

            % 8. MENSAJE FINAL 

            if ~app.resetGeneral
                uialert(app.UIFigure, ...
                    '✔ Pestaña Hiperbólica reiniciada correctamente', ...
                    'Reseteo', ...
                    'Icon', 'success');
            end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabPrincipal = uitabgroup(app.UIFigure);
            app.TabPrincipal.SelectionChangedFcn = createCallbackFcn(app, @TabPrincipalSelectionChanged, true);
            app.InicioTab = uitab(app.TabPrincipal);
            app.InicioTab.Title = 'Inicio';
            app.GridLayout = uigridlayout(app.InicioTab);
            app.MenuPrincipal = uibuttongroup(app.GridLayout);
            app.MenuPrincipal.SelectionChangedFcn = createCallbackFcn(app, @MenuPrincipalSelectionChanged, true);
            app.MenuPrincipal.Title = 'Menú Principal';
            app.ReseteoButton = uiradiobutton(app.MenuPrincipal);
            app.ReseteoButton.Tooltip = {'Borra y resetea todas las pestañas'};
            app.ReseteoButton.Text = 'Reseteo';
            app.ReseteoButton.Value = true;
            app.ElipticasButton = uiradiobutton(app.MenuPrincipal);
            app.ElipticasButton.Tooltip = {'Accede a la pestaña Elípticas'};
            app.ElipticasButton.Text = 'Elípticas';
            app.ParabolicasButton = uiradiobutton(app.MenuPrincipal);
            app.ParabolicasButton.Tooltip = {'Accede a la pestaña Parabólicas'};
            app.ParabolicasButton.Text = 'Parabólicas';
            app.HiperbolicasButton = uiradiobutton(app.MenuPrincipal);
            app.HiperbolicasButton.Tooltip = {'Accede a la pestaña Hiperbólicas'};
            app.HiperbolicasButton.Text = 'Hiperbólicas';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Graficas', 'Carátula 4.png');
            app.ElipticasTab = uitab(app.TabPrincipal);
            app.ElipticasTab.Tooltip = {''};
            app.ElipticasTab.Title = 'Elípticas';
            app.GridLayout2 = uigridlayout(app.ElipticasTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Eje3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Eje2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout3);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Texto = uitextarea(app.GridLayout3);
            app.Texto2 = uitextarea(app.GridLayout3);
            app.Exportar = uibutton(app.GridLayout3, 'push');
            app.Exportar.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.Exportar.Interpreter = 'tex';
            app.Exportar.Text = 'Exportar';
            app.UITable4 = uitable(app.GridLayout3);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.Texto4 = uitextarea(app.GridLayout3);
            app.Exportar2 = uibutton(app.GridLayout3, 'push');
            app.Exportar2.ButtonPushedFcn = createCallbackFcn(app, @Exportar2ButtonPushed, true);
            app.Exportar2.Interpreter = 'tex';
            app.Exportar2.Text = 'Exportar';
            app.Exportar4 = uibutton(app.GridLayout3, 'push');
            app.Exportar4.ButtonPushedFcn = createCallbackFcn(app, @Exportar4ButtonPushed, true);
            app.Exportar4.Interpreter = 'tex';
            app.Exportar4.Text = 'Exportar';
            app.UITable3 = uitable(app.GridLayout3);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.Texto3 = uitextarea(app.GridLayout3);
            app.Exportar3 = uibutton(app.GridLayout3, 'push');
            app.Exportar3.ButtonPushedFcn = createCallbackFcn(app, @Exportar3ButtonPushed, true);
            app.Exportar3.Interpreter = 'tex';
            app.Exportar3.Text = 'Exportar';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.DimensionesdelaplacaPanel = uipanel(app.GridLayout4);
            app.DimensionesdelaplacaPanel.Title = 'Dimensiones de la placa';
            app.GridLayout5 = uigridlayout(app.DimensionesdelaplacaPanel);
            app.x_0EditFieldLabel = uilabel(app.GridLayout5);
            app.x_0EditFieldLabel.Interpreter = 'tex';
            app.x_0EditFieldLabel.Text = 'x_0';
            app.a = uieditfield(app.GridLayout5, 'text');
            app.a.Tooltip = {'Límite inicial en la dirección x'};
            app.x_nEditFieldLabel = uilabel(app.GridLayout5);
            app.x_nEditFieldLabel.Interpreter = 'tex';
            app.x_nEditFieldLabel.Text = 'x_n';
            app.b = uieditfield(app.GridLayout5, 'text');
            app.b.Tooltip = {'Límite final en la dirección x'};
            app.y_0EditFieldLabel = uilabel(app.GridLayout5);
            app.y_0EditFieldLabel.Interpreter = 'tex';
            app.y_0EditFieldLabel.Text = 'y_0';
            app.c = uieditfield(app.GridLayout5, 'text');
            app.c.Tooltip = {'Límite inicial en la dirección y'};
            app.y_mEditFieldLabel = uilabel(app.GridLayout5);
            app.y_mEditFieldLabel.Interpreter = 'tex';
            app.y_mEditFieldLabel.Text = 'y_m';
            app.d = uieditfield(app.GridLayout5, 'text');
            app.d.Tooltip = {'Límite final en la dirección y'};
            app.CondicionesdecontornoDirichletPanel = uipanel(app.GridLayout4);
            app.CondicionesdecontornoDirichletPanel.Title = 'Condiciones de contorno Dirichlet';
            app.GridLayout6 = uigridlayout(app.CondicionesdecontornoDirichletPanel);
            app.Izquierdaux_0yLabel = uilabel(app.GridLayout6);
            app.Izquierdaux_0yLabel.Interpreter = 'tex';
            app.Izquierdaux_0yLabel.Text = 'Izquierda: u(x_0,y)';
            app.uizquierda = uieditfield(app.GridLayout6, 'text');
            app.uizquierda.Tooltip = {'Puede ingresar números o una función que dependa de y'};
            app.Derechaux_nyEditFieldLabel = uilabel(app.GridLayout6);
            app.Derechaux_nyEditFieldLabel.Interpreter = 'tex';
            app.Derechaux_nyEditFieldLabel.Text = 'Derecha: u(x_n,y)';
            app.uderecha = uieditfield(app.GridLayout6, 'text');
            app.uderecha.Tooltip = {'Puede ingresar números o una función que dependa de y'};
            app.Arribauxy_mEditFieldLabel = uilabel(app.GridLayout6);
            app.Arribauxy_mEditFieldLabel.Interpreter = 'tex';
            app.Arribauxy_mEditFieldLabel.Text = 'Arriba: u(x,y_m)';
            app.uarriba = uieditfield(app.GridLayout6, 'text');
            app.uarriba.Tooltip = {'Puede ingresar números o una función que dependa de x'};
            app.Abajouxy_0EditFieldLabel = uilabel(app.GridLayout6);
            app.Abajouxy_0EditFieldLabel.Interpreter = 'tex';
            app.Abajouxy_0EditFieldLabel.Text = 'Abajo: u(x,y_0)';
            app.uabajo = uieditfield(app.GridLayout6, 'text');
            app.uabajo.Tooltip = {'Puede ingresar números o una función que dependa de x'};
            app.TamaosdepasoPanel = uipanel(app.GridLayout4);
            app.TamaosdepasoPanel.Title = 'Tamaños de paso';
            app.GridLayout7 = uigridlayout(app.TamaosdepasoPanel);
            app.hEditFieldLabel = uilabel(app.GridLayout7);
            app.hEditFieldLabel.Interpreter = 'tex';
            app.hEditFieldLabel.Text = 'h';
            app.hx = uieditfield(app.GridLayout7, 'text');
            app.hx.Tooltip = {'Tamaño de paso en la dirección x'};
            app.kEditFieldLabel = uilabel(app.GridLayout7);
            app.kEditFieldLabel.Interpreter = 'tex';
            app.kEditFieldLabel.Text = 'k';
            app.ky = uieditfield(app.GridLayout7, 'text');
            app.ky.Tooltip = {'Tamaño de paso en la dirección y'};
            app.FuenteysolucinexactaPanel = uipanel(app.GridLayout4);
            app.FuenteysolucinexactaPanel.Title = 'Fuente y solución exacta';
            app.GridLayout8 = uigridlayout(app.FuenteysolucinexactaPanel);
            app.fxyEditFieldLabel = uilabel(app.GridLayout8);
            app.fxyEditFieldLabel.Interpreter = 'tex';
            app.fxyEditFieldLabel.Text = 'f(x,y)';
            app.fxy = uieditfield(app.GridLayout8, 'text');
            app.fxy.Tooltip = {'Función f(x,y) de la EDP Elíptica. Use * para multiplicación, / para división y las funciones propias de MATLAB.'};
            app.SetienelasolucinexactauxyLabel = uilabel(app.GridLayout8);
            app.SetienelasolucinexactauxyLabel.Interpreter = 'tex';
            app.SetienelasolucinexactauxyLabel.Text = '¿Se tiene la solución exacta u(x,y)?';
            app.SiNo = uidropdown(app.GridLayout8);
            app.SiNo.Items = {'', 'Sí', 'No'};
            app.SiNo.ValueChangedFcn = createCallbackFcn(app, @SiNoValueChanged, true);
            app.SiNo.Value = '';
            app.uxyEditFieldLabel = uilabel(app.GridLayout8);
            app.uxyEditFieldLabel.Interpreter = 'tex';
            app.uxyEditFieldLabel.Text = 'u(x,y)';
            app.uxy = uieditfield(app.GridLayout8, 'text');
            app.uxy.Tooltip = {'Solución exacta u(x,y) de la EDP Elíptica. Use * para multiplicación, / para división y las funciones propias de MATLAB.'};
            app.ResultadosPanel = uipanel(app.GridLayout4);
            app.ResultadosPanel.Title = 'Resultados';
            app.ResultadosPanel.SizeChangedFcn = createCallbackFcn(app, @ResultadosPanelSizeChanged, true);
            app.GridLayout9 = uigridlayout(app.ResultadosPanel);
            app.Resultados = uidropdown(app.GridLayout9);
            app.Resultados.Items = {'Seleccionar resultado', 'Gráficas 2D', 'Gráficas 3D', 'Tablas', 'Errores', 'Convergencia del método'};
            app.Resultados.ValueChangedFcn = createCallbackFcn(app, @ResultadosValueChanged, true);
            app.Resultados.Tooltip = {'Seleccione una opción de la lista desplegable'};
            app.Resultados.Value = 'Seleccionar resultado';
            app.ResolverEliptica = uibutton(app.GridLayout4, 'push');
            app.ResolverEliptica.ButtonPushedFcn = createCallbackFcn(app, @ResolverElipticaButtonPushed, true);
            app.ResolverEliptica.Interpreter = 'tex';
            app.ResolverEliptica.Text = '▶ Resolver EDP';
            app.RevisardatosEliptica = uibutton(app.GridLayout4, 'push');
            app.RevisardatosEliptica.ButtonPushedFcn = createCallbackFcn(app, @RevisardatosElipticaButtonPushed, true);
            app.RevisardatosEliptica.Interpreter = 'tex';
            app.RevisardatosEliptica.Text = 'Revisar datos';
            app.Image2 = uiimage(app.GridLayout4);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Graficas', 'EDP Elíptica.png');
            app.ReseteoEliptica = uibutton(app.GridLayout4, 'push');
            app.ReseteoEliptica.ButtonPushedFcn = createCallbackFcn(app, @ReseteoElipticaButtonPushed, true);
            app.ReseteoEliptica.Tooltip = {'Reiniciar entorno de cálculo: borra toda la configuración y los datos generados'};
            app.ReseteoEliptica.Interpreter = 'tex';
            app.ReseteoEliptica.Text = 'Reseteo';
            app.ParabolicasTab = uitab(app.TabPrincipal);
            app.ParabolicasTab.Tooltip = {''};
            app.ParabolicasTab.Title = 'Parabólicas';
            app.GridLayout10 = uigridlayout(app.ParabolicasTab);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.Image3 = uiimage(app.GridLayout11);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Graficas', 'EDP Parabolica 4.png');
            app.ReseteoParabolica = uibutton(app.GridLayout11, 'push');
            app.ReseteoParabolica.ButtonPushedFcn = createCallbackFcn(app, @ReseteoParabolicaButtonPushed, true);
            app.ReseteoParabolica.Interpreter = 'tex';
            app.ReseteoParabolica.Text = 'Reseteo';
            app.CondicionesinicialesPanel = uipanel(app.GridLayout11);
            app.CondicionesinicialesPanel.Title = 'Condiciones iniciales';
            app.GridLayout13 = uigridlayout(app.CondicionesinicialesPanel);
            app.ux0EditFieldLabel = uilabel(app.GridLayout13);
            app.ux0EditFieldLabel.Interpreter = 'tex';
            app.ux0EditFieldLabel.Text = 'u(x,0)';
            app.ux0 = uieditfield(app.GridLayout13, 'text');
            app.CondicionesdecontornoDirichletPanel_2 = uipanel(app.GridLayout11);
            app.CondicionesdecontornoDirichletPanel_2.Title = 'Condiciones de contorno Dirichlet';
            app.GridLayout14 = uigridlayout(app.CondicionesdecontornoDirichletPanel_2);
            app.u0tEditFieldLabel = uilabel(app.GridLayout14);
            app.u0tEditFieldLabel.Interpreter = 'tex';
            app.u0tEditFieldLabel.Text = 'u(0,t)';
            app.u0t = uieditfield(app.GridLayout14, 'text');
            app.uLtEditFieldLabel = uilabel(app.GridLayout14);
            app.uLtEditFieldLabel.Interpreter = 'tex';
            app.uLtEditFieldLabel.Text = 'u(L,t)';
            app.uLt = uieditfield(app.GridLayout14, 'text');
            app.TamaosdepasoPanel_2 = uipanel(app.GridLayout11);
            app.TamaosdepasoPanel_2.Title = 'Tamaños de paso';
            app.GridLayout15 = uigridlayout(app.TamaosdepasoPanel_2);
            app.hLabel = uilabel(app.GridLayout15);
            app.hLabel.Interpreter = 'tex';
            app.hLabel.Text = 'h';
            app.hP = uieditfield(app.GridLayout15, 'text');
            app.kLabel = uilabel(app.GridLayout15);
            app.kLabel.Interpreter = 'tex';
            app.kLabel.Text = 'k';
            app.kP = uieditfield(app.GridLayout15, 'text');
            app.PanelMetodosSolucionParabolicas = uipanel(app.GridLayout11);
            app.PanelMetodosSolucionParabolicas.Title = 'Métodos de solución';
            app.GridLayout23 = uigridlayout(app.PanelMetodosSolucionParabolicas);
            app.MetodosSolucionParabolicas = uidropdown(app.GridLayout23);
            app.MetodosSolucionParabolicas.Items = {'Seleccionar método', 'Explícito', 'Crank-Nicholson', 'Explícito y Crank-Nicholson'};
            app.MetodosSolucionParabolicas.ValueChangedFcn = createCallbackFcn(app, @MetodosSolucionParabolicasValueChanged, true);
            app.MetodosSolucionParabolicas.Value = 'Seleccionar método';
            app.PanelExplicito = uipanel(app.GridLayout11);
            app.PanelExplicito.Title = 'Explícito';
            app.GridLayout22 = uigridlayout(app.PanelExplicito);
            app.ResolverParabolicaExplicito = uibutton(app.GridLayout22, 'push');
            app.ResolverParabolicaExplicito.ButtonPushedFcn = createCallbackFcn(app, @ResolverParabolicaExplicitoButtonPushed, true);
            app.ResolverParabolicaExplicito.Interpreter = 'tex';
            app.ResolverParabolicaExplicito.Text = '▶ Resolver EDP';
            app.RevisarDatosParabolico = uibutton(app.GridLayout22, 'push');
            app.RevisarDatosParabolico.ButtonPushedFcn = createCallbackFcn(app, @RevisarDatosParabolicoButtonPushed, true);
            app.RevisarDatosParabolico.Interpreter = 'tex';
            app.RevisarDatosParabolico.Text = 'Revisar datos';
            app.PanelResultadosParabolico = uipanel(app.GridLayout22);
            app.PanelResultadosParabolico.Title = 'Resultados';
            app.GridLayout25 = uigridlayout(app.PanelResultadosParabolico);
            app.ResultadosParabolico = uidropdown(app.GridLayout25);
            app.ResultadosParabolico.Items = {'Seleccionar resultado', 'Gráficas', 'Tablas', 'Errores', 'Convergencia del método'};
            app.ResultadosParabolico.ValueChangedFcn = createCallbackFcn(app, @ResultadosParabolicoValueChanged, true);
            app.ResultadosParabolico.Value = 'Seleccionar resultado';
            app.SetienelasolucinexactauxtPanel = uipanel(app.GridLayout11);
            app.SetienelasolucinexactauxtPanel.Title = '¿Se tiene la solución exacta u(x,t)?';
            app.GridLayout26 = uigridlayout(app.SetienelasolucinexactauxtPanel);
            app.SiNoP = uidropdown(app.GridLayout26);
            app.SiNoP.Items = {'', 'Sí', 'No'};
            app.SiNoP.ValueChangedFcn = createCallbackFcn(app, @SiNoPValueChanged, true);
            app.SiNoP.Value = '';
            app.uxtLabel = uilabel(app.GridLayout26);
            app.uxtLabel.Interpreter = 'tex';
            app.uxtLabel.Text = 'u(x,t)';
            app.uxt = uieditfield(app.GridLayout26, 'text');
            app.DominioespacialytemporalPanel = uipanel(app.GridLayout11);
            app.DominioespacialytemporalPanel.Title = 'Dominio espacial y temporal';
            app.GridLayout12 = uigridlayout(app.DominioespacialytemporalPanel);
            app.LEditFieldLabel = uilabel(app.GridLayout12);
            app.LEditFieldLabel.Interpreter = 'tex';
            app.LEditFieldLabel.Text = 'L';
            app.L = uieditfield(app.GridLayout12, 'text');
            app.t_mxEditFieldLabel = uilabel(app.GridLayout12);
            app.t_mxEditFieldLabel.Interpreter = 'tex';
            app.t_mxEditFieldLabel.Text = 't_{máx}';
            app.tmax = uieditfield(app.GridLayout12, 'text');
            app.CoeficientededifusintrmicaPanel = uipanel(app.GridLayout11);
            app.CoeficientededifusintrmicaPanel.Title = 'Coeficiente de difusión térmica';
            app.GridLayout27 = uigridlayout(app.CoeficientededifusintrmicaPanel);
            app.cEditFieldLabel = uilabel(app.GridLayout27);
            app.cEditFieldLabel.Interpreter = 'tex';
            app.cEditFieldLabel.Text = 'c';
            app.cP = uieditfield(app.GridLayout27, 'text');
            app.GridLayout24 = uigridlayout(app.GridLayout10);
            app.UIAxes10 = uiaxes(app.GridLayout24);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout24);
            title(app.UIAxes6, 'UIAxes6')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes8 = uiaxes(app.GridLayout24);
            title(app.UIAxes8, 'UIAxes8')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout24);
            title(app.UIAxes7, 'UIAxes7')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes9 = uiaxes(app.GridLayout24);
            title(app.UIAxes9, 'UIAxes9')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.DeslizadorP = uislider(app.GridLayout24);
            app.DeslizadorP.ValueChangedFcn = createCallbackFcn(app, @DeslizadorPValueChanged, true);
            app.UITable5 = uitable(app.GridLayout24);
            app.UITable5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable5.RowName = {};
            app.UITable6 = uitable(app.GridLayout24);
            app.UITable6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable6.RowName = {};
            app.Exportar5 = uibutton(app.GridLayout24, 'push');
            app.Exportar5.ButtonPushedFcn = createCallbackFcn(app, @Exportar5ButtonPushed, true);
            app.Exportar5.Interpreter = 'tex';
            app.Exportar5.Text = 'Exportar';
            app.Exportar6 = uibutton(app.GridLayout24, 'push');
            app.Exportar6.ButtonPushedFcn = createCallbackFcn(app, @Exportar6ButtonPushed, true);
            app.Exportar6.Interpreter = 'tex';
            app.Exportar6.Text = 'Exportar';
            app.Texto5 = uilabel(app.GridLayout24);
            app.Texto5.Interpreter = 'tex';
            app.Texto5.Text = '';
            app.Texto6 = uilabel(app.GridLayout24);
            app.Texto6.Interpreter = 'tex';
            app.Texto6.Text = '';
            app.UITable7 = uitable(app.GridLayout24);
            app.UITable7.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.GridLayout24);
            app.UITable8.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable8.RowName = {};
            app.UITable9 = uitable(app.GridLayout24);
            app.UITable9.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable9.RowName = {};
            app.Texto7 = uilabel(app.GridLayout24);
            app.Texto7.Text = '';
            app.Texto8 = uilabel(app.GridLayout24);
            app.Texto8.Interpreter = 'tex';
            app.Texto8.Text = '';
            app.Exportar8 = uibutton(app.GridLayout24, 'push');
            app.Exportar8.ButtonPushedFcn = createCallbackFcn(app, @Exportar8ButtonPushed, true);
            app.Exportar8.Interpreter = 'tex';
            app.Exportar8.Text = 'Exportar';
            app.Texto9 = uilabel(app.GridLayout24);
            app.Texto9.Interpreter = 'tex';
            app.Texto9.Text = '';
            app.Exportar9 = uibutton(app.GridLayout24, 'push');
            app.Exportar9.ButtonPushedFcn = createCallbackFcn(app, @Exportar9ButtonPushed, true);
            app.Exportar9.Interpreter = 'tex';
            app.Exportar9.Text = 'Exportar';
            app.Exportar7 = uibutton(app.GridLayout24, 'push');
            app.Exportar7.ButtonPushedFcn = createCallbackFcn(app, @Exportar7ButtonPushed, true);
            app.Exportar7.Interpreter = 'tex';
            app.Exportar7.Text = 'Exportar';
            app.PanelErrorConvergencia = uipanel(app.GridLayout24);
            app.GridLayout28 = uigridlayout(app.PanelErrorConvergencia);
            app.UIAxes11 = uiaxes(app.GridLayout28);
            title(app.UIAxes11, 'UIAxes11')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.GridLayout28);
            title(app.UIAxes12, 'UIAxes12')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes13 = uiaxes(app.GridLayout28);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.UITable10 = uitable(app.GridLayout28);
            app.UITable10.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable10.RowName = {};
            app.UITable11 = uitable(app.GridLayout28);
            app.UITable11.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable11.RowName = {};
            app.Texto10 = uilabel(app.GridLayout28);
            app.Texto10.Interpreter = 'tex';
            app.Texto10.Text = '';
            app.Texto11 = uilabel(app.GridLayout28);
            app.Texto11.Interpreter = 'tex';
            app.Texto11.Text = '';
            app.Exportar10 = uibutton(app.GridLayout28, 'push');
            app.Exportar10.ButtonPushedFcn = createCallbackFcn(app, @Exportar10ButtonPushed, true);
            app.Exportar10.Interpreter = 'tex';
            app.Exportar10.Text = 'Exportar';
            app.Exportar11 = uibutton(app.GridLayout28, 'push');
            app.Exportar11.ButtonPushedFcn = createCallbackFcn(app, @Exportar11ButtonPushed, true);
            app.Exportar11.Interpreter = 'tex';
            app.Exportar11.Text = 'Exportar';
            app.UITable12 = uitable(app.GridLayout28);
            app.UITable12.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable12.RowName = {};
            app.Texto12 = uilabel(app.GridLayout28);
            app.Texto12.Interpreter = 'tex';
            app.Texto12.Text = '';
            app.Exportar12 = uibutton(app.GridLayout28, 'push');
            app.Exportar12.ButtonPushedFcn = createCallbackFcn(app, @Exportar12ButtonPushed, true);
            app.Exportar12.Interpreter = 'tex';
            app.Exportar12.Text = 'Exportar';
            app.HiperbolicasTab = uitab(app.TabPrincipal);
            app.HiperbolicasTab.Tooltip = {''};
            app.HiperbolicasTab.Title = 'Hiperbólicas';
            app.GridLayout10_2 = uigridlayout(app.HiperbolicasTab);
            app.GridLayout11_2 = uigridlayout(app.GridLayout10_2);
            app.Image3_2 = uiimage(app.GridLayout11_2);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'Graficas', 'EDP Hiperbólica33.png');
            app.ReseteoParabolica_2 = uibutton(app.GridLayout11_2, 'push');
            app.ReseteoParabolica_2.ButtonPushedFcn = createCallbackFcn(app, @ReseteoParabolica_2ButtonPushed, true);
            app.ReseteoParabolica_2.Interpreter = 'tex';
            app.ReseteoParabolica_2.Text = 'Reseteo';
            app.CondicionesinicialesPanel_2 = uipanel(app.GridLayout11_2);
            app.CondicionesinicialesPanel_2.Title = 'Condiciones iniciales';
            app.GridLayout13_2 = uigridlayout(app.CondicionesinicialesPanel_2);
            app.ux0EditFieldLabel_2 = uilabel(app.GridLayout13_2);
            app.ux0EditFieldLabel_2.Interpreter = 'tex';
            app.ux0EditFieldLabel_2.Text = 'u(x,0)';
            app.ux0_2 = uieditfield(app.GridLayout13_2, 'text');
            app.u_tx0EditFieldLabel = uilabel(app.GridLayout13_2);
            app.u_tx0EditFieldLabel.Interpreter = 'tex';
            app.u_tx0EditFieldLabel.Text = 'u_t(x,0)';
            app.utx0_2 = uieditfield(app.GridLayout13_2, 'text');
            app.CondicionesdecontornoDirichletPanel_3 = uipanel(app.GridLayout11_2);
            app.CondicionesdecontornoDirichletPanel_3.Title = 'Condiciones de contorno Dirichlet';
            app.GridLayout14_2 = uigridlayout(app.CondicionesdecontornoDirichletPanel_3);
            app.u0tEditFieldLabel_2 = uilabel(app.GridLayout14_2);
            app.u0tEditFieldLabel_2.Interpreter = 'tex';
            app.u0tEditFieldLabel_2.Text = 'u(0,t)';
            app.u0t_2 = uieditfield(app.GridLayout14_2, 'text');
            app.uLtEditFieldLabel_2 = uilabel(app.GridLayout14_2);
            app.uLtEditFieldLabel_2.Interpreter = 'tex';
            app.uLtEditFieldLabel_2.Text = 'u(L,t)';
            app.uLt_2 = uieditfield(app.GridLayout14_2, 'text');
            app.TamaosdepasoPanel_3 = uipanel(app.GridLayout11_2);
            app.TamaosdepasoPanel_3.Title = 'Tamaños de paso';
            app.GridLayout15_2 = uigridlayout(app.TamaosdepasoPanel_3);
            app.hLabel_2 = uilabel(app.GridLayout15_2);
            app.hLabel_2.Interpreter = 'tex';
            app.hLabel_2.Text = 'h';
            app.hP_2 = uieditfield(app.GridLayout15_2, 'text');
            app.kLabel_2 = uilabel(app.GridLayout15_2);
            app.kLabel_2.Interpreter = 'tex';
            app.kLabel_2.Text = 'k';
            app.kP_2 = uieditfield(app.GridLayout15_2, 'text');
            app.PanelMetodosSolucionParabolicas_2 = uipanel(app.GridLayout11_2);
            app.PanelMetodosSolucionParabolicas_2.Title = 'Métodos de solución';
            app.GridLayout23_2 = uigridlayout(app.PanelMetodosSolucionParabolicas_2);
            app.MetodosSolucionParabolicas_2 = uidropdown(app.GridLayout23_2);
            app.MetodosSolucionParabolicas_2.Items = {'Seleccionar método', 'Explícito', 'Implícito', 'Explícito e Implícito'};
            app.MetodosSolucionParabolicas_2.ValueChangedFcn = createCallbackFcn(app, @MetodosSolucionParabolicas_2ValueChanged, true);
            app.MetodosSolucionParabolicas_2.Value = 'Seleccionar método';
            app.PanelExplicito_2 = uipanel(app.GridLayout11_2);
            app.GridLayout22_2 = uigridlayout(app.PanelExplicito_2);
            app.ResolverParabolicaExplicito_2 = uibutton(app.GridLayout22_2, 'push');
            app.ResolverParabolicaExplicito_2.ButtonPushedFcn = createCallbackFcn(app, @ResolverParabolicaExplicito_2ButtonPushed, true);
            app.ResolverParabolicaExplicito_2.Interpreter = 'tex';
            app.ResolverParabolicaExplicito_2.Text = '▶ Resolver EDP';
            app.RevisarDatosParabolico_2 = uibutton(app.GridLayout22_2, 'push');
            app.RevisarDatosParabolico_2.ButtonPushedFcn = createCallbackFcn(app, @RevisarDatosParabolico_2ButtonPushed, true);
            app.RevisarDatosParabolico_2.Interpreter = 'tex';
            app.RevisarDatosParabolico_2.Text = 'Revisar datos';
            app.PanelResultadosParabolico_2 = uipanel(app.GridLayout22_2);
            app.PanelResultadosParabolico_2.Title = 'Resultados';
            app.GridLayout25_2 = uigridlayout(app.PanelResultadosParabolico_2);
            app.ResultadosParabolico_2 = uidropdown(app.GridLayout25_2);
            app.ResultadosParabolico_2.Items = {'Seleccionar resultado', 'Gráficas', 'Tablas', 'Errores', 'Convergencia del método'};
            app.ResultadosParabolico_2.ValueChangedFcn = createCallbackFcn(app, @ResultadosParabolico_2ValueChanged, true);
            app.ResultadosParabolico_2.Value = 'Seleccionar resultado';
            app.SetienelasolucinexactauxtPanel_2 = uipanel(app.GridLayout11_2);
            app.SetienelasolucinexactauxtPanel_2.Title = '¿Se tiene la solución exacta u(x,t)?';
            app.GridLayout26_2 = uigridlayout(app.SetienelasolucinexactauxtPanel_2);
            app.SiNoP_2 = uidropdown(app.GridLayout26_2);
            app.SiNoP_2.Items = {'', 'Sí', 'No'};
            app.SiNoP_2.ValueChangedFcn = createCallbackFcn(app, @SiNoP_2ValueChanged, true);
            app.SiNoP_2.Value = '';
            app.uxtLabel_2 = uilabel(app.GridLayout26_2);
            app.uxtLabel_2.Interpreter = 'tex';
            app.uxtLabel_2.Text = 'u(x,t)';
            app.uxt_2 = uieditfield(app.GridLayout26_2, 'text');
            app.DominioespacialytemporalPanel_2 = uipanel(app.GridLayout11_2);
            app.DominioespacialytemporalPanel_2.Title = 'Dominio espacial y temporal';
            app.GridLayout12_2 = uigridlayout(app.DominioespacialytemporalPanel_2);
            app.LEditFieldLabel_2 = uilabel(app.GridLayout12_2);
            app.LEditFieldLabel_2.Interpreter = 'tex';
            app.LEditFieldLabel_2.Text = 'L';
            app.L_2 = uieditfield(app.GridLayout12_2, 'text');
            app.t_mxEditFieldLabel_2 = uilabel(app.GridLayout12_2);
            app.t_mxEditFieldLabel_2.Interpreter = 'tex';
            app.t_mxEditFieldLabel_2.Text = 't_{máx}';
            app.tmax_2 = uieditfield(app.GridLayout12_2, 'text');
            app.ParmetrodevelocidadPanel = uipanel(app.GridLayout11_2);
            app.ParmetrodevelocidadPanel.Title = 'Parámetro de velocidad';
            app.GridLayout27_2 = uigridlayout(app.ParmetrodevelocidadPanel);
            app.c2Label = uilabel(app.GridLayout27_2);
            app.c2Label.Interpreter = 'tex';
            app.c2Label.Text = 'c^2';
            app.cP_2 = uieditfield(app.GridLayout27_2, 'text');
            app.GridLayout24_2 = uigridlayout(app.GridLayout10_2);
            app.UIAxes9_2 = uiaxes(app.GridLayout24_2);
            title(app.UIAxes9_2, 'UIAxes9')
            xlabel(app.UIAxes9_2, 'X')
            ylabel(app.UIAxes9_2, 'Y')
            zlabel(app.UIAxes9_2, 'Z')
            app.UIAxes7_2 = uiaxes(app.GridLayout24_2);
            title(app.UIAxes7_2, 'UIAxes7')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.UIAxes8_2 = uiaxes(app.GridLayout24_2);
            title(app.UIAxes8_2, 'UIAxes8')
            xlabel(app.UIAxes8_2, 'X')
            ylabel(app.UIAxes8_2, 'Y')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes6_2 = uiaxes(app.GridLayout24_2);
            title(app.UIAxes6_2, 'UIAxes6')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes10_2 = uiaxes(app.GridLayout24_2);
            title(app.UIAxes10_2, 'Title')
            xlabel(app.UIAxes10_2, 'X')
            ylabel(app.UIAxes10_2, 'Y')
            zlabel(app.UIAxes10_2, 'Z')
            app.DeslizadorP_2 = uislider(app.GridLayout24_2);
            app.DeslizadorP_2.ValueChangedFcn = createCallbackFcn(app, @DeslizadorP_2ValueChanged, true);
            app.UITable5_2 = uitable(app.GridLayout24_2);
            app.UITable5_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable5_2.RowName = {};
            app.UITable6_2 = uitable(app.GridLayout24_2);
            app.UITable6_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable6_2.RowName = {};
            app.Exportar5_2 = uibutton(app.GridLayout24_2, 'push');
            app.Exportar5_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar5_2ButtonPushed, true);
            app.Exportar5_2.Interpreter = 'tex';
            app.Exportar5_2.Text = 'Exportar';
            app.Exportar6_2 = uibutton(app.GridLayout24_2, 'push');
            app.Exportar6_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar6_2ButtonPushed, true);
            app.Exportar6_2.Interpreter = 'tex';
            app.Exportar6_2.Text = 'Exportar';
            app.Texto5_2 = uilabel(app.GridLayout24_2);
            app.Texto5_2.Interpreter = 'tex';
            app.Texto5_2.Text = '';
            app.Texto6_2 = uilabel(app.GridLayout24_2);
            app.Texto6_2.Interpreter = 'tex';
            app.Texto6_2.Text = '';
            app.UITable7_2 = uitable(app.GridLayout24_2);
            app.UITable7_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable7_2.RowName = {};
            app.UITable8_2 = uitable(app.GridLayout24_2);
            app.UITable8_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable8_2.RowName = {};
            app.UITable9_2 = uitable(app.GridLayout24_2);
            app.UITable9_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable9_2.RowName = {};
            app.Texto7_2 = uilabel(app.GridLayout24_2);
            app.Texto7_2.Text = '';
            app.Texto8_2 = uilabel(app.GridLayout24_2);
            app.Texto8_2.Interpreter = 'tex';
            app.Texto8_2.Text = '';
            app.Exportar8_2 = uibutton(app.GridLayout24_2, 'push');
            app.Exportar8_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar8_2ButtonPushed, true);
            app.Exportar8_2.Interpreter = 'tex';
            app.Exportar8_2.Text = 'Exportar';
            app.Texto9_2 = uilabel(app.GridLayout24_2);
            app.Texto9_2.Interpreter = 'tex';
            app.Texto9_2.Text = '';
            app.Exportar9_2 = uibutton(app.GridLayout24_2, 'push');
            app.Exportar9_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar9_2ButtonPushed, true);
            app.Exportar9_2.Interpreter = 'tex';
            app.Exportar9_2.Text = 'Exportar';
            app.Exportar7_2 = uibutton(app.GridLayout24_2, 'push');
            app.Exportar7_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar7_2ButtonPushed, true);
            app.Exportar7_2.Interpreter = 'tex';
            app.Exportar7_2.Text = 'Exportar';
            app.PanelErrorConvergencia_2 = uipanel(app.GridLayout24_2);
            app.GridLayout28_2 = uigridlayout(app.PanelErrorConvergencia_2);
            app.UIAxes13_2 = uiaxes(app.GridLayout28_2);
            title(app.UIAxes13_2, 'Title')
            xlabel(app.UIAxes13_2, 'X')
            ylabel(app.UIAxes13_2, 'Y')
            zlabel(app.UIAxes13_2, 'Z')
            app.UIAxes12_2 = uiaxes(app.GridLayout28_2);
            title(app.UIAxes12_2, 'UIAxes12')
            xlabel(app.UIAxes12_2, 'X')
            ylabel(app.UIAxes12_2, 'Y')
            zlabel(app.UIAxes12_2, 'Z')
            app.UIAxes11_2 = uiaxes(app.GridLayout28_2);
            title(app.UIAxes11_2, 'UIAxes11')
            xlabel(app.UIAxes11_2, 'X')
            ylabel(app.UIAxes11_2, 'Y')
            zlabel(app.UIAxes11_2, 'Z')
            app.UITable10_2 = uitable(app.GridLayout28_2);
            app.UITable10_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable10_2.RowName = {};
            app.UITable11_2 = uitable(app.GridLayout28_2);
            app.UITable11_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable11_2.RowName = {};
            app.Texto10_2 = uilabel(app.GridLayout28_2);
            app.Texto10_2.Interpreter = 'tex';
            app.Texto10_2.Text = '';
            app.Texto11_2 = uilabel(app.GridLayout28_2);
            app.Texto11_2.Interpreter = 'tex';
            app.Texto11_2.Text = '';
            app.Exportar10_2 = uibutton(app.GridLayout28_2, 'push');
            app.Exportar10_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar10_2ButtonPushed, true);
            app.Exportar10_2.Interpreter = 'tex';
            app.Exportar10_2.Text = 'Exportar';
            app.Exportar11_2 = uibutton(app.GridLayout28_2, 'push');
            app.Exportar11_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar11_2ButtonPushed, true);
            app.Exportar11_2.Interpreter = 'tex';
            app.Exportar11_2.Text = 'Exportar';
            app.UITable12_2 = uitable(app.GridLayout28_2);
            app.UITable12_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable12_2.RowName = {};
            app.Texto12_2 = uilabel(app.GridLayout28_2);
            app.Texto12_2.Interpreter = 'tex';
            app.Texto12_2.Text = '';
            app.Exportar12_2 = uibutton(app.GridLayout28_2, 'push');
            app.Exportar12_2.ButtonPushedFcn = createCallbackFcn(app, @Exportar12_2ButtonPushed, true);
            app.Exportar12_2.Interpreter = 'tex';
            app.Exportar12_2.Text = 'Exportar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolverEDP2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end